classdef Fouriersynthesis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierSynthesisUIFigure      matlab.ui.Figure
        ShapeLabel                    matlab.ui.control.Label
        ShapeDropDown                 matlab.ui.control.DropDown
        DutycycleLabel                matlab.ui.control.Label
        DutycycleSpinner              matlab.ui.control.Spinner
        PhaseDegLabel                 matlab.ui.control.Label
        PhaseDegSpinner               matlab.ui.control.Spinner
        OffsetLabel                   matlab.ui.control.Label
        OffsetSpinner                 matlab.ui.control.Spinner
        FourierSynthesisExampleLabel  matlab.ui.control.Label
        McClellanetalDSPFirst2ndedCh35FourierSeriesLabel  matlab.ui.control.Label
        RectifyButton                 matlab.ui.control.StateButton
        NoofcoefficientsSpinnerLabel  matlab.ui.control.Label
        NoofcoefficientsSpinner       matlab.ui.control.Spinner
        DisplayFormatDropDown         matlab.ui.control.DropDown
        SignalGraph                   matlab.ui.control.UIAxes
        WaveComponentGraph            matlab.ui.control.UIAxes
        SyntesizedGraph               matlab.ui.control.UIAxes
        SpectralCoefficientGraph_1    matlab.ui.control.UIAxes
        SpectralCoefficientGraph_2    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pulsepar % Pulse parameters
        pulse % Description
    end
    
    methods (Access = private)
        
        % Main function to create pulse, calculate, and plot results
        function results = CalculateAndPlot(app)
            
            CreatePulse(app);  % Calculate pulse and spectral coefficients
            CreateWaves(app);  % Synthesize waves from spectral components
            
            %cols= get(gca,'ColorOrder');
            
            PlotSignals(app, app.SignalGraph, app.pulse.t, app.pulse.s );
            PlotSignals(app, app.WaveComponentGraph, app.pulse.t, app.pulse.sp );
            
            PlotSignals(app, app.SyntesizedGraph, app.pulse.t, app.pulse.ss); hold (app.SyntesizedGraph,"on")
            PlotSignals(app, app.SyntesizedGraph, app.pulse.t, app.pulse.s ); hold (app.SyntesizedGraph,"off")
            
            CoordinateYaxes(app, [app.SignalGraph, app.WaveComponentGraph, app.SyntesizedGraph]);
            PlotCoefficients(app);
            
            results=0; % Return dummy result
        end
        
        % Create pulse and find spectral components
        function results = CreatePulse(app)
            t=app.pulse.t+deg2rad(app.pulsepar.phase);   % Add phase by shifting time-vector
            switch(lower(app.pulsepar.shape))
                case 'square',   app.pulse.s= square(t,app.pulsepar.dutycycle);
                case 'sine',     app.pulse.s= sin(t);
                case 'triangle', app.pulse.s= sawtooth(t,1/2);
                case 'sawtooth', app.pulse.s= sawtooth(t);
            end
            if (app.pulsepar.rectify), app.pulse.s=abs(app.pulse.s); end
            app.pulse.s=app.pulse.s+app.pulsepar.offset;
            
            %--- Find spectral coefficients ---
            app.pulse.Fs= fft(app.pulse.s)/app.pulsepar.N;
            app.pulse.Fs= fftshift(app.pulse.Fs);
            
            results=0;
        end
        
        % Create partial waves from selected components
        function results = CreateWaves(app)
            % Find included spectral coefficients
            app.pulsepar.ni= (-app.pulsepar.Nc:app.pulsepar.Nc);  % Spectral components to include. Two-sided, positive and negative frequencies
            Nci= length(app.pulsepar.ni);                   % No of included complex coefficients
            n  = app.pulsepar.n0+app.pulsepar.ni;              % Corresponding spectral component indices, DC in center at index n0
            app.pulsepar.Fi= app.pulse.Fs(n);               % Included components
            
            % Create complex signals to include
            app.pulse.sc=zeros(app.pulsepar.N, Nci);   % Initialize with matrix of zeros
            for k=1:Nci                             % Signals from included components. Complex valued, two-sided spectrum
                wk=app.pulsepar.ni(k);                 % Normalized frequency value
                app.pulse.sc(:,k)= app.pulsepar.Fi(k)*exp(1i*wk*app.pulse.t);
            end
            
            % Partial waves: Real valued, sum of positive and negative frequencies
            app.pulse.sp=zeros(app.pulsepar.N, app.pulsepar.Nc+1);  % Initialize with zeros. DC plus Nc frequency components
            n0=app.pulsepar.Nc+1;                                % Position of DC component in sc
            app.pulse.sp(:,1)=app.pulse.sc(:,n0);             % DC, no sum, always real
            for k=1:app.pulsepar.Nc                              % Sum of positive and negative frequencies
                app.pulse.sp(:,k+1)=app.pulse.sc(:,n0+k)+app.pulse.sc(:,n0-k);
            end
            app.pulse.sp= real(app.pulse.sp);   % Remove imaginary parts, fragments from roundoff-error
            app.pulse.ss= sum(app.pulse.sp,2);  % Syntesized signal, summed of all partial wave
            
            results=0;
        end
        
        % Plot signals, common for all time traces
        function results = PlotSignals(app,signalax, tdata, sdata)
            dt= tdata(2)-tdata(1);
            T = tdata(end)+dt;
            t = [tdata ; T+tdata];
            s = repmat(sdata,2,1);
            plot(signalax,t,s)
            results=0;
        end
        
        % Plot spectal coefficients
        function results = PlotCoefficients(app)
            displayformat= app.DisplayFormatDropDown.Value;
            switch(lower(displayformat))
                case 'real-imaginary'
                    stem(app.SpectralCoefficientGraph_1, app.pulsepar.ni, real(app.pulsepar.Fi),'filled' );
                    legend(app.SpectralCoefficientGraph_1,{'Real'})
                    stem(app.SpectralCoefficientGraph_2, app.pulsepar.ni, imag(app.pulsepar.Fi), 'filled' );
                    legend(app.SpectralCoefficientGraph_2,{'Imaginary'})
                    CoordinateYaxes(app, [app.SpectralCoefficientGraph_1,app.SpectralCoefficientGraph_2]);
                    
                case 'magnitude-phase'
                    stem(app.SpectralCoefficientGraph_1, app.pulsepar.ni, abs(app.pulsepar.Fi), 'filled' );
                    legend(app.SpectralCoefficientGraph_1,{'Magnitude'})
                    stem(app.SpectralCoefficientGraph_2, app.pulsepar.ni, angle(app.pulsepar.Fi), 'filled' );
                    legend(app.SpectralCoefficientGraph_2,{'Phase [Rad]'})
                    SetMagnitudeAxis(app, app.SpectralCoefficientGraph_1);
                    SetPhaseAxis(app, app.SpectralCoefficientGraph_2);
            end
            results=0;
        end
        
        % Set y-axes of graphs equal
        function results = CoordinateYaxes(app, ax)
            yax=[];
            for k=1:length(ax)
                set(ax(k),'YLimMode', 'Auto')
                set(ax(k),'YTickMode', 'Auto')
                set(ax(k),'YTickLabelMode', 'Auto')
                yax=[yax, get(ax(k),'ylim')];
            end
            yax= [min(yax) max(yax)];
            for k=1:length(ax)
                set(ax(k),'ylim', yax)
            end
            results=0;
        end
        
        % Set y-axes for phase graph, in radians
        function results = SetPhaseAxis(app, ax)
            set(ax, 'YLim',[-pi,pi]);
            set(ax, 'YTick',(-pi:pi/2:pi));
            set(ax, 'YTickLabel',{'-\pi','-\pi/2','0', '\pi/2','\pi'});
            results=0;
        end
        
        % Set y-axes for magnitude graph
        function results = SetMagnitudeAxis(app, ax)
            set(ax,'YLimMode', 'Auto')
            yax=get(ax,'ylim');
            set(ax,'ylim', [0 max(yax)])
            set(ax,'YTickMode', 'Auto')
            set(ax,'YTickLabelMode', 'Auto')
            results=0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pulse.t = linspace(0,2*pi, 1025)';  % Normalized time vector, one point extra
            app.pulse.t = app.pulse.t(1:end-1);  % Remove last point for exact one cycle
            app.pulsepar.N = length(app.pulse.t);
            app.pulsepar.n0=app.pulsepar.N/2+1;
            app.pulsepar.shape    = app.ShapeDropDown.Value;
            app.pulsepar.dutycycle= app.DutycycleSpinner.Value;
            app.pulsepar.phase    = app.PhaseDegSpinner.Value;
            app.pulsepar.offset   = app.OffsetSpinner.Value;
            app.pulsepar.rectify  = app.RectifyButton.Value;
            app.pulsepar.Nc       = round(app.NoofcoefficientsSpinner.Value);
            CalculateAndPlot(app);
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
            app.pulsepar.shape = app.ShapeDropDown.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: RectifyButton
        function RectifyButtonValueChanged(app, event)
            app.pulsepar.rectify = app.RectifyButton.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: DutycycleSpinner
        function DutycycleSpinnerValueChanged(app, event)
            app.pulsepar.dutycycle = app.DutycycleSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: PhaseDegSpinner
        function PhaseDegSpinnerValueChanged(app, event)
            app.pulsepar.phase = app.PhaseDegSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: OffsetSpinner
        function OffsetSpinnerValueChanged(app, event)
            app.pulsepar.offset = app.OffsetSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: NoofcoefficientsSpinner
        function NoofcoefficientsSpinnerValueChanged(app, event)
            app.pulsepar.Nc = app.NoofcoefficientsSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: DisplayFormatDropDown
        function DisplayFormatDropDownValueChanged(app, event)
            PlotCoefficients(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourierSynthesisUIFigure = uifigure('Visible', 'off');
            app.FourierSynthesisUIFigure.Name = 'Fourier Synthesis';
            app.ShapeLabel = uilabel(app.FourierSynthesisUIFigure);
            app.ShapeLabel.Text = 'Shape ';
            app.ShapeDropDown = uidropdown(app.FourierSynthesisUIFigure);
            app.ShapeDropDown.Items = {'Square', 'Sine', 'Triangle', 'Sawtooth'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Square';
            app.DutycycleLabel = uilabel(app.FourierSynthesisUIFigure);
            app.DutycycleLabel.Text = 'Duty cycle [%] ';
            app.DutycycleSpinner = uispinner(app.FourierSynthesisUIFigure);
            app.DutycycleSpinner.Limits = [0 100];
            app.DutycycleSpinner.ValueChangedFcn = createCallbackFcn(app, @DutycycleSpinnerValueChanged, true);
            app.DutycycleSpinner.Value = 50;
            app.PhaseDegLabel = uilabel(app.FourierSynthesisUIFigure);
            app.PhaseDegLabel.Text = 'Phase [Deg] ';
            app.PhaseDegSpinner = uispinner(app.FourierSynthesisUIFigure);
            app.PhaseDegSpinner.ValueChangedFcn = createCallbackFcn(app, @PhaseDegSpinnerValueChanged, true);
            app.OffsetLabel = uilabel(app.FourierSynthesisUIFigure);
            app.OffsetLabel.Text = 'Offset ';
            app.OffsetSpinner = uispinner(app.FourierSynthesisUIFigure);
            app.OffsetSpinner.Step = 0.1;
            app.OffsetSpinner.Limits = [-1 1];
            app.OffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @OffsetSpinnerValueChanged, true);
            app.FourierSynthesisExampleLabel = uilabel(app.FourierSynthesisUIFigure);
            app.FourierSynthesisExampleLabel.Text = 'Fourier Synthesis Example';
            app.McClellanetalDSPFirst2ndedCh35FourierSeriesLabel = uilabel(app.FourierSynthesisUIFigure);
            app.McClellanetalDSPFirst2ndedCh35FourierSeriesLabel.Text = {'McClellan et al.,'; 'DSP First, 2nd ed.'; 'Ch. 3-5: Fourier Series'};
            app.RectifyButton = uibutton(app.FourierSynthesisUIFigure, 'state');
            app.RectifyButton.ValueChangedFcn = createCallbackFcn(app, @RectifyButtonValueChanged, true);
            app.RectifyButton.Text = 'Rectify';
            app.NoofcoefficientsSpinnerLabel = uilabel(app.FourierSynthesisUIFigure);
            app.NoofcoefficientsSpinnerLabel.Text = 'No. of coefficients ';
            app.NoofcoefficientsSpinner = uispinner(app.FourierSynthesisUIFigure);
            app.NoofcoefficientsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofcoefficientsSpinnerValueChanged, true);
            app.DisplayFormatDropDown = uidropdown(app.FourierSynthesisUIFigure);
            app.DisplayFormatDropDown.Items = {'Real-Imaginary', 'Magnitude-Phase'};
            app.DisplayFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayFormatDropDownValueChanged, true);
            app.DisplayFormatDropDown.Value = 'Real-Imaginary';
            app.SignalGraph = uiaxes(app.FourierSynthesisUIFigure);
            app.SignalGraph.XLim = [0 12.5663706143592];
            app.SignalGraph.YLim = [-2 2];
            app.SignalGraph.XTick = [0 3.14159265358979 6.28318530717959 9.42477796076938 12.5663706143592 15.707963267949 18.8495559215388];
            app.SignalGraph.XTickLabel = '';
            app.SignalGraph.YTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.WaveComponentGraph = uiaxes(app.FourierSynthesisUIFigure);
            app.WaveComponentGraph.XLim = [0 12.5663706143592];
            app.WaveComponentGraph.YLim = [-2 2];
            app.WaveComponentGraph.XTick = [0 3.14159265358979 6.28318530717959 9.42477796076938 12.5663706143592 15.707963267949 18.8495559215388];
            app.WaveComponentGraph.XTickLabel = '';
            app.WaveComponentGraph.YTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.SyntesizedGraph = uiaxes(app.FourierSynthesisUIFigure);
            app.SyntesizedGraph.XLim = [0 12.5663706143592];
            app.SyntesizedGraph.YLim = [-2 2];
            app.SyntesizedGraph.XTick = [0 3.14159265358979 6.28318530717959 9.42477796076938 12.5663706143592 15.707963267949 18.8495559215388];
            app.SyntesizedGraph.XTickLabel = '';
            app.SyntesizedGraph.YTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.SpectralCoefficientGraph_1 = uiaxes(app.FourierSynthesisUIFigure);
            app.SpectralCoefficientGraph_1.XLim = [-20 20];
            app.SpectralCoefficientGraph_1.XTick = [-20 -18 -16 -14 -12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12 14 16 18 20];
            app.SpectralCoefficientGraph_2 = uiaxes(app.FourierSynthesisUIFigure);
            xlabel(app.SpectralCoefficientGraph_2, 'Coefficient No.')
            app.SpectralCoefficientGraph_2.XLim = [-20 20];
            app.SpectralCoefficientGraph_2.XTick = [-20 -18 -16 -14 -12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12 14 16 18 20];
            % Show the figure after all components are created
            app.FourierSynthesisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fouriersynthesis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierSynthesisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierSynthesisUIFigure)
        end
    end
end