classdef SecondOrderResponse_RT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Second_Order_Setep_Response    matlab.ui.Figure
        EigenfrequencyHzLabel          matlab.ui.control.Label
        EigenfrequencyHzSpinner        matlab.ui.control.Spinner
        ResponseGraph                  matlab.ui.control.UIAxes
        SecondOrderSystemStepResponseLabel  matlab.ui.control.Label
        CriticaldampingRatioKnobLabel  matlab.ui.control.Label
        CriticaldampingRatioKnob       matlab.ui.control.Knob
        DampingIndicator               matlab.ui.control.NumericEditField
        DampedEigenfrequencyHzEditFieldLabel  matlab.ui.control.Label
        DampedEigenfrequencyHzEditField  matlab.ui.control.NumericEditField
        PeriodsEditField_2Label        matlab.ui.control.Label
        PeriodsEditField               matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        Gauge                          matlab.ui.control.Gauge
    end

    
    properties (Access = private)
        Response % Description
        Display % Description
    end
    
    methods (Access = private)
        
        function results = CalculateResponse(app)
            app.Response.w0= 2*pi*app.Response.f0;
            app.Response.T0= 1/app.Response.f0;
            if app.Response.xi<1
                app.Response.wd= sqrt(1-app.Response.xi.^2)*app.Response.w0;
                app.Response.Td= (2*pi)/app.Response.wd;
            else
                app.Response.wd= 0;
                app.Response.Td= inf;
            end
            app.DampedEigenfrequencyHzEditField.Value=app.Response.wd/(2*pi);
            app.PeriodsEditField.Value=app.Response.Td;
            app.Response.dt=1/100;
            Tmax= 10;
            app.Response.t = (0:app.Response.dt:5);
            app.Response.s = SecondOrderStepResponse(app,app.Response.w0,app.Response.xi,app.Response.dt );
            results=0;
        end
        
        % Calculate step response from system parameters
        function s = SecondOrderStepResponse(app, w0, xi, dt)
            if xi==1; xi=xi+eps; end            % Avoid division by zero error
            if xi<1
                Tmax= 10/(w0*xi);
            elseif xi>1
                Tmax=10*2*xi/w0;
            end
            t=(0:dt:Tmax);
            Nt= length(t);
            s=zeros(Nt);
            if xi<1
                phi= atan2(-xi, sqrt(1-xi^2));  % Phase
                wd = w0*sqrt(1-xi^2);           % Damped eigenfrequency
                s= 1 - exp(-xi*w0*t).*sqrt(1/(1-xi^2)).*cos(wd*t+phi);
            elseif xi>1
                xid=sqrt(xi^2-1);
                s= 1-(xi+xid)/(2*xid)*exp((-xi+xid)*w0*t) + (xi-xid)/(2*xid)*exp((-xi-xid)*w0*t);
            end
            
        end
        
        % Plot step response in real time
        
        %=== NOT READY
        %========================================================
        function results = PlotResponse(app)
            Nt= length(app.Response.t);
            cla(app.ResponseGraph);
            
            app.an=animatedline(app.ResponseGraph);
            
            tim=timer;
            tim.TimerFcn = @(app,thisEvent) AddPoint;
            t.Period = app.Response.dt;
            t.TasksToExecute = Nt;
            t.ExecutionMode = 'fixedRate';
            start(t)

            results=0;
        end
        
        
        function results = AddPoint(app,event)
            addpoints(app.an, app.Response.t(k), app.Response.s(k) )
            app.Gauge.Value=app.Response.s(k);
            drawnow limitrate
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Response.f0 = app.EigenfrequencyHzSpinner.Value;
            app.Response.xi = app.CriticaldampingRatioKnob.Value;
            app.DampingIndicator.Value= app.Response.xi;
            app.Display.PlotColor=get(app.ResponseGraph,'ColorOrder');
            CalculateResponse(app);
        end

        % Value changed function: EigenfrequencyHzSpinner
        function EigenfrequencyHzSpinnerValueChanged(app, event)
            app.Response.f0 = app.EigenfrequencyHzSpinner.Value;
            CalculateResponse(app);
        end

        % Value changing function: CriticaldampingRatioKnob
        function CriticaldampingRatioKnobValueChanging(app, event)
            changingValue = event.Value;
            app.Response.xi  = changingValue;
            app.DampingIndicator.Value=app.Response.xi;
            CalculateResponse(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            PlotResponse(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Second_Order_Setep_Response = uifigure('Visible', 'off');
            app.Second_Order_Setep_Response.Name = 'Second Order Step Response';
            app.EigenfrequencyHzLabel = uilabel(app.Second_Order_Setep_Response);
            app.EigenfrequencyHzLabel.Text = 'Eigenfrequency [Hz] ';
            app.EigenfrequencyHzSpinner = uispinner(app.Second_Order_Setep_Response);
            app.EigenfrequencyHzSpinner.Step = 0.5;
            app.EigenfrequencyHzSpinner.Limits = [0.01 10];
            app.EigenfrequencyHzSpinner.ValueDisplayFormat = '%5.2f';
            app.EigenfrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @EigenfrequencyHzSpinnerValueChanged, true);
            app.EigenfrequencyHzSpinner.Value = 0.5;
            app.ResponseGraph = uiaxes(app.Second_Order_Setep_Response);
            title(app.ResponseGraph, '')
            xlabel(app.ResponseGraph, 'Time [s]')
            ylabel(app.ResponseGraph, 'Response')
            app.ResponseGraph.XLim = [0 4];
            app.ResponseGraph.YLim = [0 2];
            app.SecondOrderSystemStepResponseLabel = uilabel(app.Second_Order_Setep_Response);
            app.SecondOrderSystemStepResponseLabel.Text = 'Second Order System Step Response';
            app.CriticaldampingRatioKnobLabel = uilabel(app.Second_Order_Setep_Response);
            app.CriticaldampingRatioKnobLabel.Text = 'Critical damping Ratio';
            app.CriticaldampingRatioKnob = uiknob(app.Second_Order_Setep_Response, 'continuous');
            app.CriticaldampingRatioKnob.Limits = [0 2];
            app.CriticaldampingRatioKnob.ValueChangingFcn = createCallbackFcn(app, @CriticaldampingRatioKnobValueChanging, true);
            app.CriticaldampingRatioKnob.Value = 0.5;
            app.DampingIndicator = uieditfield(app.Second_Order_Setep_Response, 'numeric');
            app.DampingIndicator.ValueDisplayFormat = '%6.2f';
            app.DampedEigenfrequencyHzEditFieldLabel = uilabel(app.Second_Order_Setep_Response);
            app.DampedEigenfrequencyHzEditFieldLabel.Text = 'Damped Eigenfrequency [Hz] ';
            app.DampedEigenfrequencyHzEditField = uieditfield(app.Second_Order_Setep_Response, 'numeric');
            app.DampedEigenfrequencyHzEditField.ValueDisplayFormat = '%5.2f';
            app.PeriodsEditField_2Label = uilabel(app.Second_Order_Setep_Response);
            app.PeriodsEditField_2Label.Text = 'Period [s] ';
            app.PeriodsEditField = uieditfield(app.Second_Order_Setep_Response, 'numeric');
            app.PeriodsEditField.ValueDisplayFormat = '%5.2f';
            app.RunButton = uibutton(app.Second_Order_Setep_Response, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Gauge = uigauge(app.Second_Order_Setep_Response, 'circular');
            app.Gauge.Limits = [0 2];
            app.Gauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.Gauge.MinorTicks = 0;
            % Show the figure after all components are created
            app.Second_Order_Setep_Response.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SecondOrderResponse_RT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Second_Order_Setep_Response)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Second_Order_Setep_Response)
        end
    end
end