classdef SecondOrderStepResponse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        First_Order_Setep_Response   matlab.ui.Figure
        UnderdampedIndicator         matlab.ui.control.EditField
        PeriodofdampedoscillationssEditField  matlab.ui.control.NumericEditField
        PeriodofdampedoscillationssEditFieldLabel  matlab.ui.control.Label
        PeriodoffreeoscillationssEditField  matlab.ui.control.NumericEditField
        PeriodoffreeoscillationssEditFieldLabel  matlab.ui.control.Label
        DampedeigenfrequencyHzEditField  matlab.ui.control.NumericEditField
        DampedeigenfrequencyHzEditFieldLabel  matlab.ui.control.Label
        EigenFrequencyIndicator      matlab.ui.control.NumericEditField
        DampingRatioSlider           matlab.ui.control.Slider
        DampingRatioLabel            matlab.ui.control.Label
        EigenfrequencyHzSlider       matlab.ui.control.Slider
        EigenfrequencyHzSliderLabel  matlab.ui.control.Label
        DampingRatioIndicator        matlab.ui.control.NumericEditField
        SecondOrderSystemStepResponseLabel  matlab.ui.control.Label
        ResponseGraph                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Response % Description
    end

    methods (Access = private)

        function results = CalculateAndPlotResponse(app)
            app.Response.s = StepResponse(app, app.Response.t );
            plot(app.ResponseGraph, app.Response.t,  app.Response.s, 'b-' ); hold(app.ResponseGraph,'on' );
            hold(app.ResponseGraph,'on');
            plot(app.ResponseGraph, app.Response.t, ones(size(app.Response.t)),'k--' );
            hold(app.ResponseGraph,'off');

            results=0;
        end

        function s = StepResponse(app, t)
            w0= 2*pi*app.Response.f0;
            xi= app.Response.xi;
            s = zeros(size(t));
            k = find(t>0);
            tp= t(k);  % Equations only valid for positive t

            if xi==1; xi=xi+eps; end            % Avoid division by zero error
            if xi<1
                phi= atan2(-xi, sqrt(1-xi^2));  % Phase
                wd = w0*sqrt(1-xi^2);           % Damped eigenfrequency
                sp= 1 - exp(-xi*w0*tp).*sqrt(1/(1-xi^2)).*cos(wd*tp+phi);
            elseif xi>1
                xid=sqrt(xi^2-1);
                sp= 1-(xi+xid)/(2*xid)*exp((-xi+xid)*w0*tp) + (xi-xid)/(2*xid)*exp((-xi-xid)*w0*tp);
            end
            s(k)=sp;

        end

        function results = FindParameters(app)
            app.Response.T0= 1/app.Response.f0;
            app.Response.underdamped=(app.Response.xi<1);
            if app.Response.underdamped
                app.Response.fd= app.Response.f0*sqrt(1-app.Response.xi^2);
                app.Response.Td= 1/app.Response.fd;
                app.UnderdampedIndicator.Value="Underdamped";
                app.UnderdampedIndicator.BackgroundColor= [0, 0.45, 0.74];
            else
                app.Response.fd= 0;
                app.Response.Td= inf;
                app.UnderdampedIndicator.Value="Overdamped";
                app.UnderdampedIndicator.BackgroundColor= [0.85, 0.33, 0.10];
            end
            app.PeriodoffreeoscillationssEditField.Value  = app.Response.T0;
            app.DampedeigenfrequencyHzEditField.Value     = app.Response.fd;
            app.PeriodofdampedoscillationssEditField.Value= app.Response.Td;
            results=0;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Response.f0= app.EigenfrequencyHzSlider.Value;
            app.Response.xi= 10^app.DampingRatioSlider.Value;

            app.EigenFrequencyIndicator.Value = app.Response.f0;
            app.DampingRatioIndicator.Value=app.Response.xi;

            tlim=get(app.ResponseGraph, 'XLim');
            app.Response.t = linspace( min(tlim), max(tlim), 1000 );

            CalculateAndPlotResponse(app);
        end

        % Callback function: EigenfrequencyHzSlider, 
        % EigenfrequencyHzSlider
        function EigenfrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.Response.f0= changingValue;
            app.EigenFrequencyIndicator.Value=app.Response.f0;
            FindParameters(app);
            CalculateAndPlotResponse(app);
        end

        % Value changed function: DampingRatioIndicator
        function DampingRatioIndicatorValueChanged(app, event)
            app.Response.xi = event.Value;
            app.DampingRatioSlider.Value=app.Response.xi;
            FindParameters(app);
            CalculateAndPlotResponse(app);
        end

        % Callback function: DampingRatioSlider, DampingRatioSlider
        function DampingRatioSliderValueChanged(app, event)
            changingValue = event.Value;
            app.Response.xi  = 10.^changingValue;
            app.DampingRatioIndicator.Value=app.Response.xi;
            FindParameters(app);
            CalculateAndPlotResponse(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.First_Order_Setep_Response = uifigure('Visible', 'off');
            app.First_Order_Setep_Response.Name = 'First Order Step Response';
            app.ResponseGraph = uiaxes(app.First_Order_Setep_Response);
            xlabel(app.ResponseGraph, 'Time [s]')
            ylabel(app.ResponseGraph, 'Response')
            app.ResponseGraph.XLim = [-0.2 4];
            app.ResponseGraph.YLim = [0 2];
            app.SecondOrderSystemStepResponseLabel = uilabel(app.First_Order_Setep_Response);
            app.SecondOrderSystemStepResponseLabel.Text = 'Second Order System. Step Response';
            app.DampingRatioIndicator = uieditfield(app.First_Order_Setep_Response, 'numeric');
            app.DampingRatioIndicator.ValueDisplayFormat = '%5.2f';
            app.DampingRatioIndicator.ValueChangedFcn = createCallbackFcn(app, @DampingRatioIndicatorValueChanged, true);
            app.EigenfrequencyHzSliderLabel = uilabel(app.First_Order_Setep_Response);
            app.EigenfrequencyHzSliderLabel.Text = {'Eigenfrequency '; '[Hz]'};
            app.EigenfrequencyHzSlider = uislider(app.First_Order_Setep_Response);
            app.EigenfrequencyHzSlider.Limits = [0 4];
            app.EigenfrequencyHzSlider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.EigenfrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @EigenfrequencySliderValueChanging, true);
            app.EigenfrequencyHzSlider.ValueChangingFcn = createCallbackFcn(app, @EigenfrequencySliderValueChanging, true);
            app.EigenfrequencyHzSlider.Value = 1;
            app.DampingRatioLabel = uilabel(app.First_Order_Setep_Response);
            app.DampingRatioLabel.Text = {'Damping'; 'Ratio'};
            app.DampingRatioSlider = uislider(app.First_Order_Setep_Response);
            app.DampingRatioSlider.Limits = [-2 1];
            app.DampingRatioSlider.MajorTicks = [-4 -3.9 -3.8 -3.7 -3.6 -3.5 -3.4 -3.3 -3.2 -3.1 -3 -2.9 -2.8 -2.7 -2.6 -2.5 -2.4 -2.3 -2.2 -2.1 -2 -1.9 -1.8 -1.7 -1.6 -1.5 -1.4 -1.3 -1.2 -1.1 -1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.0999999999999996 0 0.100000000000001 0.2 0.3 0.4 0.5 0.600000000000001 0.7 0.800000000000001 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10];
            app.DampingRatioSlider.MajorTickLabels = {''};
            app.DampingRatioSlider.ValueChangedFcn = createCallbackFcn(app, @DampingRatioSliderValueChanged, true);
            app.DampingRatioSlider.ValueChangingFcn = createCallbackFcn(app, @DampingRatioSliderValueChanged, true);
            app.DampingRatioSlider.MinorTicks = [];
            app.DampingRatioSlider.Value = -0.1549;
            app.EigenFrequencyIndicator = uieditfield(app.First_Order_Setep_Response, 'numeric');
            app.EigenFrequencyIndicator.Limits = [0 Inf];
            app.EigenFrequencyIndicator.ValueDisplayFormat = '%5.1f';
            app.DampedeigenfrequencyHzEditFieldLabel = uilabel(app.First_Order_Setep_Response);
            app.DampedeigenfrequencyHzEditFieldLabel.Text = 'Damped eigenfrequency [Hz]';
            app.DampedeigenfrequencyHzEditField = uieditfield(app.First_Order_Setep_Response, 'numeric');
            app.DampedeigenfrequencyHzEditField.ValueDisplayFormat = '%5.2f';
            app.PeriodoffreeoscillationssEditFieldLabel = uilabel(app.First_Order_Setep_Response);
            app.PeriodoffreeoscillationssEditFieldLabel.Text = 'Period of free oscillations [s]';
            app.PeriodoffreeoscillationssEditField = uieditfield(app.First_Order_Setep_Response, 'numeric');
            app.PeriodoffreeoscillationssEditField.ValueDisplayFormat = '%5.2f';
            app.PeriodofdampedoscillationssEditFieldLabel = uilabel(app.First_Order_Setep_Response);
            app.PeriodofdampedoscillationssEditFieldLabel.Text = 'Period of damped oscillations [s]';
            app.PeriodofdampedoscillationssEditField = uieditfield(app.First_Order_Setep_Response, 'numeric');
            app.PeriodofdampedoscillationssEditField.ValueDisplayFormat = '%5.2f';
            app.UnderdampedIndicator = uieditfield(app.First_Order_Setep_Response, 'text');
            app.UnderdampedIndicator.Value = 'Underdamped';
            % Show the figure after all components are created
            app.First_Order_Setep_Response.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SecondOrderStepResponse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.First_Order_Setep_Response)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.First_Order_Setep_Response)
        end
    end
end