classdef aliasing_frequencydomain_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AliasingFrequencySpectrum    matlab.ui.Figure
        Image                        matlab.ui.control.Image
        showaliased                  matlab.ui.control.CheckBox
        McClellanetalDSPFirst2ndedCh4SamplingandAliasingLabel  matlab.ui.control.Label
        ReconstructedFrequencyEditField  matlab.ui.control.NumericEditField
        ReconstructedFrequencyLabel  matlab.ui.control.Label
        AliasingFrequencyRepresentationLabel  matlab.ui.control.Label
        SamplerateSpinner            matlab.ui.control.Spinner
        SamplerateLabel              matlab.ui.control.Label
        OriginalfrequencySpinner     matlab.ui.control.Spinner
        OriginalfrequencyLabel       matlab.ui.control.Label
        ReconstructedFrequencyGraph  matlab.ui.control.UIAxes
        OriginalFrequencyGraph       matlab.ui.control.UIAxes
        AliasedFrequencyGraph        matlab.ui.control.UIAxes
        ReconstructedSignalGraph     matlab.ui.control.UIAxes
        SamplingSignalGraph          matlab.ui.control.UIAxes
        OriginalSignalGraph          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        CurvePar % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            
            fs= app.CurvePar.fs;
            f = app.CurvePar.f;
            
            n = -10:10;
            fp= f+n*fs;
            fn=-f+n*fs;
            f1= unique([fn fp]);
            y1= ones(size(f1));
            
            fa=min(abs(f1));
            
            app.ReconstructedFrequencyEditField.Value= fa;
            
            ts = (0:1/fs:app.CurvePar.N);
            ti = (0:1/(100*fs):app.CurvePar.N);
            
            ss =cos(2*pi*f *ts);
            s0 =cos(2*pi*f *ti);
            sr =cos(2*pi*fa*ti);
            
            app.CurvePar.original= plot(app.OriginalSignalGraph,ti,s0,'r-');
            app.CurvePar.samples= stem(app.SamplingSignalGraph,ts,ss,'bo');
            hold (app.SamplingSignalGraph,"on")
            app.CurvePar.original2= plot(app.SamplingSignalGraph,ti,s0,'r:');
            app.CurvePar.aliased= plot(app.SamplingSignalGraph,ti,sr,'b-');
            hold (app.SamplingSignalGraph,"off")
            app.CurvePar.iplot= plot(app.ReconstructedSignalGraph,ti,sr,'b-');
            
            ShowPlot(app,app.CurvePar.aliased,   app.showaliased.Value );
            
            stem(app.OriginalFrequencyGraph,[f -f],[1 1],'r-');
            
            stem(app.AliasedFrequencyGraph,f1, y1,'b-');
            hold (app.AliasedFrequencyGraph, 'on');
            stem(app.AliasedFrequencyGraph,[f -f],[1 1],'r-');
            hold (app.AliasedFrequencyGraph, 'off');
            yl=ylim(app.AliasedFrequencyGraph);
            patch(app.AliasedFrequencyGraph, [-fs +fs +fs -fs]/2, [yl(1) yl(1) yl(2) yl(2)],  [0 0 1], 'Edgecolor', 'none', 'FaceAlpha', 0.2);
            
            stem(app.ReconstructedFrequencyGraph,[fa -fa],[1 1],'b-');

            results=0;
        end
        
        function results = ShowPlot(app,ax,on)
            if(on)
                show='on';
            else
                show='off';
            end
            set(ax,'Visible',show)
            results=0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurvePar.N  = 3000;
            app.CurvePar.f  = app.OriginalfrequencySpinner.Value;
            app.CurvePar.fs = app.SamplerateSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: OriginalfrequencySpinner
        function OriginalfrequencySpinnerValueChanged(app, event)
            app.CurvePar.f = app.OriginalfrequencySpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: SamplerateSpinner
        function SamplerateSpinnerValueChanged(app, event)
            app.CurvePar.fs = app.SamplerateSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: showaliased
        function showaliasedValueChanged(app, event)
            ShowPlot(app,app.CurvePar.aliased,   app.showaliased.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AliasingFrequencySpectrum = uifigure('Visible', 'off');
            app.AliasingFrequencySpectrum.Name = 'UI Figure';
            app.OriginalSignalGraph = uiaxes(app.AliasingFrequencySpectrum);
            xlabel(app.OriginalSignalGraph, 'Time [s]')
            ylabel(app.OriginalSignalGraph, 'Amplitude [s]')
            app.OriginalSignalGraph.XLim = [0 10];
            app.OriginalSignalGraph.YLim = [-1.2 1.2];
            app.SamplingSignalGraph = uiaxes(app.AliasingFrequencySpectrum);
            xlabel(app.SamplingSignalGraph, 'Time [s]')
            ylabel(app.SamplingSignalGraph, 'Amplitude [s]')
            app.SamplingSignalGraph.XLim = [0 10];
            app.SamplingSignalGraph.YLim = [-1.2 1.2];
            app.ReconstructedSignalGraph = uiaxes(app.AliasingFrequencySpectrum);
            xlabel(app.ReconstructedSignalGraph, 'Time [s]')
            ylabel(app.ReconstructedSignalGraph, 'Amplitude [s]')
            app.ReconstructedSignalGraph.XLim = [0 10];
            app.ReconstructedSignalGraph.YLim = [-1.2 1.2];
            app.AliasedFrequencyGraph = uiaxes(app.AliasingFrequencySpectrum);
            xlabel(app.AliasedFrequencyGraph, 'Frequency')
            app.AliasedFrequencyGraph.XLim = [-2 2];
            app.AliasedFrequencyGraph.YLim = [0 1.5];
            app.OriginalFrequencyGraph = uiaxes(app.AliasingFrequencySpectrum);
            xlabel(app.OriginalFrequencyGraph, 'Frequency')
            app.OriginalFrequencyGraph.XLim = [-2 2];
            app.OriginalFrequencyGraph.YLim = [0 1.5];
            app.ReconstructedFrequencyGraph = uiaxes(app.AliasingFrequencySpectrum);
            xlabel(app.ReconstructedFrequencyGraph, 'Frequency')
            app.ReconstructedFrequencyGraph.XLim = [-2 2];
            app.ReconstructedFrequencyGraph.YLim = [0 1.5];
            app.OriginalfrequencyLabel = uilabel(app.AliasingFrequencySpectrum);
            app.OriginalfrequencyLabel.Text = 'Original frequency ';
            app.OriginalfrequencySpinner = uispinner(app.AliasingFrequencySpectrum);
            app.OriginalfrequencySpinner.Step = 0.1;
            app.OriginalfrequencySpinner.Limits = [0 10];
            app.OriginalfrequencySpinner.ValueDisplayFormat = '%11.2g';
            app.OriginalfrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @OriginalfrequencySpinnerValueChanged, true);
            app.OriginalfrequencySpinner.Value = 0.2;
            app.SamplerateLabel = uilabel(app.AliasingFrequencySpectrum);
            app.SamplerateLabel.Text = 'Sample rate ';
            app.SamplerateSpinner = uispinner(app.AliasingFrequencySpectrum);
            app.SamplerateSpinner.Step = 0.1;
            app.SamplerateSpinner.Limits = [0 100];
            app.SamplerateSpinner.ValueDisplayFormat = '%11.2g';
            app.SamplerateSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplerateSpinnerValueChanged, true);
            app.SamplerateSpinner.Value = 1;
            app.AliasingFrequencyRepresentationLabel = uilabel(app.AliasingFrequencySpectrum);
            app.AliasingFrequencyRepresentationLabel.Text = 'Aliasing - Frequency Representation';
            app.ReconstructedFrequencyLabel = uilabel(app.AliasingFrequencySpectrum);
            app.ReconstructedFrequencyLabel.Text = 'Reconstructed Frequency ';
            app.ReconstructedFrequencyEditField = uieditfield(app.AliasingFrequencySpectrum, 'numeric');
            app.McClellanetalDSPFirst2ndedCh4SamplingandAliasingLabel = uilabel(app.AliasingFrequencySpectrum);
            app.McClellanetalDSPFirst2ndedCh4SamplingandAliasingLabel.Text = {'McClellan et al.,DSP First, 2nd ed.'; 'Ch. 4: Sampling and Aliasing'};
            app.showaliased = uicheckbox(app.AliasingFrequencySpectrum);
            app.showaliased.ValueChangedFcn = createCallbackFcn(app, @showaliasedValueChanged, true);
            app.showaliased.Text = '';
            app.Image = uiimage(app.AliasingFrequencySpectrum);
            app.Image.ImageSource = 'USN_logo_En_rgb.png';
            % Show the figure after all components are created
            app.AliasingFrequencySpectrum.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aliasing_frequencydomain_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AliasingFrequencySpectrum)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AliasingFrequencySpectrum)
        end
    end
end