classdef aliasing_simple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Aliasing           matlab.ui.Figure
        McClellanetalDSPFirst2ndedCh4SamplingandAliasingLabel  matlab.ui.control.Label
        AliasingDemoLabel  matlab.ui.control.Label
        SampleRateSpinner  matlab.ui.control.Spinner
        SampleRateLabel    matlab.ui.control.Label
        PhaseDegSpinner    matlab.ui.control.Spinner
        PhaseDegLabel      matlab.ui.control.Label
        FrequencySpinner   matlab.ui.control.Spinner
        FrequencyLabel     matlab.ui.control.Label
        SignalGraph        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        CurvePar % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            t = app.CurvePar.t;
            fs= app.CurvePar.fs;
            
            s0 =sin(2*pi*app.CurvePar.f*t+app.CurvePar.phase);

            plot(app.SignalGraph,app.CurvePar.t,s0, 'b:');
            hold (app.SignalGraph,"on")
           
            ts=(min(t):1/fs:max(t));
            ss= interp1(t,s0,ts,'pchip');
            stem(app.SignalGraph,ts,ss, 'bo');                       
            [si,ti] = resample(ss,ts,app.CurvePar.fs0,'spline');
            plot(app.SignalGraph,ti,si, 'r-');
            
           hold (app.SignalGraph,"off")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurvePar.N  = 3001;
            T=10;
            app.CurvePar.t  = linspace(-1/2*T,T,app.CurvePar.N);
            app.CurvePar.fs0=  1/(app.CurvePar.t(2)-app.CurvePar.t(1));            
            app.CurvePar.f  = app.FrequencySpinner.Value;
            app.CurvePar.phase= deg2rad(app.PhaseDegSpinner.Value);
            app.CurvePar.fs   = app.SampleRateSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: FrequencySpinner
        function FrequencySpinnerValueChanged(app, event)
            app.CurvePar.f = app.FrequencySpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: SampleRateSpinner
        function SampleRateSpinnerValueChanged(app, event)
            app.CurvePar.fs = app.SampleRateSpinner.Value;
            CalculateAndPlot(app);
            
        end

        % Value changed function: PhaseDegSpinner
        function PhaseDegSpinnerValueChanged(app, event)
            app.CurvePar.phase= deg2rad(app.PhaseDegSpinner.Value);
            CalculateAndPlot(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Aliasing = uifigure('Visible', 'off');
            app.Aliasing.Name = 'Aliasing';
            app.SignalGraph = uiaxes(app.Aliasing);
            xlabel(app.SignalGraph, 'Time [s]')
            ylabel(app.SignalGraph, 'Amplitude [s]')
            app.SignalGraph.XLim = [0 6];
            app.SignalGraph.YLim = [-1.2 1.2];
            app.FrequencyLabel = uilabel(app.Aliasing);
            app.FrequencyLabel.Text = 'Frequency ';
            app.FrequencySpinner = uispinner(app.Aliasing);
            app.FrequencySpinner.Step = 0.1;
            app.FrequencySpinner.Limits = [0 10];
            app.FrequencySpinner.ValueDisplayFormat = '%11.2g';
            app.FrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencySpinnerValueChanged, true);
            app.FrequencySpinner.Value = 2;
            app.PhaseDegLabel = uilabel(app.Aliasing);
            app.PhaseDegLabel.Text = 'Phase [Deg] ';
            app.PhaseDegSpinner = uispinner(app.Aliasing);
            app.PhaseDegSpinner.ValueDisplayFormat = '%5.1f';
            app.PhaseDegSpinner.ValueChangedFcn = createCallbackFcn(app, @PhaseDegSpinnerValueChanged, true);
            app.SampleRateLabel = uilabel(app.Aliasing);
            app.SampleRateLabel.Text = 'Sample Rate ';
            app.SampleRateSpinner = uispinner(app.Aliasing);
            app.SampleRateSpinner.Step = 0.1;
            app.SampleRateSpinner.Limits = [0 100];
            app.SampleRateSpinner.ValueDisplayFormat = '%11.2g';
            app.SampleRateSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleRateSpinnerValueChanged, true);
            app.SampleRateSpinner.Value = 1.5;
            app.AliasingDemoLabel = uilabel(app.Aliasing);
            app.AliasingDemoLabel.Text = 'Aliasing Demo';
            app.McClellanetalDSPFirst2ndedCh4SamplingandAliasingLabel = uilabel(app.Aliasing);
            app.McClellanetalDSPFirst2ndedCh4SamplingandAliasingLabel.Text = {'McClellan et al.,'; 'DSP First, 2nd ed.'; 'Ch. 4: Sampling and Aliasing'};
            % Show the figure after all components are created
            app.Aliasing.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aliasing_simple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Aliasing)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Aliasing)
        end
    end
end