classdef moire_sampling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MoiresamplingUIFigure    matlab.ui.Figure
        SamplesperperiodSpinner  matlab.ui.control.Spinner
        SamplesperperiodLabel    matlab.ui.control.Label
        AliasinginImagesMoirepatternsLabel  matlab.ui.control.Label
        NoofStripesSpinner       matlab.ui.control.Spinner
        NoofStripesLabel         matlab.ui.control.Label
        WhiteSpinner             matlab.ui.control.Spinner
        WhiteLabel               matlab.ui.control.Label
        BlackSpinner             matlab.ui.control.Spinner
        BlackLabel               matlab.ui.control.Label
        ResampledGraph           matlab.ui.control.UIAxes
        ResampledImage           matlab.ui.control.UIAxes
        Graph                    matlab.ui.control.UIAxes
        Image                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imagepar % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            
            Np=100;   % Pixels per period
            Graphcolor=[0 0.4470 0.7410];
            
            % Define image
            b=  ones(round(app.imagepar.bw/100*Np),  1)';
            h= zeros(round(app.imagepar.ww/100*Np), 1)';
            x= repmat([b h], 10, app.imagepar.Ns);
            [Mx,Nx]=size(x);
            nx=1:Nx;
            
            % Resample image
            r= round(Np/app.imagepar.r);
            nr=(1:r:Nx);
            xr=x(:,nr);
            [Mr,Nr]=size(xr);
            
            % Show image
            imagesc(app.Image, nx, [0 1], x)
            colormap(app.Image, "copper")
            hold(app.Image,"on");
            plot(app.Image, nr, 1*ones(size(nr)), 'o','MarkerEdgeColor','r','MarkerFaceColor','r','MarkerSize',5);
            axis(app.Image,[0 Nx 0 1])
            hold(app.Image,"off");
            
            % Show intensity graph
            stairs(app.Graph, nx, x(1,:), 'color', Graphcolor );
            hold(app.Graph, "on");
            plot(app.Graph, nr, xr(1,:), 'o','MarkerEdgeColor','r','MarkerFaceColor','r','MarkerSize',5);
            ylim(app.Graph, [-0.1 1.1])
            hold(app.Graph, "off");
            
            % Show resampled image
            imagesc(app.ResampledImage, nx, [0 1],xr)
            colormap(app.ResampledImage, "copper")
            axis(app.ResampledImage,[0 Nx 0 1])
            
            % Show resampled graph
            stairs(app.ResampledGraph, nr, xr(1,:), 'color', 'r' );
            xlim(app.ResampledGraph, [min(nr) max(nr)])
            ylim(app.ResampledGraph, [-0.1 1.1])
            grid(app.ResampledGraph, "on");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imagepar.bw= app.BlackSpinner.Value;
            app.imagepar.ww= app.WhiteSpinner.Value;
            app.imagepar.Ns= app.NoofStripesSpinner.Value;
            app.imagepar.r = app.SamplesperperiodSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: NoofStripesSpinner, 
        % ...and 1 other component
        function SpinnerValueChanged(app, event)
            app.imagepar.Ns= app.NoofStripesSpinner.Value;
            app.imagepar.r = app.SamplesperperiodSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: BlackSpinner
        function BlackSpinnerValueChanged(app, event)
            app.imagepar.bw= app.BlackSpinner.Value;
            app.imagepar.ww=100-app.imagepar.bw;
            app.WhiteSpinner.Value=app.imagepar.ww;
            CalculateAndPlot(app);
        end

        % Value changed function: WhiteSpinner
        function WhiteSpinnerValueChanged(app, event)
            app.imagepar.ww= app.WhiteSpinner.Value;
            app.imagepar.bw=100-app.imagepar.ww;
            app.BlackSpinner.Value=app.imagepar.bw;
            CalculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MoiresamplingUIFigure = uifigure('Visible', 'off');
            app.MoiresamplingUIFigure.Name = 'Moire sampling';
            app.Image = uiaxes(app.MoiresamplingUIFigure);
            title(app.Image, 'Original Image')
            app.Image.XTick = [];
            app.Image.YTick = [];
            app.Graph = uiaxes(app.MoiresamplingUIFigure);
            title(app.Graph, 'Intensity Graph')
            app.Graph.XTick = [];
            app.Graph.YTick = [];
            app.ResampledImage = uiaxes(app.MoiresamplingUIFigure);
            title(app.ResampledImage, 'Resampled Image')
            app.ResampledImage.XTick = [];
            app.ResampledImage.YTick = [];
            app.ResampledGraph = uiaxes(app.MoiresamplingUIFigure);
            title(app.ResampledGraph, 'Resampled Graph')
            app.ResampledGraph.XTick = [];
            app.ResampledGraph.YTick = [];
            app.BlackLabel = uilabel(app.MoiresamplingUIFigure);
            app.BlackLabel.Text = 'Black [%] ';
            app.BlackSpinner = uispinner(app.MoiresamplingUIFigure);
            app.BlackSpinner.Limits = [0 100];
            app.BlackSpinner.ValueChangedFcn = createCallbackFcn(app, @BlackSpinnerValueChanged, true);
            app.BlackSpinner.Value = 70;
            app.WhiteLabel = uilabel(app.MoiresamplingUIFigure);
            app.WhiteLabel.Text = 'White [%] ';
            app.WhiteSpinner = uispinner(app.MoiresamplingUIFigure);
            app.WhiteSpinner.Limits = [0 100];
            app.WhiteSpinner.ValueDisplayFormat = '%4d';
            app.WhiteSpinner.ValueChangedFcn = createCallbackFcn(app, @WhiteSpinnerValueChanged, true);
            app.WhiteSpinner.Value = 30;
            app.NoofStripesLabel = uilabel(app.MoiresamplingUIFigure);
            app.NoofStripesLabel.Text = 'No. of Stripes ';
            app.NoofStripesSpinner = uispinner(app.MoiresamplingUIFigure);
            app.NoofStripesSpinner.Limits = [1 100];
            app.NoofStripesSpinner.RoundFractionalValues = 'on';
            app.NoofStripesSpinner.ValueDisplayFormat = '%4d';
            app.NoofStripesSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.NoofStripesSpinner.Value = 20;
            app.AliasinginImagesMoirepatternsLabel = uilabel(app.MoiresamplingUIFigure);
            app.AliasinginImagesMoirepatternsLabel.Text = {'Aliasing in Images'; 'Moire-patterns'};
            app.SamplesperperiodLabel = uilabel(app.MoiresamplingUIFigure);
            app.SamplesperperiodLabel.Text = 'Samples per period ';
            app.SamplesperperiodSpinner = uispinner(app.MoiresamplingUIFigure);
            app.SamplesperperiodSpinner.Step = 0.1;
            app.SamplesperperiodSpinner.Limits = [0.01 100];
            app.SamplesperperiodSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.SamplesperperiodSpinner.Value = 1.6;
            % Show the figure after all components are created
            app.MoiresamplingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = moire_sampling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MoiresamplingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MoiresamplingUIFigure)
        end
    end
end