classdef moirepattern_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PhaseshiftPeriodsSpinner       matlab.ui.control.Spinner
        PhaseshiftPeriodsSpinnerLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        MoirePatternDemoLabel          matlab.ui.control.Label
        FrequencydifferenceRelativeSpinner  matlab.ui.control.Spinner
        FrequencydifferenceRelativeSpinnerLabel  matlab.ui.control.Label
        LinethicknessSpinner           matlab.ui.control.Spinner
        LinethicknessSpinnerLabel      matlab.ui.control.Label
        NumberoflinesSpinner           matlab.ui.control.Spinner
        NumberoflinesLabel             matlab.ui.control.Label
        MoireImage                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pattern % Description
    end
    
    methods (Access = private)
        
        function results = CalculateandPlot(app)
            Np= 1001;        % Number of points
            f1=1;                       % Frequency of upper pattern
            f2=f1*(1+app.pattern.df);   % Frequency of lower pattern
            shift=app.pattern.shift*2*pi;

            Im1 = CreatePattern(app, f1, 0,     Np, 1);
            Im2 = CreatePattern(app, f2, shift, Np, 0);

            Im=sign(Im1+Im2); % Merge upper and lower images
            [Mi,Ni]=size(Im);

            imagesc(app.MoireImage, Im);
            colormap(app.MoireImage, 1-gray);
            axis(app.MoireImage, [0 Ni 0 Mi])

            results=1;
        end
        
        function Im = CreatePattern(app, f, shift, Np, upper)
            x = linspace(0, app.pattern.N*2*pi, Np);
            s = (square( f*x+shift, 100*app.pattern.g )+1)/2; % Create stripe pattern
            Ny= 100;
            Nz= 25;
            sM= repmat(s,Ny,1);          % Create image from pattern
            z  = zeros(Nz, Np);
            
            if upper
                Im=[sM;z];   % Image above zeros
            else
                Im=[z;sM];   % Image below zeros
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pattern.df= app.FrequencydifferenceRelativeSpinner.Value;
            app.pattern.N = app.NumberoflinesSpinner.Value;
            app.pattern.g = app.LinethicknessSpinner.Value;
            app.pattern.shift = app.PhaseshiftPeriodsSpinner.Value;
            CalculateandPlot(app);

        end

        % Value changed function: FrequencydifferenceRelativeSpinner
        function FrequencydifferenceRelativeSpinnerValueChanged(app, event)
            app.pattern.df = app.FrequencydifferenceRelativeSpinner.Value;
            CalculateandPlot(app);
        end

        % Value changed function: NumberoflinesSpinner
        function NumberoflinesSpinnerValueChanged(app, event)
            app.pattern.N = app.NumberoflinesSpinner.Value;
            CalculateandPlot(app);
        end

        % Value changed function: LinethicknessSpinner
        function LinethicknessSpinnerValueChanged(app, event)
            app.pattern.g = app.LinethicknessSpinner.Value;
            CalculateandPlot(app);
        end

        % Value changed function: PhaseshiftPeriodsSpinner
        function PhaseshiftPeriodsSpinnerValueChanged(app, event)
            app.pattern.shift = app.PhaseshiftPeriodsSpinner.Value;
            CalculateandPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MoireImage = uiaxes(app.UIFigure);
            app.MoireImage.XTick = [];
            app.MoireImage.YTick = [];
            app.NumberoflinesLabel = uilabel(app.UIFigure);
            app.NumberoflinesLabel.Text = 'Number of lines ';
            app.NumberoflinesSpinner = uispinner(app.UIFigure);
            app.NumberoflinesSpinner.Limits = [0 100];
            app.NumberoflinesSpinner.RoundFractionalValues = 'on';
            app.NumberoflinesSpinner.ValueDisplayFormat = '%3d';
            app.NumberoflinesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberoflinesSpinnerValueChanged, true);
            app.NumberoflinesSpinner.Value = 40;
            app.LinethicknessSpinnerLabel = uilabel(app.UIFigure);
            app.LinethicknessSpinnerLabel.Text = 'Line thickness';
            app.LinethicknessSpinner = uispinner(app.UIFigure);
            app.LinethicknessSpinner.Step = 0.05;
            app.LinethicknessSpinner.Limits = [0 1];
            app.LinethicknessSpinner.ValueDisplayFormat = '%11.2f';
            app.LinethicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @LinethicknessSpinnerValueChanged, true);
            app.LinethicknessSpinner.Value = 0.25;
            app.FrequencydifferenceRelativeSpinnerLabel = uilabel(app.UIFigure);
            app.FrequencydifferenceRelativeSpinnerLabel.Text = 'Frequency difference [Relative]';
            app.FrequencydifferenceRelativeSpinner = uispinner(app.UIFigure);
            app.FrequencydifferenceRelativeSpinner.Step = 0.05;
            app.FrequencydifferenceRelativeSpinner.Limits = [-1 1];
            app.FrequencydifferenceRelativeSpinner.ValueDisplayFormat = '%11.2f';
            app.FrequencydifferenceRelativeSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencydifferenceRelativeSpinnerValueChanged, true);
            app.FrequencydifferenceRelativeSpinner.Value = 0.2;
            app.MoirePatternDemoLabel = uilabel(app.UIFigure);
            app.MoirePatternDemoLabel.Text = 'Moire Pattern Demo';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'USN_logo_En_rgb.png';
            app.PhaseshiftPeriodsSpinnerLabel = uilabel(app.UIFigure);
            app.PhaseshiftPeriodsSpinnerLabel.Text = 'Phase shift [Periods]';
            app.PhaseshiftPeriodsSpinner = uispinner(app.UIFigure);
            app.PhaseshiftPeriodsSpinner.Step = 0.05;
            app.PhaseshiftPeriodsSpinner.Limits = [-1 1];
            app.PhaseshiftPeriodsSpinner.ValueDisplayFormat = '%11.2f';
            app.PhaseshiftPeriodsSpinner.ValueChangedFcn = createCallbackFcn(app, @PhaseshiftPeriodsSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = moirepattern_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end