classdef first_order_frequency_response < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        First_Order_Setep_Response  matlab.ui.Figure
        CutofffrequencyHzEditField  matlab.ui.control.NumericEditField
        CutofffrequencyHzLabel      matlab.ui.control.Label
        TimeConstantsSlider         matlab.ui.control.Slider
        TimeConstantsSliderLabel    matlab.ui.control.Label
        TimeConstantIndicator       matlab.ui.control.NumericEditField
        FirstOrderSystemFrequencyResponseLabel  matlab.ui.control.Label
        PhaseGraph                  matlab.ui.control.UIAxes
        MagnitudeGraph              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Response % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlotResponse(app)
            flim=get(app.MagnitudeGraph, 'XLim');
            logflim= log10(flim);
            app.Response.f = logspace( min(logflim),max(logflim),300 );
            fc= 1/(2*pi*app.Response.tau);          
            
            app.Response.H = FrequencyResponse(app,app.Response.f,fc );
            plot(app.MagnitudeGraph, app.Response.f,  20*log10(abs(app.Response.H)), 'b-' ); hold(app.MagnitudeGraph,'on'  );
            plot(app.MagnitudeGraph, app.Response.f,  -0*ones(size(app.Response.H)), 'k-' ); 
            plot(app.MagnitudeGraph, app.Response.f,  -3*ones(size(app.Response.H)), 'k-' ); hold(app.MagnitudeGraph,'off' );
            
            plot(app.PhaseGraph,     app.Response.f,  rad2deg(angle(app.Response.H)), 'b-' ); hold(app.PhaseGraph,'on'  );
            plot(app.PhaseGraph,     app.Response.f,  -45*ones(size(app.Response.H)), 'k-' ); hold(app.PhaseGraph,'off' );
            
            results=0;
        end
        
        function H = FrequencyResponse(app, f, fc)
            H=1./(1+1i*f/fc);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Response.tau = app.TimeConstantsSlider.Value;
            app.TimeConstantIndicator.Value=app.Response.tau;
            CalculateAndPlotResponse(app);
        end

        % Value changing function: TimeConstantsSlider
        function TimeConstantsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Response.tau= 10.^(changingValue);
            app.Response.fc = 1/(2*pi*app.Response.tau);
            app.TimeConstantIndicator.Value     = app.Response.tau;
            app.CutofffrequencyHzEditField.Value= app.Response.fc;
            CalculateAndPlotResponse(app);
        end

        % Value changed function: TimeConstantIndicator
        function TimeConstantIndicatorValueChanged(app, event)
            app.Response.tau = app.TimeConstantIndicator.Value;
            app.TimeConstantsSlider.Value=app.Response.tau;
            CalculateAndPlotResponse(app);
        end

        % Value changed function: CutofffrequencyHzEditField
        function CutofffrequencyHzEditFieldValueChanged(app, event)
            app.Response.fc = app.CutofffrequencyHzEditField.Value;
            app.Response.tau= 1/(2*pi*app.Response.fc);
            app.TimeConstantIndicator.Value= app.Response.tau;
            app.TimeConstantsSlider.Value  = app.Response.tau;
            CalculateAndPlotResponse(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.First_Order_Setep_Response = uifigure('Visible', 'off');
            app.First_Order_Setep_Response.Name = 'First Order Step Response';
            app.MagnitudeGraph = uiaxes(app.First_Order_Setep_Response);
            xlabel(app.MagnitudeGraph, 'Frequency [Hz]')
            ylabel(app.MagnitudeGraph, 'Magnitude [dB]')
            app.MagnitudeGraph.XLim = [0.1 1000];
            app.MagnitudeGraph.YLim = [-30 2];
            app.MagnitudeGraph.XScale = 'log';
            app.MagnitudeGraph.YTick = [-30 -27 -24 -21 -18 -15 -12 -9 -6 -3 0];
            app.PhaseGraph = uiaxes(app.First_Order_Setep_Response);
            xlabel(app.PhaseGraph, 'Frequency [Hz]')
            ylabel(app.PhaseGraph, 'Phase [Deg]')
            app.PhaseGraph.XLim = [0.1 1000];
            app.PhaseGraph.YLim = [-90 0];
            app.PhaseGraph.XScale = 'log';
            app.FirstOrderSystemFrequencyResponseLabel = uilabel(app.First_Order_Setep_Response);
            app.FirstOrderSystemFrequencyResponseLabel.Text = 'First Order System. Frequency Response';
            app.TimeConstantIndicator = uieditfield(app.First_Order_Setep_Response, 'numeric');
            app.TimeConstantIndicator.Limits = [0 Inf];
            app.TimeConstantIndicator.ValueDisplayFormat = '%5.2e';
            app.TimeConstantIndicator.ValueChangedFcn = createCallbackFcn(app, @TimeConstantIndicatorValueChanged, true);
            app.TimeConstantsSliderLabel = uilabel(app.First_Order_Setep_Response);
            app.TimeConstantsSliderLabel.Text = {'Time Constant'; '[s]'};
            app.TimeConstantsSlider = uislider(app.First_Order_Setep_Response);
            app.TimeConstantsSlider.Limits = [-4 1];
            app.TimeConstantsSlider.MajorTicks = [-4 1];
            app.TimeConstantsSlider.MajorTickLabels = {' ', ' '};
            app.TimeConstantsSlider.ValueChangingFcn = createCallbackFcn(app, @TimeConstantsSliderValueChanging, true);
            app.TimeConstantsSlider.MinorTicks = [-4 -3.9 -3.8 -3.7 -3.6 -3.5 -3.4 -3.3 -3.2 -3.1 -3 -2.9 -2.8 -2.7 -2.6 -2.5 -2.4 -2.3 -2.2 -2.1 -2 -1.9 -1.8 -1.7 -1.6 -1.5 -1.4 -1.3 -1.2 -1.1 -1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.0999999999999996 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TimeConstantsSlider.Value = 0.1;
            app.CutofffrequencyHzLabel = uilabel(app.First_Order_Setep_Response);
            app.CutofffrequencyHzLabel.Text = 'Cut-off frequency [Hz] ';
            app.CutofffrequencyHzEditField = uieditfield(app.First_Order_Setep_Response, 'numeric');
            app.CutofffrequencyHzEditField.ValueDisplayFormat = '%6.2f';
            app.CutofffrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @CutofffrequencyHzEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.First_Order_Setep_Response.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = first_order_frequency_response

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.First_Order_Setep_Response)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.First_Order_Setep_Response)
        end
    end
end