classdef first_order_step_response < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        First_Order_Setep_Response  matlab.ui.Figure
        TimeConstantsSlider         matlab.ui.control.Slider
        TimeConstantsSliderLabel    matlab.ui.control.Label
        TimeConstantIndicator       matlab.ui.control.NumericEditField
        FirstOrderSystemStepResponseLabel  matlab.ui.control.Label
        InitialvalueSpinner         matlab.ui.control.Spinner
        InitialvalueLabel           matlab.ui.control.Label
        FinalvalueSpinner           matlab.ui.control.Spinner
        FinalvalueLabel             matlab.ui.control.Label
        ResponseGraph               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Response % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlotResponse(app)
            tlim=get(app.ResponseGraph, 'XLim');
            app.Response.t = linspace( min(tlim), max(tlim), 1000 );
            app.Response.tm= app.Response.tau*(0:10);
            app.Response.s = StepResponse(app,app.Response.s0,app.Response.sinf,app.Response.tau,app.Response.t );
            app.Response.sm= StepResponse(app,app.Response.s0,app.Response.sinf,app.Response.tau,app.Response.tm);
            plot(app.ResponseGraph, app.Response.t,  app.Response.s, 'b-' ); hold(app.ResponseGraph,'on' );
            plot(app.ResponseGraph, app.Response.tm, app.Response.sm,'bo' );
            plot(app.ResponseGraph, app.Response.t, app.Response.s0*ones(size(app.Response.t)),'k--' );
            plot(app.ResponseGraph, app.Response.t, app.Response.sinf*ones(size(app.Response.t)),'k--' );
            hold(app.ResponseGraph,'off');
            
            yex= abs(app.Response.s0-app.Response.sinf)/10;
            ymax= max([app.Response.s0, app.Response.sinf])+yex;
            ymin= min([app.Response.s0, app.Response.sinf])-yex;
            
            if ymax>ymin
                set(app.ResponseGraph, 'YLim', [ymin ymax]);
            end
            results=0;
        end
        
        function s = StepResponse(app, s0, sinf, tau, t)            
            if tau==0
                s= sinf*ones(size(t)); % Exception if time constant equals zero
            else
                s= sinf-(sinf-s0)*exp(-t/tau);
            end               
            kn=find(t<0);
            s(kn)=s0*ones(size(kn));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Response.tau = app.TimeConstantsSlider.Value;
            app.Response.s0  = app.InitialvalueSpinner.Value;
            app.Response.sinf= app.FinalvalueSpinner.Value;
            app.TimeConstantIndicator.Value=app.Response.tau;
            CalculateAndPlotResponse(app);
        end

        % Value changed function: FinalvalueSpinner
        function FinalvalueSpinnerValueChanged(app, event)
            app.Response.sinf = app.FinalvalueSpinner.Value;
            CalculateAndPlotResponse(app);
        end

        % Value changed function: InitialvalueSpinner
        function InitialvalueSpinnerValueChanged(app, event)
            app.Response.s0  = app.InitialvalueSpinner.Value;
            CalculateAndPlotResponse(app);
        end

        % Callback function: TimeConstantsSlider, TimeConstantsSlider
        function TimeConstantsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Response.tau= changingValue;
            app.TimeConstantIndicator.Value=app.Response.tau;
            CalculateAndPlotResponse(app);
        end

        % Value changed function: TimeConstantIndicator
        function TimeConstantIndicatorValueChanged(app, event)
            app.Response.tau = app.TimeConstantIndicator.Value;
            app.TimeConstantsSlider.Value=app.Response.tau;
            CalculateAndPlotResponse(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.First_Order_Setep_Response = uifigure('Visible', 'off');
            app.First_Order_Setep_Response.Name = 'First Order Step Response';
            app.ResponseGraph = uiaxes(app.First_Order_Setep_Response);
            xlabel(app.ResponseGraph, 'Time [s]')
            ylabel(app.ResponseGraph, 'Response')
            app.ResponseGraph.XLim = [-2 20];
            app.FinalvalueLabel = uilabel(app.First_Order_Setep_Response);
            app.FinalvalueLabel.Text = 'Final value ';
            app.FinalvalueSpinner = uispinner(app.First_Order_Setep_Response);
            app.FinalvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @FinalvalueSpinnerValueChanged, true);
            app.FinalvalueSpinner.Value = 1;
            app.InitialvalueLabel = uilabel(app.First_Order_Setep_Response);
            app.InitialvalueLabel.Text = 'Initial value ';
            app.InitialvalueSpinner = uispinner(app.First_Order_Setep_Response);
            app.InitialvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @InitialvalueSpinnerValueChanged, true);
            app.FirstOrderSystemStepResponseLabel = uilabel(app.First_Order_Setep_Response);
            app.FirstOrderSystemStepResponseLabel.Text = 'First Order System. Step Response';
            app.TimeConstantIndicator = uieditfield(app.First_Order_Setep_Response, 'numeric');
            app.TimeConstantIndicator.Limits = [0 Inf];
            app.TimeConstantIndicator.ValueDisplayFormat = '%5.3f';
            app.TimeConstantIndicator.ValueChangedFcn = createCallbackFcn(app, @TimeConstantIndicatorValueChanged, true);
            app.TimeConstantsSliderLabel = uilabel(app.First_Order_Setep_Response);
            app.TimeConstantsSliderLabel.Text = {'Time Constant'; '[s]'};
            app.TimeConstantsSlider = uislider(app.First_Order_Setep_Response);
            app.TimeConstantsSlider.Limits = [0 5];
            app.TimeConstantsSlider.ValueChangedFcn = createCallbackFcn(app, @TimeConstantsSliderValueChanging, true);
            app.TimeConstantsSlider.ValueChangingFcn = createCallbackFcn(app, @TimeConstantsSliderValueChanging, true);
            app.TimeConstantsSlider.Value = 1;
            % Show the figure after all components are created
            app.First_Order_Setep_Response.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = first_order_step_response

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.First_Order_Setep_Response)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.First_Order_Setep_Response)
        end
    end
end