classdef dirichletkernel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        McClellanetalDSPFirstCh6Amplitudenormalizedto1Label  matlab.ui.control.Label
        DirichletkernelLabel  matlab.ui.control.Label
        LSpinner              matlab.ui.control.Spinner
        LLabel                matlab.ui.control.Label
        dBscaled              matlab.ui.control.UIAxes
        Amplitude             matlab.ui.control.UIAxes
        FullRange             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
    end
    
    methods (Access = private)

        % Calculate results and call function to plot graphs
        function results = CalculateAndPlot(app)
            
            app.data.DL=sin(1/2*app.data.w*app.data.L)./(app.data.L*sin(1/2*app.data.w));
            app.data.DLin=app.data.DL(app.data.kin);
            
            PlotDLGraph(app, app.FullRange, app.data.DLin,                app.data.DL,                [ -1 1] );
            PlotDLGraph(app, app.Amplitude, abs(app.data.DLin),           abs(app.data.DL),           [  0 1] );
            PlotDLGraph(app, app.dBscaled,  20*log10(abs(app.data.DLin)), 20*log10(abs(app.data.DL)), [-30 0] );

            results=0;
        end
        
        % Plot graph, according to input data, standardised format
        function results = PlotDLGraph(app, Daxes, Dshort, Dlong, Drange)

            plot( Daxes, app.data.w/pi,   Dlong,  '-', 'Color', app.data.col(1,:) )

            wrange = [min(app.data.w), max(app.data.w) ]/pi;
            axis( Daxes, [ wrange, Drange] );

            xpatch1 = [ wrange(1) -1,        -1        wrange(1) ];
            xpatch2 = [  1        wrange(2) wrange(2)  1        ];           
            ypatch  = [ Drange(1)*[ 1 1 ] Drange(2)*[ 1 1 ] ];
            patch( Daxes, xpatch1, ypatch, 'k', 'Edgecolor', 'none', 'FaceAlpha', 0.2 );
            patch( Daxes, xpatch2, ypatch, 'k', 'Edgecolor', 'none', 'FaceAlpha', 0.1 );            

            results=0;   
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data.w= linspace(-2.2*pi, 4.2*pi, 1000);
            app.data.kin=abs(app.data.w)<=pi;
            app.data.win=app.data.w(app.data.kin);
            app.data.L = app.LSpinner.Value;
            app.data.col = colororder;
            CalculateAndPlot(app);
        end

        % Value changed function: LSpinner
        function LSpinnerValueChanged(app, event)
            app.data.L = app.LSpinner.Value;
            CalculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FullRange = uiaxes(app.UIFigure);
            title(app.FullRange, 'Linear scale - Extended beyond unique range')
            xlabel(app.FullRange, '\omega/\pi')
            ylabel(app.FullRange, 'D_L')
            zlabel(app.FullRange, 'Z')
            app.FullRange.YLim = [-1 1];
            app.Amplitude = uiaxes(app.UIFigure);
            title(app.Amplitude, 'Amplitude')
            xlabel(app.Amplitude, '\omega/\pi')
            ylabel(app.Amplitude, '|D_L|')
            zlabel(app.Amplitude, 'Z')
            app.Amplitude.XLim = [-1 1];
            app.Amplitude.YLim = [0 1];
            app.dBscaled = uiaxes(app.UIFigure);
            title(app.dBscaled, 'Amplitude in dB')
            xlabel(app.dBscaled, '\omega/\pi')
            ylabel(app.dBscaled, '20 log10 (|D_L|)     [dB]')
            zlabel(app.dBscaled, 'Z')
            app.dBscaled.XLim = [-1 1];
            app.dBscaled.YLim = [-30 0];
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L ';
            app.LSpinner = uispinner(app.UIFigure);
            app.LSpinner.Limits = [0 1000];
            app.LSpinner.RoundFractionalValues = 'on';
            app.LSpinner.ValueDisplayFormat = '%4d';
            app.LSpinner.ValueChangedFcn = createCallbackFcn(app, @LSpinnerValueChanged, true);
            app.LSpinner.Value = 7;
            app.DirichletkernelLabel = uilabel(app.UIFigure);
            app.DirichletkernelLabel.Text = 'Dirichlet-kernel';
            app.McClellanetalDSPFirstCh6Amplitudenormalizedto1Label = uilabel(app.UIFigure);
            app.McClellanetalDSPFirstCh6Amplitudenormalizedto1Label.Text = {'McClellan et al., DSP First, Ch. 6'; 'Amplitude normalized to 1'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'USN_logo_En_rgb.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dirichletkernel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end