classdef FIRfrequency < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        bkLabel                        matlab.ui.control.Label
        bkEditField                    matlab.ui.control.EditField
        FrequencyResponseofFIRFilterLabel  matlab.ui.control.Label
        McClellanetalDSPFirstCh6Label  matlab.ui.control.Label
        FilterCoefficientsLabel        matlab.ui.control.Label
        FunctionLabel                  matlab.ui.control.Label
        FunctionEditField              matlab.ui.control.EditField
        coefficients                   matlab.ui.control.UIAxes
        magnitude                      matlab.ui.control.UIAxes
        phase                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        num % Description
    end


    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            b=app.num.b;
            M=length(b)-1;
            n=(0:M);
            
            nm=-2;
            np= nm:M+6;
            bp= zeros(size(np));
            bp(-nm+[1:M+1])=b;
            stem(app.coefficients,np,bp, 'filled')
            ylim(app.coefficients,1.2*max(abs(bp))*[-1 1])
            w=linspace(-pi,pi,3000)';
            Hk=zeros(length(w),M+1);
            for k=n
                Hk(:,k+1)= b(k+1)*exp(-1i*w*k);
            end
            H=sum(Hk,2);
            k=find(abs(H)<(10*eps)); % Replace H-values close to zero to avoid roundoff-error
            H(k)=zeros(size(k));
            plot(app.magnitude,w/pi,abs(H))
            plot(app.phase,w/pi,angle(H)/pi)
            results=0;           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.bkEditField.Value;
            app.num.b=sscanf(value,'%g,');
            app.FunctionEditField.Value='';
            CalculateAndPlot(app);
        end

        % Value changed function: bkEditField
        function bkEditFieldValueChanged(app, event)
            value = app.bkEditField.Value;
            app.num.b=sscanf(value,'%g,');
            app.FunctionEditField.Value='';
            CalculateAndPlot(app);
        end

        % Value changed function: FunctionEditField
        function FunctionEditFieldValueChanged(app, event)
            value = app.FunctionEditField.Value;
            app.num.b=eval(value);
            app.bkEditField.Value=sprintf(' %g,',app.num.b);
            CalculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.bkLabel = uilabel(app.UIFigure);
            app.bkLabel.Text = 'bk ';
            app.bkEditField = uieditfield(app.UIFigure, 'text');
            app.bkEditField.ValueChangedFcn = createCallbackFcn(app, @bkEditFieldValueChanged, true);
            app.bkEditField.Value = '1,2,2,1';
            app.FrequencyResponseofFIRFilterLabel = uilabel(app.UIFigure);
            app.FrequencyResponseofFIRFilterLabel.Text = 'Frequency Response of FIR-Filter';
            app.McClellanetalDSPFirstCh6Label = uilabel(app.UIFigure);
            app.McClellanetalDSPFirstCh6Label.Text = 'McClellan et al., DSP First, Ch. 6';
            app.FilterCoefficientsLabel = uilabel(app.UIFigure);
            app.FilterCoefficientsLabel.Text = 'Filter Coefficients';
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function ';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionEditFieldValueChanged, true);
            app.coefficients = uiaxes(app.UIFigure);
            xlabel(app.coefficients, 'n')
            ylabel(app.coefficients, 'h[n]')
            app.coefficients.YLim = [0 1];
            app.magnitude = uiaxes(app.UIFigure);
            xlabel(app.magnitude, '\omega/\pi ')
            ylabel(app.magnitude, 'Magnitude |H(w)|')
            app.phase = uiaxes(app.UIFigure);
            xlabel(app.phase, '\omega/\pi')
            ylabel(app.phase, 'Phase angle(H)/\pi')
            app.phase.XLim = [-1 1];
            app.phase.YLim = [-1 1];
            app.phase.ZLim = [-1 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FIRfrequency

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end