classdef runningaverageexample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RunningAverageDemoUIFigure  matlab.ui.Figure
        SpinnerNoiselevel           matlab.ui.control.Spinner
        NoiselevelaLabel            matlab.ui.control.Label
        TextAreaSignaldefinition    matlab.ui.control.TextArea
        SpinnerSignallength         matlab.ui.control.Spinner
        SignallengthNLabel          matlab.ui.control.Label
        SpinnerFilterlength         matlab.ui.control.Spinner
        FilterlengthMLabel          matlab.ui.control.Label
        LabelReference              matlab.ui.control.Label
        LabelHeader                 matlab.ui.control.Label
        SpinnerSelectedsample       matlab.ui.control.Spinner
        HiglightedsampleLabel       matlab.ui.control.Label
        AxesFilteredSignal          matlab.ui.control.UIAxes
        AxesImpulseResponse         matlab.ui.control.UIAxes
        AxesFlippedFilter           matlab.ui.control.UIAxes
        AxesSignal                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal % Description
    end
    
    methods (Access = private)
        
        function results = calculateAndPlot(app)
            app.signal.N = length( app.signal.x );

            n     = 0:length(app.signal.x)-1;
            nlims = [-4 max(n)+5];

            % Define moving average filter
            app.signal.h = 1/app.signal.M * ones( 1, app.signal.M );  
            nh = 0:length(app.signal.h)-1;
            nflipped = -nh;

            % Do filtering
            y =conv( app.signal.x, app.signal.h );
            ny= 0 : length(y)-1;

            % Plot results
            stem(app.AxesSignal,  n, app.signal.x, 'filled', 'color', app.signal.color{1} );
            xlim(app.AxesSignal, nlims);
            hold(app.AxesSignal, "on");
            plot(app.AxesSignal, app.signal.mSelected*[1 1],                    [-10 10], '-', 'color', app.signal.color{2} );
            plot(app.AxesSignal, ( app.signal.mSelected-app.signal.M+1 )*[1 1], [-10 10], '-', 'color', app.signal.color{2} );
            hold(app.AxesSignal, "off");

            scaleYAixs(app, app.AxesSignal, [min(app.signal.x) max(app.signal.x)] );

            stem( app.AxesFlippedFilter, nflipped, fliplr( app.signal.h ), 'filled', 'color', app.signal.color{2} )
            hold( app.AxesFlippedFilter, "on" );
            plot( app.AxesFlippedFilter, [0 0],                [-10 10], ':', 'color', app.signal.color{2} );
            plot( app.AxesFlippedFilter, 1-app.signal.M*[1 1], [-10 10], ':', 'color', app.signal.color{2} );
            hold( app.AxesFlippedFilter, "off" );
            xlim( app.AxesFlippedFilter, nlims-app.signal.mSelected );

            stem(app.AxesImpulseResponse, nh, app.signal.h, 'filled', 'color', app.signal.color{2} );
            xlim(app.AxesImpulseResponse, nlims);

            stem(app.AxesFilteredSignal, ny, y, 'filled', 'color', app.signal.color{4} );
            hold(app.AxesFilteredSignal, "on");               
            plot(app.AxesFilteredSignal, app.signal.mSelected*[1 1], [-10 10], 'color', app.signal.color{2} );
            hold(app.AxesFilteredSignal, "off");
            xlim(app.AxesFilteredSignal, nlims);
            scaleYAixs(app, app.AxesFilteredSignal, [min(y) max(y)] );

        end
        
        function results = scaleYAixs(app, ax, ylims)
            ymin= min([min(ylims) 0])-0.5;
            ymax= max([max(ylims) 0])+0.5;
            
            ylim(ax, [ymin ymax] );
            results=0;
        end
        
        function results = defineSignal(app)
            N = app.SpinnerSignallength.Value;
            xN= app.SpinnerNoiselevel.Value;
            signaldef= app.TextAreaSignaldefinition.Value;
            for k=1:length(signaldef)
                eval( signaldef{k} )
            end
            app.signal.x = x;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            defineSignal(app)
            app.signal.mSelected = round( app.SpinnerSelectedsample.Value );
            app.signal.M         = round( app.SpinnerFilterlength.Value );

            c = colororder;
            app.signal.color= mat2cell( c, ones( length(c), 1 ), 3 );  % Colors as cell array

            calculateAndPlot(app);
        end

        % Callback function
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.signal.x = str2num(value);
            calculateAndPlot(app);
        end

        % Value changed function: SpinnerSelectedsample
        function mSpinnerValueChanged(app, event)
            app.signal.mSelected = app.SpinnerSelectedsample.Value;
            app.signal.mSelected = round(app.signal.mSelected);
            calculateAndPlot(app);
        end

        % Value changed function: SpinnerFilterlength
        function SpinnerFilterlengthValueChanged(app, event)
            app.signal.M  = round( app.SpinnerFilterlength.Value );
            calculateAndPlot(app);            
        end

        % Value changed function: SpinnerNoiselevel, SpinnerSignallength, 
        % ...and 1 other component
        function SignalChanged(app, event)
            defineSignal(app)
            calculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RunningAverageDemoUIFigure = uifigure('Visible', 'off');
            app.RunningAverageDemoUIFigure.Name = 'Running Average Demo';
            app.AxesSignal = uiaxes(app.RunningAverageDemoUIFigure);
            title(app.AxesSignal, 'Signal')
            xlabel(app.AxesSignal, 'n')
            ylabel(app.AxesSignal, 'x[n]')
            zlabel(app.AxesSignal, 'Z')
            app.AxesFlippedFilter = uiaxes(app.RunningAverageDemoUIFigure);
            title(app.AxesFlippedFilter, 'Flipped filter')
            xlabel(app.AxesFlippedFilter, 'n')
            ylabel(app.AxesFlippedFilter, 'h[n] flipped')
            zlabel(app.AxesFlippedFilter, 'Z')
            app.AxesFlippedFilter.YLim = [0 1];
            app.AxesImpulseResponse = uiaxes(app.RunningAverageDemoUIFigure);
            title(app.AxesImpulseResponse, 'Filter')
            xlabel(app.AxesImpulseResponse, 'n')
            ylabel(app.AxesImpulseResponse, 'h[n]')
            zlabel(app.AxesImpulseResponse, 'Z')
            app.AxesImpulseResponse.YLim = [0 1];
            app.AxesFilteredSignal = uiaxes(app.RunningAverageDemoUIFigure);
            title(app.AxesFilteredSignal, 'Filtered signal')
            xlabel(app.AxesFilteredSignal, 'n')
            ylabel(app.AxesFilteredSignal, 'x[n]*h[n]')
            zlabel(app.AxesFilteredSignal, 'Z')
            app.HiglightedsampleLabel = uilabel(app.RunningAverageDemoUIFigure);
            app.HiglightedsampleLabel.Text = 'Higlighted sample ';
            app.SpinnerSelectedsample = uispinner(app.RunningAverageDemoUIFigure);
            app.SpinnerSelectedsample.Limits = [-10 100];
            app.SpinnerSelectedsample.RoundFractionalValues = 'on';
            app.SpinnerSelectedsample.ValueDisplayFormat = '%d';
            app.SpinnerSelectedsample.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.LabelHeader = uilabel(app.RunningAverageDemoUIFigure);
            app.LabelHeader.Text = {'Convolution Example'; 'Running Average Filter'};
            app.LabelReference = uilabel(app.RunningAverageDemoUIFigure);
            app.LabelReference.Text = 'McClellan et al., DSP First, Ch. 5';
            app.FilterlengthMLabel = uilabel(app.RunningAverageDemoUIFigure);
            app.FilterlengthMLabel.Text = 'Filter length [M] ';
            app.SpinnerFilterlength = uispinner(app.RunningAverageDemoUIFigure);
            app.SpinnerFilterlength.Limits = [1 10];
            app.SpinnerFilterlength.RoundFractionalValues = 'on';
            app.SpinnerFilterlength.ValueDisplayFormat = '%d';
            app.SpinnerFilterlength.ValueChangedFcn = createCallbackFcn(app, @SpinnerFilterlengthValueChanged, true);
            app.SpinnerFilterlength.Value = 2;
            app.SignallengthNLabel = uilabel(app.RunningAverageDemoUIFigure);
            app.SignallengthNLabel.Text = 'Signal length [N] ';
            app.SpinnerSignallength = uispinner(app.RunningAverageDemoUIFigure);
            app.SpinnerSignallength.Limits = [1 400];
            app.SpinnerSignallength.RoundFractionalValues = 'on';
            app.SpinnerSignallength.ValueDisplayFormat = '%d';
            app.SpinnerSignallength.ValueChangedFcn = createCallbackFcn(app, @SignalChanged, true);
            app.SpinnerSignallength.Value = 60;
            app.TextAreaSignaldefinition = uitextarea(app.RunningAverageDemoUIFigure);
            app.TextAreaSignaldefinition.ValueChangedFcn = createCallbackFcn(app, @SignalChanged, true);
            app.TextAreaSignaldefinition.Value = {'t = linspace(0, 2*pi, N);'; 'x= (t).*cos(t)+xN*randn(1,N);'};
            app.NoiselevelaLabel = uilabel(app.RunningAverageDemoUIFigure);
            app.NoiselevelaLabel.Text = 'Noise level [xN] ';
            app.SpinnerNoiselevel = uispinner(app.RunningAverageDemoUIFigure);
            app.SpinnerNoiselevel.Step = 0.1;
            app.SpinnerNoiselevel.Limits = [0 10];
            app.SpinnerNoiselevel.ValueDisplayFormat = '%.1f';
            app.SpinnerNoiselevel.ValueChangedFcn = createCallbackFcn(app, @SignalChanged, true);
            app.SpinnerNoiselevel.Value = 0.3;
            % Show the figure after all components are created
            app.RunningAverageDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = runningaverageexample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RunningAverageDemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RunningAverageDemoUIFigure)
        end
    end
end