classdef convolution_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        McClellanetalDSPFirstCh5Label  matlab.ui.control.Label
        ConvolutionExampleLabel        matlab.ui.control.Label
        HiglightedsampleSpinner        matlab.ui.control.Spinner
        HiglightedsampleLabel          matlab.ui.control.Label
        hEditField                     matlab.ui.control.EditField
        hLabel                         matlab.ui.control.Label
        xEditField                     matlab.ui.control.EditField
        xLabel                         matlab.ui.control.Label
        ResultAxes                     matlab.ui.control.UIAxes
        ConvolutionAxes                matlab.ui.control.UIAxes
        ImpulseAxes                    matlab.ui.control.UIAxes
        SignalAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            n     = 0:app.signal.N-1;
            nlims = [-4 app.signal.N+5];
            
            app.signal.x(app.signal.N)=0;
            app.signal.h(app.signal.N)=0;

            y =conv(app.signal.x, app.signal.h);
            ny= 0:length(y)-1;

            stem(app.SignalAxes,  n, app.signal.x, 'filled', 'color', app.signal.color(1,:) );
            xlim(app.SignalAxes, nlims);
            SetyAixs(app, app.SignalAxes, [min(app.signal.x) max(app.signal.x)] );

            stem(app.ImpulseAxes, n, app.signal.h, 'filled', 'color', app.signal.color(2,:))
            xlim(app.ImpulseAxes, nlims);
            SetyAixs(app, app.ImpulseAxes, [min(app.signal.h) max(app.signal.h)] );

            Nh=length(app.signal.h);
            nh=app.signal.m-Nh+(Nh:-1:1);
            stem(app.ConvolutionAxes, n,  app.signal.x, 'filled', 'color', app.signal.color(1,:));
            hold(app.ConvolutionAxes, "on");
            stem(app.ConvolutionAxes, nh, app.signal.h, 'filled', 'color', app.signal.color(2,:));
            plot(app.ConvolutionAxes, app.signal.m*[1 1], [-10 10], 'k-');
            hold(app.ConvolutionAxes, "off");
            xlim(app.ConvolutionAxes, nlims);
            xh=[app.signal.x app.signal.h];
            SetyAixs(app, app.ConvolutionAxes, [min(xh) max(xh)] );

            stem(app.ResultAxes, ny, y, 'filled', 'color', app.signal.color(4,:));
            hold(app.ResultAxes, "on");               
            plot(app.ResultAxes, app.signal.m*[1 1], [-10 10], 'k-');
            hold(app.ResultAxes, "off");
            xlim(app.ResultAxes, nlims);
            SetyAixs(app, app.ResultAxes, [min(y) max(y)] );

        end
        
        function results = SetyAixs(app, ax, ylims)
            ymin= min([min(ylims) 0])-0.5;
            ymax= max([max(ylims) 0])+0.5;
            
            ylim(ax, [ymin ymax] );
            results=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.signal.x = str2num(app.xEditField.Value);
            app.signal.h = str2num(app.hEditField.Value);
            app.signal.m = app.HiglightedsampleSpinner.Value;
            app.signal.N = 20;
            app.signal.color=colororder;
            CalculateAndPlot(app);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.signal.x = str2num(value);
            CalculateAndPlot(app);
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            value = app.hEditField.Value;
            app.signal.h = str2num(value);
            CalculateAndPlot(app);
        end

        % Value changed function: HiglightedsampleSpinner
        function mSpinnerValueChanged(app, event)
            app.signal.m= app.HiglightedsampleSpinner.Value;
            app.signal.m= round(app.signal.m);
            CalculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignalAxes = uiaxes(app.UIFigure);
            xlabel(app.SignalAxes, 'n')
            ylabel(app.SignalAxes, 'x[n]')
            zlabel(app.SignalAxes, 'Z')
            app.ImpulseAxes = uiaxes(app.UIFigure);
            xlabel(app.ImpulseAxes, 'n')
            ylabel(app.ImpulseAxes, 'h[n]')
            zlabel(app.ImpulseAxes, 'Z')
            app.ConvolutionAxes = uiaxes(app.UIFigure);
            xlabel(app.ConvolutionAxes, 'n')
            ylabel(app.ConvolutionAxes, 'x[n]*h[n]')
            zlabel(app.ConvolutionAxes, 'Z')
            app.ResultAxes = uiaxes(app.UIFigure);
            xlabel(app.ResultAxes, 'n')
            ylabel(app.ResultAxes, 'x[n]*h[n]')
            zlabel(app.ResultAxes, 'Z')
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x ';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = '1, 1, 1, 1,1';
            app.hLabel = uilabel(app.UIFigure);
            app.hLabel.Text = 'h ';
            app.hEditField = uieditfield(app.UIFigure, 'text');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.hEditField.Value = '1,1,1';
            app.HiglightedsampleLabel = uilabel(app.UIFigure);
            app.HiglightedsampleLabel.Text = 'Higlighted sample ';
            app.HiglightedsampleSpinner = uispinner(app.UIFigure);
            app.HiglightedsampleSpinner.Limits = [-3 30];
            app.HiglightedsampleSpinner.RoundFractionalValues = 'on';
            app.HiglightedsampleSpinner.ValueDisplayFormat = '%d';
            app.HiglightedsampleSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.ConvolutionExampleLabel = uilabel(app.UIFigure);
            app.ConvolutionExampleLabel.Text = 'Convolution Example';
            app.McClellanetalDSPFirstCh5Label = uilabel(app.UIFigure);
            app.McClellanetalDSPFirstCh5Label.Text = 'McClellan et al., DSP First, Ch. 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convolution_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end