classdef fir_frequency < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FunctionEditField              matlab.ui.control.EditField
        FunctionLabel                  matlab.ui.control.Label
        FilterCoefficientsLabel        matlab.ui.control.Label
        McClellanetalDSPFirstCh6Label  matlab.ui.control.Label
        FrequencyResponseofFIRFilterLabel  matlab.ui.control.Label
        bkEditField                    matlab.ui.control.EditField
        bkLabel                        matlab.ui.control.Label
        phase                          matlab.ui.control.UIAxes
        magnitude                      matlab.ui.control.UIAxes
        coefficients                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        num % Description
    end


    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            b=app.num.b;
            M=length(b)-1;
            n=(0:M);
            
            nm=-1;
            np= nm:M+6;
            bp= zeros(size(np));
            bp(-nm+[1:M+1])=b;            
            stem(app.coefficients,np,bp, 'filled')
            ylim(app.coefficients,1.2*max(abs(bp))*[-1 1])
            ax=axis(app.coefficients);
            patch(app.coefficients, [ax(1) -0.05 -0.05 ax(1)], [ ax(3) ax(3) ax(4) ax(4)],  [0 0 0], 'Edgecolor', 'none', 'FaceAlpha', 0.1);

            w=linspace(-pi,pi,3000)';
            Hk=zeros(length(w),M+1);
            for k=n
                Hk(:,k+1)= b(k+1)*exp(-1i*w*k);                
            end
            H=sum(Hk,2);            
            plot(app.magnitude,w/pi,abs(H))
            plot(app.phase,w/pi,angle(H)/pi)            
            results=0;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bkEditFieldValueChanged(app, 0)
        end

        % Value changed function: bkEditField
        function bkEditFieldValueChanged(app, event)
            value = app.bkEditField.Value;
            app.num.b=sscanf(value,'%g,');
            app.FunctionEditField.Value='';
            CalculateAndPlot(app);
        end

        % Value changed function: FunctionEditField
        function FunctionEditFieldValueChanged(app, event)
            value = app.FunctionEditField.Value;
            app.num.b=eval(value);
            app.bkEditField.Value=sprintf(' %g,',app.num.b);
            CalculateAndPlot(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.coefficients = uiaxes(app.UIFigure);
            xlabel(app.coefficients, 'n')
            ylabel(app.coefficients, 'h[n]')
            app.coefficients.YLim = [0 1];
            app.magnitude = uiaxes(app.UIFigure);
            xlabel(app.magnitude, '\omega/\pi ')
            ylabel(app.magnitude, 'Magnitude |H(w)|')
            app.phase = uiaxes(app.UIFigure);
            xlabel(app.phase, '\omega/\pi')
            ylabel(app.phase, 'Phase angle(H)/\pi')
            app.phase.XLim = [-1 1];
            app.phase.YLim = [-1 1];
            app.phase.ZLim = [-1 1];
            app.bkLabel = uilabel(app.UIFigure);
            app.bkLabel.Text = 'bk ';
            app.bkEditField = uieditfield(app.UIFigure, 'text');
            app.bkEditField.ValueChangedFcn = createCallbackFcn(app, @bkEditFieldValueChanged, true);
            app.bkEditField.Value = '1,2,1';
            app.FrequencyResponseofFIRFilterLabel = uilabel(app.UIFigure);
            app.FrequencyResponseofFIRFilterLabel.Text = 'Frequency Response of FIR-Filter';
            app.McClellanetalDSPFirstCh6Label = uilabel(app.UIFigure);
            app.McClellanetalDSPFirstCh6Label.Text = 'McClellan et al., DSP First, Ch. 6';
            app.FilterCoefficientsLabel = uilabel(app.UIFigure);
            app.FilterCoefficientsLabel.Text = 'Filter Coefficients';
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function ';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fir_frequency

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end