classdef iir_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        p2EditField                     matlab.ui.control.EditField
        p2EditFieldLabel                matlab.ui.control.Label
        p1EditField                     matlab.ui.control.EditField
        p1EditFieldLabel                matlab.ui.control.Label
        PolesLabel                      matlab.ui.control.Label
        StableLabel                     matlab.ui.control.Label
        bEditField                      matlab.ui.control.EditField
        bLabel                          matlab.ui.control.Label
        aEditField                      matlab.ui.control.EditField
        aLabel                          matlab.ui.control.Label
        FilterCoefficientsLabel         matlab.ui.control.Label
        McClellanetalDSPFirstCh10Label  matlab.ui.control.Label
        ResponseofIIRFilterLabel        matlab.ui.control.Label
        PhasePlot                       matlab.ui.control.UIAxes
        ImpulseresponsePlot             matlab.ui.control.UIAxes
        MagnitudePlot                   matlab.ui.control.UIAxes
        pzPlot                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filterdata % Description
    end
    
    methods (Access = private)
        
        function results = CalculateandPlot(app)
            b= app.filterdata.b(:)'; % Ensure b is a row vector, as required by 'zplane'
            a= app.filterdata.a(:)';
            w= app.filterdata.w;
            H=freqz(b,a,w);
            k=find(abs(H)<max(abs(H))*1e-10); % Replace H-values close to zero to avoid roundoff-error
            H(k)=zeros(size(k));
            
            cla(app.pzPlot)
            [hz, hp, ht]=zplane(b,a, app.pzPlot);
            
            z0=hz.XData+1i*hz.YData;    % Zeros
            zp=hp.XData+1i*hp.YData;    % Poles

            ptext=strings(2,1);
            for k=1:length(zp)
                ptext(k)= string(sprintf('%4.2f    %s%5.2f %s ', abs(zp(k)), char(8736), angle(zp(k))/pi, char(0960) ));
            end
            app.p1EditField.Value=ptext{1};
            app.p2EditField.Value=ptext{2};
            
            stable=all(abs(zp)<1);
            if stable
                app.StableLabel.Text='Stable';
                app.StableLabel.BackgroundColor=0.4*[0 1 0];
            else
                app.StableLabel.Text='Unstable';
                app.StableLabel.BackgroundColor=0.4*[1 0 0];
            end
           
            if stable
                plot(app.MagnitudePlot, w,abs(H) )
                pi_scaled_axis(app.MagnitudePlot,'x','Normalized frequency',3)
                ylim(app.MagnitudePlot, [0, 1.2*max(abs(H))]);
                
                plot(app.PhasePlot, w,angle(H) )
                pi_scaled_axis(app.PhasePlot,'x','Normalized frequency',3)
                pi_scaled_axis(app.PhasePlot,'y','Phase',2)
            else
                cla(app.MagnitudePlot);
                cla(app.PhasePlot);                
            end
            N = max([length(b), 20]);
            nz= 2;
            n = (-nz:N);          % Plot a few zero-points before and after the filter length
            x = zeros(size(n));
            x(nz+1)=1;
            h = filter(b,a,x);
            
            stem(app.ImpulseresponsePlot, n,h,'filled')
            xlim(app.ImpulseresponsePlot, [min(n) max(n)])
            ylim(app.ImpulseresponsePlot, 1.2*max(abs(h))*[-1 1]);
            
            results=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filterdata.w= linspace(-pi,pi,2001);   % Normalized frequency
            app.filterdata.z= exp(1i*app.filterdata.w);    % z on unit circle
            
            app.filterdata.a = sscanf(app.aEditField.Value,'%g,');
            app.filterdata.b = sscanf(app.bEditField.Value,'%g,');
            CalculateandPlot(app);
            
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value= app.aEditField.Value;
            app.filterdata.a = str2num(value);
            CalculateandPlot(app);
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.filterdata.b = str2num(value);
            CalculateandPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pzPlot = uiaxes(app.UIFigure);
            xlabel(app.pzPlot, 'Real Part')
            ylabel(app.pzPlot, 'Imaginary PardY')
            zlabel(app.pzPlot, 'Z')
            app.MagnitudePlot = uiaxes(app.UIFigure);
            xlabel(app.MagnitudePlot, 'Normalized Frequency')
            ylabel(app.MagnitudePlot, 'Magnitude |H|')
            zlabel(app.MagnitudePlot, 'Z')
            app.MagnitudePlot.YLim = [-1 1];
            app.ImpulseresponsePlot = uiaxes(app.UIFigure);
            xlabel(app.ImpulseresponsePlot, 'n')
            ylabel(app.ImpulseresponsePlot, 'h[n]')
            zlabel(app.ImpulseresponsePlot, 'Z')
            app.ImpulseresponsePlot.XLim = [-1 1];
            app.ImpulseresponsePlot.YLim = [-1 1];
            app.PhasePlot = uiaxes(app.UIFigure);
            xlabel(app.PhasePlot, 'Normalized Frequency')
            ylabel(app.PhasePlot, 'Phase')
            zlabel(app.PhasePlot, 'Z')
            app.PhasePlot.XLim = [-1 1];
            app.PhasePlot.YLim = [-1 1];
            app.ResponseofIIRFilterLabel = uilabel(app.UIFigure);
            app.ResponseofIIRFilterLabel.Text = 'Response of IIR-Filter';
            app.McClellanetalDSPFirstCh10Label = uilabel(app.UIFigure);
            app.McClellanetalDSPFirstCh10Label.Text = 'McClellan et al., DSP First, Ch. 10';
            app.FilterCoefficientsLabel = uilabel(app.UIFigure);
            app.FilterCoefficientsLabel.Text = 'Filter Coefficients';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a ';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = '1, -0.8';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b ';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = '1, 1';
            app.StableLabel = uilabel(app.UIFigure);
            app.StableLabel.Text = 'Stable';
            app.PolesLabel = uilabel(app.UIFigure);
            app.PolesLabel.Text = 'Poles';
            app.p1EditFieldLabel = uilabel(app.UIFigure);
            app.p1EditFieldLabel.Text = 'p1 ';
            app.p1EditField = uieditfield(app.UIFigure, 'text');
            app.p2EditFieldLabel = uilabel(app.UIFigure);
            app.p2EditFieldLabel.Text = 'p2 ';
            app.p2EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iir_filter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end