classdef iir_response_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StableLabel                     matlab.ui.control.Label
        bEditField                      matlab.ui.control.EditField
        bLabel                          matlab.ui.control.Label
        aEditField                      matlab.ui.control.EditField
        aLabel                          matlab.ui.control.Label
        FilterCoefficientsLabel         matlab.ui.control.Label
        McClellanetalDSPFirstCh10Label  matlab.ui.control.Label
        ResponseofIIRFilterLabel        matlab.ui.control.Label
        PhasePlot                       matlab.ui.control.UIAxes
        ImpulseresponsePlot             matlab.ui.control.UIAxes
        MagnitudePlot                   matlab.ui.control.UIAxes
        pzPlot                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filterdata % Description
    end
    
    methods (Access = private)
        
        function results = calculateandPlot(app)
            b= app.filterdata.b(:)';   % Ensure b and a are row vectors, as required by 'zplane'
            a= app.filterdata.a(:)';

            w= app.filterdata.w;        % Frequency vector
            H= freqz(b,a,w);

            % Find H-values that should be zero but deviate due to roundoff-error
            kZero   = find( abs(H) < max(abs(H))*1e-10 ); 
            H(kZero)=zeros( size(kZero) );
            
            cla( app.pzPlot )
            [ hz, hp, ht ]=zplane( b,a, app.pzPlot );
            xlabel( app.pzPlot , 'Re\{z\}' )
            ylabel( app.pzPlot , 'Im\{z\}' )
            
            z0 = hz.XData + 1i*hz.YData;    % Zeros
            zp = hp.XData + 1i*hp.YData;    % Poles

            % Determine stability, all poles inside unit circle
            stable = all( abs(zp)<1 );    
            if stable
                app.StableLabel.Text='Stable';
                app.StableLabel.BackgroundColor=0.4*[0 1 0];
            else
                app.StableLabel.Text='Unstable';
                app.StableLabel.BackgroundColor=0.4*[1 0 0];
            end
            
            if stable
                wAxisLabel = 'Normalized frequency $\hat \omega$';
                plot( app.MagnitudePlot, w,abs(H) )
                piaxis( app.MagnitudePlot, 'x', wAxisLabel, 3 )
                ylim( app.MagnitudePlot, [0, 1.2*max(abs(H)) ] );
                
                plot( app.PhasePlot, w, angle(H) )
                piaxis( app.PhasePlot, 'x', wAxisLabel, 3 )
                piaxis( app.PhasePlot, 'y', 'Phase',    2 )
            else
                cla(app.MagnitudePlot);
                cla(app.PhasePlot);                
            end

            nMax = max( [1.1*length(b), 30] );   % Minimum no. of samples to plot
            nZero= 2;                            % Include a few zero-points before the filter 
            n = ( -nZero:nMax );   
            d = ( n==0 );            % Impulse, Kronecker-delta function
            h = filter( b, a, d );   % Filter impulse to find impulse response
            
            stem( app.ImpulseresponsePlot, n, h, 'filled' )
            xlim( app.ImpulseresponsePlot, [min(n) max(n)] )
            ylim( app.ImpulseresponsePlot, 1.2*max(abs(h))*[-1 1] );
            
            results=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filterdata.w= linspace( -pi, pi, 2001 );     % Normalized frequency
            app.filterdata.z= exp( 1i*app.filterdata.w );    % z on unit circle
            
            app.filterdata.a = sscanf( app.aEditField.Value,'%g,' );
            app.filterdata.b = sscanf( app.bEditField.Value,'%g,' );

            calculateandPlot(app);            
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value= app.aEditField.Value;
            app.filterdata.a = str2num( value );
            calculateandPlot( app );
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.filterdata.b = str2num( value );
            calculateandPlot( app );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pzPlot = uiaxes(app.UIFigure);
            xlabel(app.pzPlot, 'Real Part')
            ylabel(app.pzPlot, 'Imaginary PardY')
            zlabel(app.pzPlot, 'Z')
            app.MagnitudePlot = uiaxes(app.UIFigure);
            xlabel(app.MagnitudePlot, 'Normalized Frequency')
            ylabel(app.MagnitudePlot, 'Magnitude |H|')
            zlabel(app.MagnitudePlot, 'Z')
            app.MagnitudePlot.YLim = [-1 1];
            app.ImpulseresponsePlot = uiaxes(app.UIFigure);
            xlabel(app.ImpulseresponsePlot, 'n')
            ylabel(app.ImpulseresponsePlot, 'h[n]')
            zlabel(app.ImpulseresponsePlot, 'Z')
            app.ImpulseresponsePlot.XLim = [-1 1];
            app.ImpulseresponsePlot.YLim = [-1 1];
            app.PhasePlot = uiaxes(app.UIFigure);
            xlabel(app.PhasePlot, 'Normalized Frequency')
            ylabel(app.PhasePlot, 'Phase')
            zlabel(app.PhasePlot, 'Z')
            app.PhasePlot.XLim = [-1 1];
            app.PhasePlot.YLim = [-1 1];
            app.ResponseofIIRFilterLabel = uilabel(app.UIFigure);
            app.ResponseofIIRFilterLabel.Text = 'Response of IIR-Filter';
            app.McClellanetalDSPFirstCh10Label = uilabel(app.UIFigure);
            app.McClellanetalDSPFirstCh10Label.Text = 'McClellan et al., DSP First, Ch. 10';
            app.FilterCoefficientsLabel = uilabel(app.UIFigure);
            app.FilterCoefficientsLabel.Text = 'Filter Coefficients';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a ';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = '1, -0.8';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b ';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = '1, 1';
            app.StableLabel = uilabel(app.UIFigure);
            app.StableLabel.Text = 'Stable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iir_response_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end