classdef lowpass_response_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StopbandrippleSpinner   matlab.ui.control.Spinner
        StopbandrippledBLabel   matlab.ui.control.Label
        PassbandrippleSpinner   matlab.ui.control.Spinner
        PassbandrippledBLabel   matlab.ui.control.Label
        OrderSpinner            matlab.ui.control.Spinner
        OrderLabel              matlab.ui.control.Label
        CutofffrequencySpinner  matlab.ui.control.Spinner
        CutofffrequencyLabel    matlab.ui.control.Label
        TopologyDropDown        matlab.ui.control.DropDown
        StableLabel             matlab.ui.control.Label
        ReferenceLabel          matlab.ui.control.Label
        HeaderLabel             matlab.ui.control.Label
        PhasePlot               matlab.ui.control.UIAxes
        ImpulseresponsePlot     matlab.ui.control.UIAxes
        MagnitudePlot           matlab.ui.control.UIAxes
        pzPlot                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filter % Description
    end
    
    methods (Access = private)
        
        function results = calculateandPlot(app)
            b= app.filter.b(:)';   % Ensure b and a are row vectors, as required by 'zplane'
            a= app.filter.a(:)';

            w= app.filter.w;        % Frequency vector
            H= freqz(b,a,w);

            % Find H-values that should be zero but deviate due to roundoff-error
            kZero   = find( abs(H) < max(abs(H))*1e-10 ); 
            H(kZero)=zeros( size(kZero) );
            
            cla( app.pzPlot )
            [ hz, hp, ht ]=zplane( b,a, app.pzPlot );
            xlabel( app.pzPlot , 'Re\{z\}' )
            ylabel( app.pzPlot , 'Im\{z\}' )
            
            z0 = hz.XData + 1i*hz.YData;    % Zeros
            zp = hp.XData + 1i*hp.YData;    % Poles

            % Determine stability, all poles inside unit circle
            stable = all( abs(zp)<1 );    
            if stable
                app.StableLabel.Text='Stable';
                app.StableLabel.BackgroundColor=0.4*[0 1 0];
            else
                app.StableLabel.Text='Unstable';
                app.StableLabel.BackgroundColor=0.4*[1 0 0];
            end
            
            if stable
                wAxisLabel = 'Normalized frequency $\hat \omega$';
                magnitudeTicks = [-42:3:3];
                magnitudeLim =   [ min(magnitudeTicks) max(magnitudeTicks) ];
                
                plot( app.MagnitudePlot, w, 20*log10 (abs(H)), Color=app.filter.color{1}, LineStyle="-" )
                piaxis( app.MagnitudePlot, 'x', wAxisLabel, 3 )
                ylim  ( app.MagnitudePlot, magnitudeLim );
                yticks( app.MagnitudePlot, magnitudeTicks )
                addFrequencyMarkers(app, app.MagnitudePlot, app.filter.wc*pi, app.filter.color{2}, '-' )
                
                plot( app.PhasePlot, w, angle(H), Color=app.filter.color{1}, LineStyle="-" )
                piaxis( app.PhasePlot, 'x', wAxisLabel, 3 )
                piaxis( app.PhasePlot, 'y', 'Phase',    2 )
                addFrequencyMarkers(app, app.PhasePlot, app.filter.wc*pi, app.filter.color{2}, '-' )

            else
                cla(app.MagnitudePlot);
                cla(app.PhasePlot);                
            end

            nMax = max( [1.1*length(b), 30] );   % Minimum no. of samples to plot
            nZero= 2;                            % Include a few zero-points before the filter 
            n = ( -nZero:nMax );   
            d = ( n==0 );            % Impulse, Kronecker-delta function
            h = filter( b, a, d );   % Filter impulse to find impulse response
            
            stem( app.ImpulseresponsePlot, n, h, 'filled' )
            xlim( app.ImpulseresponsePlot, [min(n) max(n)] )
            ylim( app.ImpulseresponsePlot, 1.2*max(abs(h))*[-1 1] );
            
            results=0;
        end
        
        
        function results = addFrequencyMarkers(app, hPlot, wc, lineColor, lineStyle )
            hold( hPlot, "on")
            plot( hPlot, wc*[-1 -1 ], hPlot.YLim, Color=lineColor, LineStyle=lineStyle );
            plot( hPlot, wc*[ 1  1 ], hPlot.YLim, Color=lineColor, LineStyle=lineStyle );
            hold (hPlot, "off")
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filter.w= linspace( -pi, pi, 2001 );     % Normalized frequency
            app.filter.z= exp( 1i*app.filter.w );    % z on unit circle
            
            c = colororder;
            app.filter.color= mat2cell( c, ones( length(c), 1 ), 3 );  % Colors as cell array

            defineFilter(app, 0 )
        end

        % Callback function: not associated with a component
        function aEditFieldValueChanged(app, event)
            value= app.aEditField.Value;
            app.filter.a = str2num( value );
            calculateandPlot( app );
        end

        % Callback function: not associated with a component
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.filter.b = str2num( value );
            calculateandPlot( app );
        end

        % Value changed function: CutofffrequencySpinner, OrderSpinner, 
        % ...and 3 other components
        function defineFilter(app, event)
            app.filter.topology = app.TopologyDropDown.Value;
            app.filter.order    = app.OrderSpinner.Value;
            app.filter.wc       = app.CutofffrequencySpinner.Value;
            app.filter.ripplePB = app.PassbandrippleSpinner.Value;
            app.filter.rippleSB=  app.StopbandrippleSpinner.Value;

            switch lower(app.filter.topology)
                case 'butterworth'
                    [b,a] = butter ( app.filter.order, app.filter.wc );
                case 'elliptic'
                    [b,a] = ellip ( app.filter.order, app.filter.ripplePB, app.filter.rippleSB, app.filter.wc );
                case 'chebyshev i'
                    [b,a] = cheby1 (app.filter.order, app.filter.ripplePB, app.filter.wc );
                case 'chebyshev ii'
                    [b,a] = cheby2 (app.filter.order, app.filter.rippleSB, app.filter.wc );
                otherwise
                    error('Topology not recognised')
            end
            app.filter.b = b;
            app.filter.a = a;

            calculateandPlot( app );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pzPlot = uiaxes(app.UIFigure);
            xlabel(app.pzPlot, 'Real Part')
            ylabel(app.pzPlot, 'Imaginary PardY')
            zlabel(app.pzPlot, 'Z')
            app.MagnitudePlot = uiaxes(app.UIFigure);
            xlabel(app.MagnitudePlot, 'Normalized Frequency')
            ylabel(app.MagnitudePlot, 'Magnitude |H|')
            zlabel(app.MagnitudePlot, 'Z')
            app.MagnitudePlot.YLim = [-1 1];
            app.ImpulseresponsePlot = uiaxes(app.UIFigure);
            xlabel(app.ImpulseresponsePlot, 'n')
            ylabel(app.ImpulseresponsePlot, 'h[n]')
            zlabel(app.ImpulseresponsePlot, 'Z')
            app.ImpulseresponsePlot.XLim = [-1 1];
            app.ImpulseresponsePlot.YLim = [-1 1];
            app.PhasePlot = uiaxes(app.UIFigure);
            xlabel(app.PhasePlot, 'Normalized Frequency')
            ylabel(app.PhasePlot, 'Phase')
            zlabel(app.PhasePlot, 'Z')
            app.PhasePlot.XLim = [-1 1];
            app.PhasePlot.YLim = [-1 1];
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'Response of lowpass IIR-Filter';
            app.ReferenceLabel = uilabel(app.UIFigure);
            app.ReferenceLabel.Text = 'McClellan et al., DSP First, Ch. 10';
            app.StableLabel = uilabel(app.UIFigure);
            app.StableLabel.Text = 'Stable';
            app.TopologyDropDown = uidropdown(app.UIFigure);
            app.TopologyDropDown.Items = {'Butterworth', 'Elliptic', 'Chebyshev I', 'Chebyshev II'};
            app.TopologyDropDown.ValueChangedFcn = createCallbackFcn(app, @defineFilter, true);
            app.TopologyDropDown.Value = 'Butterworth';
            app.CutofffrequencyLabel = uilabel(app.UIFigure);
            app.CutofffrequencyLabel.Text = 'Cutoff frequency ';
            app.CutofffrequencySpinner = uispinner(app.UIFigure);
            app.CutofffrequencySpinner.Step = 0.1;
            app.CutofffrequencySpinner.Limits = [0.01 0.99];
            app.CutofffrequencySpinner.ValueDisplayFormat = '%.2f';
            app.CutofffrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @defineFilter, true);
            app.CutofffrequencySpinner.Value = 0.3;
            app.OrderLabel = uilabel(app.UIFigure);
            app.OrderLabel.Text = 'Order ';
            app.OrderSpinner = uispinner(app.UIFigure);
            app.OrderSpinner.Limits = [1 10];
            app.OrderSpinner.ValueDisplayFormat = '%.0f';
            app.OrderSpinner.ValueChangedFcn = createCallbackFcn(app, @defineFilter, true);
            app.OrderSpinner.Value = 2;
            app.PassbandrippledBLabel = uilabel(app.UIFigure);
            app.PassbandrippledBLabel.Text = 'Passband ripple ][dB] ';
            app.PassbandrippleSpinner = uispinner(app.UIFigure);
            app.PassbandrippleSpinner.Step = 0.1;
            app.PassbandrippleSpinner.Limits = [0.1 10];
            app.PassbandrippleSpinner.ValueDisplayFormat = '%.1f';
            app.PassbandrippleSpinner.ValueChangedFcn = createCallbackFcn(app, @defineFilter, true);
            app.PassbandrippleSpinner.Value = 1;
            app.StopbandrippledBLabel = uilabel(app.UIFigure);
            app.StopbandrippledBLabel.Text = 'Stopband ripple ][dB] ';
            app.StopbandrippleSpinner = uispinner(app.UIFigure);
            app.StopbandrippleSpinner.Limits = [1 100];
            app.StopbandrippleSpinner.ValueDisplayFormat = '%.0f';
            app.StopbandrippleSpinner.ValueChangedFcn = createCallbackFcn(app, @defineFilter, true);
            app.StopbandrippleSpinner.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lowpass_response_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end