classdef running_average_example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RunningAverageDemoUIFigure     matlab.ui.Figure
        NoiselevelxNSpinner            matlab.ui.control.Spinner
        NoiselevelaLabel               matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        SignallengthNSpinner           matlab.ui.control.Spinner
        SignallengthNLabel             matlab.ui.control.Label
        FilterlengthMSpinner           matlab.ui.control.Spinner
        FilterlengthMLabel             matlab.ui.control.Label
        McClellanetalDSPFirstCh5Label  matlab.ui.control.Label
        ConvolutionExampleRunningAverageFilterLabel  matlab.ui.control.Label
        HiglightedsampleSpinner        matlab.ui.control.Spinner
        HiglightedsampleLabel          matlab.ui.control.Label
        ResultAxes                     matlab.ui.control.UIAxes
        ImpulseAxes                    matlab.ui.control.UIAxes
        MovingImpulseAxes              matlab.ui.control.UIAxes
        SignalAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            app.signal.N = length(app.signal.x);

            n     = 0:length(app.signal.x)-1;
            nlims = [-4 max(n)+5];

            app.signal.h = 1/app.signal.M * ones( 1, app.signal.M );
            nh = 0:length(app.signal.h)-1;
            nhi = -nh;

            y =conv(app.signal.x, app.signal.h);
            ny= 0:length(y)-1;

            stem(app.SignalAxes,  n, app.signal.x, 'filled', 'color', app.signal.color(1,:) );
            xlim(app.SignalAxes, nlims);
            hold(app.SignalAxes, "on");
            plot(app.SignalAxes, app.signal.m*[1 1],                  [-10 10], '-', 'color', app.signal.color(2,:));
            plot(app.SignalAxes, (app.signal.m-app.signal.M+1)*[1 1], [-10 10], '-', 'color', app.signal.color(2,:));
            hold(app.SignalAxes, "off");

            SetyAixs(app, app.SignalAxes, [min(app.signal.x) max(app.signal.x)] );

            stem( app.MovingImpulseAxes, nhi, fliplr( app.signal.h ), 'filled', 'color', app.signal.color(2,:))
            hold( app.MovingImpulseAxes, "on" );
            plot( app.MovingImpulseAxes, [0 0],                [-10 10], '--', 'color', app.signal.color(2,:));
            plot( app.MovingImpulseAxes, 1-app.signal.M*[1 1], [-10 10], '--', 'color', app.signal.color(2,:));
            hold( app.MovingImpulseAxes, "off" );
            xlim( app.MovingImpulseAxes, nlims-app.signal.m );

            stem(app.ImpulseAxes, nh, app.signal.h, 'filled', 'color', app.signal.color(2,:));
            xlim(app.ImpulseAxes, nlims);

            stem(app.ResultAxes, ny, y, 'filled', 'color', app.signal.color(4,:));
            hold(app.ResultAxes, "on");               
            plot(app.ResultAxes, app.signal.m*[1 1], [-10 10], 'k-');
            hold(app.ResultAxes, "off");
            xlim(app.ResultAxes, nlims);
            SetyAixs(app, app.ResultAxes, [min(y) max(y)] );

        end
        
        function results = SetyAixs(app, ax, ylims)
            ymin= min([min(ylims) 0])-0.5;
            ymax= max([max(ylims) 0])+0.5;
            
            ylim(ax, [ymin ymax] );
            results=0;
        end
        
        function results = DefineSignal(app)
            N = app.SignallengthNSpinner.Value;
            xN= app.NoiselevelxNSpinner.Value;
            signaldef= app.TextArea.Value;
            for k=1:length(signaldef)
                eval( signaldef{k} )
            end
            app.signal.x = x;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DefineSignal(app)
            app.signal.m = round( app.HiglightedsampleSpinner.Value );
            app.signal.M = round( app.FilterlengthMSpinner.Value );
%            app.signal.N = 20;            
            app.signal.color=colororder;
            CalculateAndPlot(app);
        end

        % Callback function
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.signal.x = str2num(value);
            CalculateAndPlot(app);
        end

        % Value changed function: HiglightedsampleSpinner
        function mSpinnerValueChanged(app, event)
            app.signal.m= app.HiglightedsampleSpinner.Value;
            app.signal.m= round(app.signal.m);
            CalculateAndPlot(app);
        end

        % Value changed function: FilterlengthMSpinner
        function FilterlengthMSpinnerValueChanged(app, event)
            app.signal.M  = round( app.FilterlengthMSpinner.Value );
            CalculateAndPlot(app);            
        end

        % Value changed function: NoiselevelxNSpinner, 
        % ...and 2 other components
        function SignalChanged(app, event)
            DefineSignal(app)
            CalculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RunningAverageDemoUIFigure = uifigure('Visible', 'off');
            app.RunningAverageDemoUIFigure.Name = 'Running Average Demo';
            app.SignalAxes = uiaxes(app.RunningAverageDemoUIFigure);
            title(app.SignalAxes, 'Signal')
            xlabel(app.SignalAxes, 'n')
            ylabel(app.SignalAxes, 'x[n]')
            zlabel(app.SignalAxes, 'Z')
            app.MovingImpulseAxes = uiaxes(app.RunningAverageDemoUIFigure);
            title(app.MovingImpulseAxes, 'Flipped filter')
            xlabel(app.MovingImpulseAxes, 'n')
            ylabel(app.MovingImpulseAxes, 'h[n] flipped')
            zlabel(app.MovingImpulseAxes, 'Z')
            app.MovingImpulseAxes.YLim = [0 1];
            app.ImpulseAxes = uiaxes(app.RunningAverageDemoUIFigure);
            title(app.ImpulseAxes, 'Filter')
            xlabel(app.ImpulseAxes, 'n')
            ylabel(app.ImpulseAxes, 'h[n]')
            zlabel(app.ImpulseAxes, 'Z')
            app.ImpulseAxes.YLim = [0 1];
            app.ResultAxes = uiaxes(app.RunningAverageDemoUIFigure);
            title(app.ResultAxes, 'Filtered signal')
            xlabel(app.ResultAxes, 'n')
            ylabel(app.ResultAxes, 'x[n]*h[n]')
            zlabel(app.ResultAxes, 'Z')
            app.HiglightedsampleLabel = uilabel(app.RunningAverageDemoUIFigure);
            app.HiglightedsampleLabel.Text = 'Higlighted sample ';
            app.HiglightedsampleSpinner = uispinner(app.RunningAverageDemoUIFigure);
            app.HiglightedsampleSpinner.Limits = [-10 100];
            app.HiglightedsampleSpinner.RoundFractionalValues = 'on';
            app.HiglightedsampleSpinner.ValueDisplayFormat = '%d';
            app.HiglightedsampleSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.ConvolutionExampleRunningAverageFilterLabel = uilabel(app.RunningAverageDemoUIFigure);
            app.ConvolutionExampleRunningAverageFilterLabel.Text = {'Convolution Example'; 'Running Average Filter'};
            app.McClellanetalDSPFirstCh5Label = uilabel(app.RunningAverageDemoUIFigure);
            app.McClellanetalDSPFirstCh5Label.Text = 'McClellan et al., DSP First, Ch. 5';
            app.FilterlengthMLabel = uilabel(app.RunningAverageDemoUIFigure);
            app.FilterlengthMLabel.Text = 'Filter length [M] ';
            app.FilterlengthMSpinner = uispinner(app.RunningAverageDemoUIFigure);
            app.FilterlengthMSpinner.Limits = [1 10];
            app.FilterlengthMSpinner.RoundFractionalValues = 'on';
            app.FilterlengthMSpinner.ValueDisplayFormat = '%d';
            app.FilterlengthMSpinner.ValueChangedFcn = createCallbackFcn(app, @FilterlengthMSpinnerValueChanged, true);
            app.FilterlengthMSpinner.Value = 2;
            app.SignallengthNLabel = uilabel(app.RunningAverageDemoUIFigure);
            app.SignallengthNLabel.Text = 'Signal length [N] ';
            app.SignallengthNSpinner = uispinner(app.RunningAverageDemoUIFigure);
            app.SignallengthNSpinner.Limits = [1 400];
            app.SignallengthNSpinner.RoundFractionalValues = 'on';
            app.SignallengthNSpinner.ValueDisplayFormat = '%d';
            app.SignallengthNSpinner.ValueChangedFcn = createCallbackFcn(app, @SignalChanged, true);
            app.SignallengthNSpinner.Value = 60;
            app.TextArea = uitextarea(app.RunningAverageDemoUIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @SignalChanged, true);
            app.TextArea.Value = {'t = linspace(0, 2*pi, N);'; 'x= (t+1).*cos(t)+xN*randn(1,N);'};
            app.NoiselevelaLabel = uilabel(app.RunningAverageDemoUIFigure);
            app.NoiselevelaLabel.Text = 'Noise level [xN] ';
            app.NoiselevelxNSpinner = uispinner(app.RunningAverageDemoUIFigure);
            app.NoiselevelxNSpinner.Step = 0.1;
            app.NoiselevelxNSpinner.Limits = [0 10];
            app.NoiselevelxNSpinner.ValueDisplayFormat = '%.1f';
            app.NoiselevelxNSpinner.ValueChangedFcn = createCallbackFcn(app, @SignalChanged, true);
            app.NoiselevelxNSpinner.Value = 0.3;
            % Show the figure after all components are created
            app.RunningAverageDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = running_average_example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RunningAverageDemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RunningAverageDemoUIFigure)
        end
    end
end