classdef beat_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhasorFigure  matlab.ui.Figure
        QuitButton    matlab.ui.control.StateButton
        FormulaImage  matlab.ui.control.Image
        FreezeButton  matlab.ui.control.StateButton
        HeaderLabel   matlab.ui.control.Label
        SumAxes       matlab.ui.control.UIAxes
        PhasorAxes    matlab.ui.control.UIAxes
        SingleAxes    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        phasorpar   % Description
        status      % Description
    end

    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            app.status.stopped=0;

            %---- Initialise circle and time plot
            phi = linspace( 0, 2*pi, 100);
            zc  = exp( 1i*phi );            % Unit circle

            nTime = 600;
            t = ( 0: nTime-1 ) *app.phasorpar.dt;
            c1= zeros( size(t) );      % Times trace of zeros, to be filled
            c2= zeros( size(t) );

            % Draw initial figure
            plot( app.PhasorAxes, real(zc), imag(zc), 'k:');   % Unit circle
            hold( app.PhasorAxes, "on" );
            plot( app.PhasorAxes, [-2 2], [ 0 0], 'k:');       % Real axis
            plot( app.PhasorAxes, [ 0 0], [-2 2], 'k:');       % Imaginary axis

            hphasor1 = plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], '.-', 'color', app.phasorpar.color{1} );   % First frequency phasor
            hphasor2 = plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], '.-', 'color', app.phasorpar.color{2} );   % Second frequency phasor
            hphasors = plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], '.-', 'color', app.phasorpar.color{3} );   % Sum phasor

            htime1 =  plot( app.SingleAxes, t, c1,    'color', app.phasorpar.color{1} );    % Time trace, first frequency 
            hold(app.SingleAxes,"on");
            htime2 =  plot( app.SingleAxes, t, c2,    'color', app.phasorpar.color{2} );    % Time trace, second frequency 
            htimes =  plot( app.SumAxes,    t, c1+c2, 'color', app.phasorpar.color{3} );    % Time trace, sum

            %%--- Run continously to animate plots
            app.phasorpar.t = 0;    % Time after start
            while not( app.QuitButton.Value )
                if not(app.FreezeButton.Value)
                    tic
                    % Calculate new values
                    app.phasorpar.z1 = exp( 2*pi* 1i*app.phasorpar.f1* app.phasorpar.t );
                    app.phasorpar.z2 = exp( 2*pi* 1i*app.phasorpar.f2* app.phasorpar.t );

                    z1 = app.phasorpar.z1; 
                    z2 = app.phasorpar.z2;
                    zs = z1 + z2;
                    c1 = [ c1(2:end) real(z1)];  % Add single point to traces
                    c2 = [ c2(2:end) real(z2)];

                    % Update data in plots
                    hphasor1.XData= [  0 real(z1) ];  hphasor1.YData = [  0 imag(z1) ];
                    hphasor2.XData= [  0 real(z2) ];  hphasor2.YData = [  0 imag(z2) ];
                    hphasors.XData= [  0 real(zs) ];  hphasors.YData = [  0 imag(zs) ];

                    htime1.YData= t; htime1.XData= c1;
                    htime2.YData= t; htime2.XData= c2;
                    htimes.YData= t; htimes.XData= c1+c2;
                    set(app.SingleAxes, 'ylim', [ min(t) max(t)] );
                    set(app.SumAxes,    'ylim', [ min(t) max(t)] );

                    % Update time
                    app.phasorpar.t = app.phasorpar.t+app.phasorpar.dt;
                    t = t + app.phasorpar.dt;
                    
                end
                drawnow 

                tel=toc;
                pause( app.phasorpar.dt - tel );
            end
        end        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.phasorpar.f1 = 0.38;
            app.phasorpar.f2 = 0.42;
            app.phasorpar.dt = 0.1;

            c = colororder;
            app.phasorpar.color= mat2cell( c, ones( length(c), 1 ), 3 );
            CalculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PhasorFigure = uifigure('Visible', 'off');
            app.PhasorFigure.Name = 'MATLAB App';
            app.SingleAxes = uiaxes(app.PhasorFigure);
            app.SingleAxes.XLim = [-2 2];
            app.SingleAxes.YLim = [-1 1];
            app.PhasorAxes = uiaxes(app.PhasorFigure);
            title(app.PhasorAxes, 'Phasor')
            xlabel(app.PhasorAxes, 'Re\{z\}')
            ylabel(app.PhasorAxes, 'Im\{z\}')
            zlabel(app.PhasorAxes, 'Z')
            app.PhasorAxes.XLim = [-2 2];
            app.PhasorAxes.YLim = [-2 2];
            app.PhasorAxes.XTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.PhasorAxes.YTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.SumAxes = uiaxes(app.PhasorFigure);
            app.SumAxes.XLim = [-2 2];
            app.SumAxes.YLim = [-1 1];
            app.HeaderLabel = uilabel(app.PhasorFigure);
            app.HeaderLabel.Text = 'Beat as Phasors';
            app.FreezeButton = uibutton(app.PhasorFigure, 'state');
            app.FreezeButton.Text = 'Freeze';
            app.FormulaImage = uiimage(app.PhasorFigure);
            app.FormulaImage.ImageSource = fullfile(pathToMLAPP, 'beat.png');
            app.QuitButton = uibutton(app.PhasorFigure, 'state');
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.PhasorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beat_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhasorFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhasorFigure)
        end
    end
end