classdef beat_phasordemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhasorFigure        matlab.ui.Figure
        Image3              matlab.ui.control.Image
        FreezeButton        matlab.ui.control.StateButton
        StopButton          matlab.ui.control.StateButton
        BeatasPhasorsLabel  matlab.ui.control.Label
        Timeplot_sum        matlab.ui.control.UIAxes
        Phasorplot          matlab.ui.control.UIAxes
        Timeplot_single     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        phasorpar % Description
        status % Description
    end

    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            app.status.stopped=0;

            %---- Initialise circle and time plot
            phi= linspace(0,2*pi, 100);
            zc = exp(1i*phi);            % Unit circle

            N=400;
            t = (0:N-1)*app.phasorpar.dt;
            c1= zeros( size(t) );         % Time trace of zeros
            c2= zeros( size(t) );

            colors = { [ 0.0 0.0 1.0 ], [ 0.0 0.5 0.0 ], [ 1.0 0.0 0.0 ] };

            % Draw initial figure
            plot( app.Phasorplot, real(zc), imag(zc), 'k:');   % Unit circle
            hold( app.Phasorplot,"on");
            plot( app.Phasorplot, [-2 2], [ 0 0], 'k:');       % Real axis
            plot( app.Phasorplot, [ 0 0], [-2 2], 'k:');       % Imaginary axis

            hphasor1 = plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], '.-', 'color', colors{1} );   % First frequency phasor
            hphasor2 = plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], '.-', 'color', colors{2} );   % Second frequency phasor
            hphasors = plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], '.-', 'color', colors{3} );   % Sum phasor

            htime1 =  plot( app.Timeplot_single, t, c1,    'color', colors{1} );    % Time trace, first frequency 
            hold(app.Timeplot_single,"on");
            htime2 =  plot( app.Timeplot_single, t, c2,    'color', colors{2} );    % Time trace, second frequency 
            htimes =  plot( app.Timeplot_sum,    t, c1+c2, 'color', colors{3} );    % Time trace, sum

            %%--- Run continously to animate plots
            app.phasorpar.t=0;
            while not(app.StopButton.Value)
                if not(app.FreezeButton.Value)
                    tic
                    % Calculate new values
                    app.phasorpar.z1 = exp( 2*pi*1i*app.phasorpar.f1* app.phasorpar.t );
                    app.phasorpar.z2 = exp( 2*pi*1i*app.phasorpar.f2* app.phasorpar.t );

                    z1 = app.phasorpar.z1; 
                    z2 = app.phasorpar.z2;
                    zs=z1+z2;
                    c1=[ c1(2:end) real(z1)];
                    c2=[ c2(2:end) real(z2)];

                    % Update data in plots
                    hphasor1.XData= [  0     real(z1) ];  hphasor1.YData = [  0     imag(z1) ];
                    hphasor2.XData= [  0     real(z2) ];  hphasor2.YData = [  0     imag(z2) ];
                    hphasors.XData= [  0     real(zs) ];  hphasors.YData = [  0     imag(zs) ];

                    htime1.YData= t; htime1.XData= c1;
                    htime2.YData= t; htime2.XData= c2;
                    htimes.YData= t; htimes.XData= c1+c2;
                    set(app.Timeplot_single, 'ylim', [min(t) max(t)]);
                    set(app.Timeplot_sum,    'ylim', [min(t) max(t)]);

                    % Update time
                    app.phasorpar.t=app.phasorpar.t+app.phasorpar.dt;
                    t=t+app.phasorpar.dt;
                    
                end
                drawnow 

                tel=toc;
                pause(app.phasorpar.dt-tel);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.phasorpar.f1 = 0.38;
            app.phasorpar.f2 = 0.42;
            app.phasorpar.dt = 0.1;
            CalculateAndPlot(app);
        end

        % Callback function
        function AmplitudeASliderValueChanging(app, event)
            app.phasorpar.A=event.Value;
            CalculateAndPlot(app)
        end

        % Callback function
        function FrequencyfSliderValueChanged(app, event)
            app.phasorpar.f=event.Value;
            CalculateAndPlot(app)
        end

        % Callback function
        function PhaseSliderValueChanging(app, event)
            app.phasorpar.phi=event.Value;
            CalculateAndPlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PhasorFigure = uifigure('Visible', 'off');
            app.PhasorFigure.Name = 'MATLAB App';
            app.Timeplot_single = uiaxes(app.PhasorFigure);
            xlabel(app.Timeplot_single, 'Time [s]')
            ylabel(app.Timeplot_single, 'y(t)')
            zlabel(app.Timeplot_single, 'Z')
            app.Timeplot_single.XLim = [-2 2];
            app.Timeplot_single.YLim = [-1 1];
            app.Phasorplot = uiaxes(app.PhasorFigure);
            title(app.Phasorplot, 'Phasor')
            xlabel(app.Phasorplot, 'Re\{z\}')
            ylabel(app.Phasorplot, 'Im\{z\}')
            zlabel(app.Phasorplot, 'Z')
            app.Phasorplot.XLim = [-2 2];
            app.Phasorplot.YLim = [-2 2];
            app.Phasorplot.XTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.Phasorplot.YTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.Timeplot_sum = uiaxes(app.PhasorFigure);
            xlabel(app.Timeplot_sum, 'Time [s]')
            ylabel(app.Timeplot_sum, 'y(t)')
            zlabel(app.Timeplot_sum, 'Z')
            app.Timeplot_sum.XLim = [-2 2];
            app.Timeplot_sum.YLim = [-1 1];
            app.BeatasPhasorsLabel = uilabel(app.PhasorFigure);
            app.BeatasPhasorsLabel.Text = 'Beat as Phasors';
            app.StopButton = uibutton(app.PhasorFigure, 'state');
            app.StopButton.Text = 'Stop';
            app.FreezeButton = uibutton(app.PhasorFigure, 'state');
            app.FreezeButton.Text = 'Freeze';
            app.Image3 = uiimage(app.PhasorFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'beat.png');
            % Show the figure after all components are created
            app.PhasorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beat_phasordemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhasorFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhasorFigure)
        end
    end
end