classdef phasor_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhasorFigure              matlab.ui.Figure
        Image3                    matlab.ui.control.Image
        negative_image            matlab.ui.control.Image
        sine_Image                matlab.ui.control.Image
        exp_Im_Button             matlab.ui.control.StateButton
        FreezeButton              matlab.ui.control.StateButton
        exp_n_Button              matlab.ui.control.StateButton
        StopButton                matlab.ui.control.StateButton
        PhasorsandSinewavesLabel  matlab.ui.control.Label
        Phasorplot                matlab.ui.control.UIAxes
        Timeplot                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        phasorpar % Description
        status % Description
    end

    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            app.status.stopped=0;

            %---- Initialise circle and time plot
            phi= linspace(0,2*pi, 100);
            zc = exp(1i*phi);            % Unit circle

            N=200;
            t= [0:N-1]*app.phasorpar.dt;
            c= zeros(size(t));         % Time trace of only zeros
            s= zeros(size(t));         % Time trace of only zeros

            % Draw initial figure
            plot(app.Phasorplot, real(zc), imag(zc), 'k:');  % Unit circle
            hold(app.Phasorplot,"on");
            plot(app.Phasorplot, [-1 1], [0 0], 'k:');       % Real axis
            plot(app.Phasorplot, [0,0],[-1 1],  'k:');       % Imaginary axis

            hphasor= plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], 'k.-');   % Positive phasor
            hnphasor=plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], '.-', 'color', [0 0.5 0.0]);   % Negative phasor
            hcos=    plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], 'b.-');   % Cosine phasor (real)
            hsin=    plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], 'r.-');   % Sine phasor (Imaginary)
            hcline=  plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], 'k:');    % Projection to real axis
            hnline=  plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], 'k:');    % Projection from negative phasor to real axis
            hsline=  plot(app.Phasorplot, [0, 0 ], [ 0, 0 ], 'k:');    % Projection to imaginary axis

            hctime =  plot(app.Timeplot, t,c, 'b-');                    % Time trace, cos-wave
            hold(app.Timeplot,"on");
            hstime =  plot(app.Timeplot, t,s, 'r-');                    % Time trace, sine-wave

            %%--- Run continously to animate plots
            app.phasorpar.t=0;
            while not(app.StopButton.Value)
                if not(app.FreezeButton.Value)
                    tic
                    % Calculate new values
                    app.phasorpar.z = exp( 2*pi*1i*app.phasorpar.f* app.phasorpar.t );
                    app.phasorpar.zn= exp(-2*pi*1i*app.phasorpar.f* app.phasorpar.t );

                    z = app.phasorpar.z;
                    zn= app.phasorpar.zn;
                    c=[ c(2:end) real(z)];
                    s=[ s(2:end) imag(z)];

                    % Update data in plots
                    hphasor.XData =[  0     real(z)  ];  hphasor.YData= [  0     imag(z) ];
                    hnphasor.XData=[  0     real(zn) ];  hnphasor.YData=[  0     imag(zn)];
                    hcos.XData    =[  0     real(z)  ];  hcos.YData =   [  0       0     ];
                    hsin.XData    =[  0        0     ];  hsin.YData =   [  0     imag(z) ];
                    hcline.XData  =[real(z)  real(z) ];  hcline.YData = [  0     imag(z) ];
                    hnline.XData  =[real(zn) real(zn)];  hnline.YData = [  0     imag(zn)];
                    hsline.XData  =[  0      real(z) ];  hsline.YData = [imag(z) imag(z) ];

                    hctime.YData= t; hctime.XData= c;
                    hstime.YData= t; hstime.XData= s;
                    set(app.Timeplot, 'ylim', [min(t) max(t)]);

                    % Update time
                    app.phasorpar.t=app.phasorpar.t+app.phasorpar.dt;
                    t=t+app.phasorpar.dt;
                    
                end
                % Determine which plots to display
                set(hnphasor, 'Visible', app.exp_n_Button.Value);
                set(hnline,   'Visible', app.exp_n_Button.Value);
                set(hsin,     'Visible', app.exp_Im_Button.Value);
                set(hsline,   'Visible', app.exp_Im_Button.Value);
                set(hstime,   'Visible', app.exp_Im_Button.Value);

                set(app.negative_image, 'Visible', app.exp_n_Button.Value  );
                set(app.sine_Image,     'Visible', app.exp_Im_Button.Value );

                drawnow 

                tel=toc;
                pause(app.phasorpar.dt-tel);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.phasorpar.f  = 0.05;
            app.phasorpar.dt = 0.1;

            N=3000;
            app.phasorpar.t=linspace(0,1,N);
            CalculateAndPlot(app);
        end

        % Callback function
        function AmplitudeASliderValueChanging(app, event)
            app.phasorpar.A=event.Value;
            CalculateAndPlot(app)
        end

        % Callback function
        function FrequencyfSliderValueChanged(app, event)
            app.phasorpar.f=event.Value;
            CalculateAndPlot(app)
        end

        % Callback function
        function PhaseSliderValueChanging(app, event)
            app.phasorpar.phi=event.Value;
            CalculateAndPlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhasorFigure = uifigure('Visible', 'off');
            app.PhasorFigure.Name = 'MATLAB App';
            app.Timeplot = uiaxes(app.PhasorFigure);
            xlabel(app.Timeplot, 'Time [s]')
            ylabel(app.Timeplot, 'y(t)')
            zlabel(app.Timeplot, 'Z')
            app.Timeplot.XLim = [-1 1];
            app.Timeplot.YLim = [-1 1];
            app.Phasorplot = uiaxes(app.PhasorFigure);
            title(app.Phasorplot, 'Phasor')
            xlabel(app.Phasorplot, 'Re\{z\}')
            ylabel(app.Phasorplot, 'Im\{z\}')
            zlabel(app.Phasorplot, 'Z')
            app.Phasorplot.XLim = [-1 1];
            app.Phasorplot.YLim = [-1 1];
            app.Phasorplot.XTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.Phasorplot.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.PhasorsandSinewavesLabel = uilabel(app.PhasorFigure);
            app.PhasorsandSinewavesLabel.Text = 'Phasors and Sine waves';
            app.StopButton = uibutton(app.PhasorFigure, 'state');
            app.StopButton.Text = 'Stop';
            app.exp_n_Button = uibutton(app.PhasorFigure, 'state');
            app.exp_n_Button.Text = 'Negative';
            app.FreezeButton = uibutton(app.PhasorFigure, 'state');
            app.FreezeButton.Text = 'Freeze';
            app.exp_Im_Button = uibutton(app.PhasorFigure, 'state');
            app.exp_Im_Button.Text = 'Imaginary';
            app.sine_Image = uiimage(app.PhasorFigure);
            app.sine_Image.ImageSource = 'sin_imaginary.png';
            app.negative_image = uiimage(app.PhasorFigure);
            app.negative_image.Visible = 'off';
            app.negative_image.ImageSource = 'cos_pos_neg.png';
            app.Image3 = uiimage(app.PhasorFigure);
            app.Image3.ImageSource = 'cos_real.png';
            % Show the figure after all components are created
            app.PhasorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phasor_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhasorFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhasorFigure)
        end
    end
end