classdef sinewave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SinewavedemoLabel      matlab.ui.control.Label
        PhaseSlider            matlab.ui.control.Slider
        PhaseSliderLabel       matlab.ui.control.Label
        FrequencyfSlider       matlab.ui.control.Slider
        FrequencyfSliderLabel  matlab.ui.control.Label
        AmplitudeASlider       matlab.ui.control.Slider
        AmplitudeASliderLabel  matlab.ui.control.Label
        xtAcoswtLabel          matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cospar % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            y=app.cospar.A*cos(2*pi*app.cospar.f* app.cospar.t + app.cospar.phi);
            plot(app.UIAxes, app.cospar.t, y);
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            N=3000;
            app.cospar.t=linspace(0,1,N);
            app.cospar.A   = app.AmplitudeASlider.Value;
            app.cospar.f   = app.FrequencyfSlider.Value;
            app.cospar.phi = app.PhaseSlider.Value;
            CalculateAndPlot(app);
        end

        % Callback function: AmplitudeASlider, AmplitudeASlider
        function AmplitudeASliderValueChanging(app, event)
            app.cospar.A=event.Value;
            CalculateAndPlot(app)
        end

        % Callback function: FrequencyfSlider, FrequencyfSlider
        function FrequencyfSliderValueChanged(app, event)
            app.cospar.f=event.Value;
            CalculateAndPlot(app)
        end

        % Callback function: PhaseSlider, PhaseSlider
        function PhaseSliderValueChanging(app, event)
            app.cospar.phi=event.Value;
            CalculateAndPlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'y(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-2 2];
            app.xtAcoswtLabel = uilabel(app.UIFigure);
            app.xtAcoswtLabel.Text = 'x(t)= A cos(wt+ø)';
            app.AmplitudeASliderLabel = uilabel(app.UIFigure);
            app.AmplitudeASliderLabel.Text = 'Amplitude A';
            app.AmplitudeASlider = uislider(app.UIFigure);
            app.AmplitudeASlider.Limits = [0 2];
            app.AmplitudeASlider.MajorTicks = [0 0.5 1 1.5 2];
            app.AmplitudeASlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeASliderValueChanging, true);
            app.AmplitudeASlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeASliderValueChanging, true);
            app.AmplitudeASlider.Value = 1;
            app.FrequencyfSliderLabel = uilabel(app.UIFigure);
            app.FrequencyfSliderLabel.Text = 'Frequency f';
            app.FrequencyfSlider = uislider(app.UIFigure);
            app.FrequencyfSlider.Limits = [0 10];
            app.FrequencyfSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyfSliderValueChanged, true);
            app.FrequencyfSlider.ValueChangingFcn = createCallbackFcn(app, @FrequencyfSliderValueChanged, true);
            app.FrequencyfSlider.Value = 2;
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase ø';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.PhaseSlider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.PhaseSlider.MinorTicks = [-5 -4.8 -4.6 -4.4 -4.2 -4 -3.8 -3.6 -3.4 -3.2 -3 -2.8 -2.6 -2.4 -2.2 -2 -1.8 -1.6 -1.4 -1.2 -1 -0.8 -0.6 -0.399999999999999 -0.199999999999999 0 0.199999999999999 0.399999999999999 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5];
            app.SinewavedemoLabel = uilabel(app.UIFigure);
            app.SinewavedemoLabel.Text = 'Sine wave demo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sinewave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end