classdef ArrayBeamprofile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureBeamProfile           matlab.ui.Figure
        lButton                       matlab.ui.control.Button
        l2Button                      matlab.ui.control.Button
        NoofelementsLabel             matlab.ui.control.Label
        NoofelementsEditField         matlab.ui.control.NumericEditField
        ElementpitchumLabel           matlab.ui.control.Label
        ElementpitchumEditField       matlab.ui.control.NumericEditField
        WavelengthumLabel             matlab.ui.control.Label
        WavelengthumEditField         matlab.ui.control.NumericEditField
        PitchWavelengthLabel          matlab.ui.control.Label
        PitchWavelengthEditField      matlab.ui.control.NumericEditField
        ElementshapeDropDownLabel     matlab.ui.control.Label
        ElementshapeDropDown          matlab.ui.control.DropDown
        SpeedofsoundmsLabel           matlab.ui.control.Label
        SpeedofsoundmsEditField       matlab.ui.control.NumericEditField
        SteeringAngleSlider           matlab.ui.control.Slider
        BeamprofileRectangular        matlab.ui.control.UIAxes
        BeamProfilefromaLinearTransducerArrayLabel  matlab.ui.control.Label
        ElementkerfumLabel            matlab.ui.control.Label
        ElementkerfumEditField        matlab.ui.control.NumericEditField
        ElementwidthumLabel           matlab.ui.control.Label
        ElementwidthumEditField       matlab.ui.control.NumericEditField
        SteeringAngleDegLabel         matlab.ui.control.Label
        SteeringAngleDegSpinner       matlab.ui.control.Spinner
        Image                         matlab.ui.control.Image
        ElementDelaynsEditFieldLabel  matlab.ui.control.Label
        ElementDelaynsEditField       matlab.ui.control.NumericEditField
        FarfieldprofilecalculatedfromtheFraunhoferapproximationLabel  matlab.ui.control.Label
        FrequencyMHzSpinnerLabel      matlab.ui.control.Label
        FrequencyMHzSpinner           matlab.ui.control.Spinner
        ScaledBSpinnerLabel           matlab.ui.control.Label
        ScaledBSpinner                matlab.ui.control.Spinner
    end

    properties (Access = private)
        Polarax % Description
        Polarvalue
        ArrayParameters
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app,Na)
            if nargin<3
                Na=1000;
            end
            [p,theta,A]=CalculateBeamprofile(app,Na);
            thetaS= app.ArrayParameters.thetaS;
            PdB=20*log10(abs(p));
            AdB=20*log10(A);
            yl=get(app.BeamprofileRectangular,'ylim');
            plot(app.BeamprofileRectangular,theta,PdB, 'b-', theta,AdB, 'b-', thetaS*[1 1], yl, 'k:') ;
            drawnow
            set(app.Polarvalue,'ThetaData',deg2rad(theta),'RData',PdB);
            results=0;
            
        end
        
        function [p,theta,A] = CalculateBeamprofile(app, Na)
            theta = linspace(-90,90,Na);
            thetaS=app.ArrayParameters.thetaS;
            dl=app.ArrayParameters.dl;
            N =app.ArrayParameters.N;
            wl=app.ArrayParameters.w/app.ArrayParameters.lambda;
            
            switch(lower(app.ArrayParameters.shape))
                case 'point',       A= ones(size(theta));
                case 'rectangular', A= sinc(wl*(sin(deg2rad(theta))));
                otherwise, error('Unknown element shape')
            end
            x=pi*dl*(sin(deg2rad(theta))-sin(deg2rad(thetaS)));
            p0= sin(N*x)./(N*sin(x));
            p= A.*p0;
        end
        
        function results = UpdateWavelength(app)
            f= app.ArrayParameters.f;
            c= app.ArrayParameters.c;
            lambda=c/f;
            app.ArrayParameters.lambda=lambda;
            app.WavelengthumEditField.Value=lambda*1e6;
            UpdatePitch(app);
            results=0;
        end
        
        function results = UpdatePitch(app)
            d= app.ArrayParameters.d;
            l= app.ArrayParameters.lambda;
            k= app.ArrayParameters.k;
            dl=d/l;
            w =d-k;
            app.ArrayParameters.dl=dl;
            app.ArrayParameters.w=w;
            
            app.ElementwidthumEditField.Value=w*1e6;
            app.PitchWavelengthEditField.Value=dl;
            CalculateDelay(app);
            results=0;
        end
        
        function results = CalculateDelay(app)
            thetaS=deg2rad(app.ArrayParameters.thetaS);
            app.ArrayParameters.tau=(app.ArrayParameters.d*sin(thetaS))/app.ArrayParameters.c;
            app.ElementDelaynsEditField.Value=app.ArrayParameters.tau*1e9;
            results=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %--- Initialize array parameters with values from GUI
            app.ArrayParameters.f = app.FrequencyMHzSpinner.Value*1e6;
            app.ArrayParameters.c = app.SpeedofsoundmsEditField.Value;
            app.ArrayParameters.d = app.ElementpitchumEditField.Value*1e-6;
            app.ArrayParameters.k = app.ElementkerfumEditField.Value*1e-6;
            app.ArrayParameters.N = app.NoofelementsEditField.Value;
            app.ArrayParameters.shape = app.ElementshapeDropDown.Value;
            app.ArrayParameters.thetaS = app.SteeringAngleSlider.Value;

            dBmin = app.ScaledBSpinner.Value;
            app.BeamprofileRectangular.YLim= [dBmin, 0];
            
            %--- Create polar axes and position it in pixels
            app.Polarax = polaraxes(app.UIFigureBeamProfile);
            app.Polarax.Units = 'pixels';
            app.Polarax.Position = [600 30 420 280];
            app.Polarax.ThetaLim =[-90 90];
            app.Polarax.RLim = [dBmin, 0];
            app.Polarax.RTick= -72:6:0;
            app.Polarax.RMinorTick = 'on';
            app.Polarax.RMinorGrid = 'on';
            app.Polarax.ThetaAxisUnits="degrees";
            app.Polarax.ThetaZeroLocation="bottom";
            app.Polarax.FontUnits="pixels";
            app.Polarax.FontSize = 12;
            app.Polarax.NextPlot ="Add";
            app.Polarvalue =polarplot(app.Polarax, 0,1);
            
            %--- Update calculated fields and plot results
            UpdateWavelength(app);
            CalculateAndPlot(app,0);
            drawnow
        end

        % Value changed function: SpeedofsoundmsEditField
        function SpeedofsoundmsEditFieldValueChanged(app, event)
            app.ArrayParameters.c = app.SpeedofsoundmsEditField.Value;
            UpdateWavelength(app);
            UpdatePitch(app);
            CalculateAndPlot(app,event);
        end

        % Value changed function: FrequencyMHzSpinner
        function FrequencyMHzSpinnerValueChanged(app, event)
            app.ArrayParameters.f=app.FrequencyMHzSpinner.Value*1e6;
            UpdateWavelength(app);
            UpdatePitch(app);
            CalculateAndPlot(app,event);
        end

        % Value changed function: ElementpitchumEditField
        function ElementpitchumEditFieldValueChanged(app, event)
            app.ArrayParameters.d=app.ElementpitchumEditField.Value*1e-6;
            UpdatePitch(app);
            CalculateAndPlot(app,event);
        end

        % Value changed function: ElementkerfumEditField
        function ElementkerfumEditFieldValueChanged(app, event)
            app.ArrayParameters.k = app.ElementkerfumEditField.Value*1e-6;
            UpdatePitch(app);
            CalculateAndPlot(app,event);
        end

        % Value changed function: NoofelementsEditField
        function NoofelementsEditFieldValueChanged(app, event)
            app.ArrayParameters.N=app.NoofelementsEditField.Value;
            CalculateAndPlot(app,event);
        end

        % Value changed function: ElementshapeDropDown
        function ElementshapeDropDownValueChanged(app, event)
            app.ArrayParameters.shape=app.ElementshapeDropDown.Value;
            CalculateAndPlot(app,event);
        end

        % Value changed function: SteeringAngleDegSpinner
        function SteeringAngleDegSpinnerValueChanged(app, event)
            thetaS= app.SteeringAngleDegSpinner.Value;
            app.ArrayParameters.thetaS   = thetaS;
            app.SteeringAngleSlider.Value=thetaS;
            CalculateDelay(app);
            CalculateAndPlot(app,event);
        end

        % Callback function: SteeringAngleSlider, SteeringAngleSlider
        function SteeringAngleSliderValueChanged(app, event)
            thetaS= event.Value;
            app.SteeringAngleDegSpinner.Value=thetaS;
            app.ArrayParameters.thetaS = thetaS;
            CalculateDelay(app);
            CalculateAndPlot(app,event);
            drawnow
        end

        % Button pushed function: l2Button
        function l2ButtonPushed(app, event)
            app.ArrayParameters.d=1/2*app.ArrayParameters.lambda;
            app.ElementpitchumEditField.Value=app.ArrayParameters.d*1e6;
            UpdatePitch(app);
            CalculateAndPlot(app,event);
        end

        % Button pushed function: lButton
        function lButtonPushed(app, event)
            app.ArrayParameters.d=app.ArrayParameters.lambda;
            app.ElementpitchumEditField.Value=app.ArrayParameters.d*1e6;
            UpdatePitch(app);
            CalculateAndPlot(app,event);
        end

        % Value changed function: ScaledBSpinner
        function ScaledBSpinnerValueChanged(app, event)
            Pmin = app.ScaledBSpinner.Value;
            ylim(app.BeamprofileRectangular,[Pmin,0]);
            rlim(app.Polarax,[Pmin,0])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureBeamProfile = uifigure('Visible', 'off');
            app.UIFigureBeamProfile.Name = 'Far-Field Array Beam Profile';
            app.lButton = uibutton(app.UIFigureBeamProfile, 'push');
            app.lButton.ButtonPushedFcn = createCallbackFcn(app, @lButtonPushed, true);
            app.lButton.Text = 'l';
            app.l2Button = uibutton(app.UIFigureBeamProfile, 'push');
            app.l2Button.ButtonPushedFcn = createCallbackFcn(app, @l2ButtonPushed, true);
            app.l2Button.Text = 'l/2';
            app.NoofelementsLabel = uilabel(app.UIFigureBeamProfile);
            app.NoofelementsLabel.Text = 'No. of elements ';
            app.NoofelementsEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.NoofelementsEditField.Limits = [1 4000];
            app.NoofelementsEditField.ValueDisplayFormat = '%d';
            app.NoofelementsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofelementsEditFieldValueChanged, true);
            app.NoofelementsEditField.Value = 64;
            app.ElementpitchumLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementpitchumLabel.Text = 'Element pitch [um] ';
            app.ElementpitchumEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.ElementpitchumEditField.Limits = [1 5000];
            app.ElementpitchumEditField.ValueDisplayFormat = '%4.0f';
            app.ElementpitchumEditField.ValueChangedFcn = createCallbackFcn(app, @ElementpitchumEditFieldValueChanged, true);
            app.ElementpitchumEditField.Value = 300;
            app.WavelengthumLabel = uilabel(app.UIFigureBeamProfile);
            app.WavelengthumLabel.Text = 'Wavelength [um] ';
            app.WavelengthumEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.WavelengthumEditField.Limits = [10 5000];
            app.WavelengthumEditField.ValueDisplayFormat = '%4.0f';
            app.WavelengthumEditField.Editable = 'off';
            app.WavelengthumEditField.Value = 300;
            app.PitchWavelengthLabel = uilabel(app.UIFigureBeamProfile);
            app.PitchWavelengthLabel.Text = 'Pitch / Wavelength ';
            app.PitchWavelengthEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.PitchWavelengthEditField.Limits = [0.01 10];
            app.PitchWavelengthEditField.ValueDisplayFormat = '%4.2f';
            app.PitchWavelengthEditField.Editable = 'off';
            app.PitchWavelengthEditField.Value = 0.7;
            app.ElementshapeDropDownLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementshapeDropDownLabel.Text = 'Element shape';
            app.ElementshapeDropDown = uidropdown(app.UIFigureBeamProfile);
            app.ElementshapeDropDown.Items = {'Point', 'Rectangular'};
            app.ElementshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementshapeDropDownValueChanged, true);
            app.ElementshapeDropDown.Value = 'Point';
            app.SpeedofsoundmsLabel = uilabel(app.UIFigureBeamProfile);
            app.SpeedofsoundmsLabel.Text = 'Speed of sound [m/s] ';
            app.SpeedofsoundmsEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.SpeedofsoundmsEditField.Limits = [10 5000];
            app.SpeedofsoundmsEditField.ValueDisplayFormat = '%4.0f';
            app.SpeedofsoundmsEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedofsoundmsEditFieldValueChanged, true);
            app.SpeedofsoundmsEditField.Value = 1540;
            app.SteeringAngleSlider = uislider(app.UIFigureBeamProfile);
            app.SteeringAngleSlider.Limits = [-90 90];
            app.SteeringAngleSlider.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.SteeringAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SteeringAngleSliderValueChanged, true);
            app.SteeringAngleSlider.ValueChangingFcn = createCallbackFcn(app, @SteeringAngleSliderValueChanged, true);
            app.BeamprofileRectangular = uiaxes(app.UIFigureBeamProfile);
            title(app.BeamprofileRectangular, '')
            xlabel(app.BeamprofileRectangular, 'Angle [Deg]')
            ylabel(app.BeamprofileRectangular, 'Amplitude [dB]')
            app.BeamprofileRectangular.XLim = [-90 90];
            app.BeamprofileRectangular.YLim = [-42 2];
            app.BeamprofileRectangular.XTick = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.BeamprofileRectangular.YTick = [-60 -54 -48 -42 -36 -30 -24 -18 -12 -6 0 6];
            app.BeamprofileRectangular.YTickLabel = {'-60'; '-54'; '-48'; '-42'; '-36'; '-30'; '-24'; '-18'; '-12'; '-6'; '0'; '6'};
            app.BeamProfilefromaLinearTransducerArrayLabel = uilabel(app.UIFigureBeamProfile);
            app.BeamProfilefromaLinearTransducerArrayLabel.Text = 'Beam Profile from a Linear Transducer Array';
            app.ElementkerfumLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementkerfumLabel.Text = 'Element kerf [um] ';
            app.ElementkerfumEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.ElementkerfumEditField.Limits = [0 5000];
            app.ElementkerfumEditField.ValueDisplayFormat = '%4.0f';
            app.ElementkerfumEditField.ValueChangedFcn = createCallbackFcn(app, @ElementkerfumEditFieldValueChanged, true);
            app.ElementkerfumEditField.Value = 20;
            app.ElementwidthumLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementwidthumLabel.Text = 'Element width [um] ';
            app.ElementwidthumEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.ElementwidthumEditField.Limits = [1 5000];
            app.ElementwidthumEditField.ValueDisplayFormat = '%4.0f';
            app.ElementwidthumEditField.Editable = 'off';
            app.ElementwidthumEditField.Value = 280;
            app.SteeringAngleDegLabel = uilabel(app.UIFigureBeamProfile);
            app.SteeringAngleDegLabel.Text = 'Steering Angle [Deg] ';
            app.SteeringAngleDegSpinner = uispinner(app.UIFigureBeamProfile);
            app.SteeringAngleDegSpinner.Limits = [-90 90];
            app.SteeringAngleDegSpinner.ValueDisplayFormat = '%4.1f';
            app.SteeringAngleDegSpinner.ValueChangedFcn = createCallbackFcn(app, @SteeringAngleDegSpinnerValueChanged, true);
            app.Image = uiimage(app.UIFigureBeamProfile);
            app.Image.ImageSource = 'USN_logo_En_rgb.png';
            app.ElementDelaynsEditFieldLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementDelaynsEditFieldLabel.Text = 'Element Delay [ns] ';
            app.ElementDelaynsEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.ElementDelaynsEditField.Limits = [-100000 100000];
            app.ElementDelaynsEditField.ValueDisplayFormat = '%6.1f';
            app.ElementDelaynsEditField.Editable = 'off';
            app.ElementDelaynsEditField.Value = 1e-05;
            app.FarfieldprofilecalculatedfromtheFraunhoferapproximationLabel = uilabel(app.UIFigureBeamProfile);
            app.FarfieldprofilecalculatedfromtheFraunhoferapproximationLabel.Text = 'Far-field profile calculated from the Fraunhofer-approximation';
            app.FrequencyMHzSpinnerLabel = uilabel(app.UIFigureBeamProfile);
            app.FrequencyMHzSpinnerLabel.Text = 'Frequency [MHz] ';
            app.FrequencyMHzSpinner = uispinner(app.UIFigureBeamProfile);
            app.FrequencyMHzSpinner.Step = 0.1;
            app.FrequencyMHzSpinner.Limits = [0.01 100];
            app.FrequencyMHzSpinner.ValueDisplayFormat = '%5.2f';
            app.FrequencyMHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyMHzSpinnerValueChanged, true);
            app.FrequencyMHzSpinner.Value = 3;
            app.ScaledBSpinnerLabel = uilabel(app.UIFigureBeamProfile);
            app.ScaledBSpinnerLabel.Text = 'Scale [dB]';
            app.ScaledBSpinner = uispinner(app.UIFigureBeamProfile);
            app.ScaledBSpinner.LowerLimitInclusive = 'off';
            app.ScaledBSpinner.UpperLimitInclusive = 'off';
            app.ScaledBSpinner.Limits = [-200 0];
            app.ScaledBSpinner.ValueDisplayFormat = '%4.0f';
            app.ScaledBSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaledBSpinnerValueChanged, true);
            app.ScaledBSpinner.Value = -42;
            % Show the figure after all components are created
            app.UIFigureBeamProfile.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArrayBeamprofile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigureBeamProfile)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureBeamProfile)
        end
    end
end