classdef element_beamprofile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureBeamProfile            matlab.ui.Figure
        ElementwidthEditField          matlab.ui.control.NumericEditField
        ElementwidthmmEditFieldLabel   matlab.ui.control.Label
        ElementwidthSlider             matlab.ui.control.Slider
        ApodizationDropDown            matlab.ui.control.DropDown
        ApodizationLabel               matlab.ui.control.Label
        ScaleDegSpinner                matlab.ui.control.Spinner
        ScaleDegSpinnerLabel           matlab.ui.control.Label
        ScaledBSpinner                 matlab.ui.control.Spinner
        ScaledBSpinnerLabel            matlab.ui.control.Label
        FarfieldprofilecalculatedfromtheFraunhoferapproximationLabel  matlab.ui.control.Label
        FrequencyMHzSpinner            matlab.ui.control.Spinner
        FrequencyMHzSpinnerLabel       matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        BeamProfilefromaSingleElementRectangularTransducerLabel  matlab.ui.control.Label
        SpeedofsoundmsEditField        matlab.ui.control.NumericEditField
        SpeedofsoundmsLabel            matlab.ui.control.Label
        WidthWavelengthEditField       matlab.ui.control.NumericEditField
        WidthWavelengthEditFieldLabel  matlab.ui.control.Label
        WavelengthumEditField          matlab.ui.control.NumericEditField
        WavelengthumLabel              matlab.ui.control.Label
        BeamprofileRectangular         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Polarax % Description
        Polarvalue
        ArrayParameters
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app,Na)
            if nargin<3
                Na=3000;
            end
            [p,theta]=CalculateBeamprofile(app,Na);
            
            PdB=20*log10(abs(p));
            plot(app.BeamprofileRectangular,theta,PdB, 'b-') ;
            drawnow
            set(app.Polarvalue,'ThetaData',deg2rad(theta),'RData',PdB);
            results=0;
            
        end
        
        function [p,theta] = CalculateBeamprofile(app, Na)
            theta= linspace(-90,90,Na);
            w = app.ArrayParameters.w;
            lambda=app.ArrayParameters.lambda;
            wl=w/lambda;
            dx= w/Na;
            switch ( lower(app.ArrayParameters.apodization(1:4)) )
                case 'none'
                    p= sinc(wl*(sin(deg2rad(theta))));
% ----To be implemented
                % case 'hann'  
                %     kx= (2*pi)/lambda*sin(theta);
                %     W = window(@hann,Na);
                %     s = ones(Na,1);
                %     pc= fftshift(fft(W.*s,Na));
                %     p = abs(pc);     %------- Check! --------
                %     p = p/max(abs(p));
                %     [N,~]= size(p);
                %     kx = 2*pi*(-N+1:2:N)/(2*N)/dx;
                % 
                %     x0= kx*lambda*w;
                %     sin_theta= lambda/(2*pi)*kx;
                %     theta=asin(sin_theta);
                otherwise, error('Unknown apodization')
            end
        end
        
        function results = UpdateWavelength(app)
            f= app.ArrayParameters.f;
            c= app.ArrayParameters.c;
            lambda=c/f;
            app.ArrayParameters.lambda=lambda;
            app.WavelengthumEditField.Value=lambda*1e6;
            UpdatePitch(app);
            results=0;
        end
        
        function results = UpdatePitch(app)
            w = app.ArrayParameters.w;
            l = app.ArrayParameters.lambda;
            wl = w/l;
            app.ArrayParameters.wl=wl;
            
            app.ElementwidthSlider.Value = w*1e3;
            app.ElementwidthEditField.Value = w*1e3;
            app.WidthWavelengthEditField.Value = wl;
            results=0;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        %--- Initialize array parameters with values from GUI
        app.ArrayParameters.f = app.FrequencyMHzSpinner.Value*1e6;
        app.ArrayParameters.c = app.SpeedofsoundmsEditField.Value;
        app.ArrayParameters.w = app.ElementwidthSlider.Value*1e-3;
        app.ArrayParameters.apodization= app.ApodizationDropDown.Value;
        
        dBmin = app.ScaledBSpinner.Value;
        app.BeamprofileRectangular.YLim= [dBmin, 0];
        
        %--- Create polar axes and position it in pixels
        app.Polarax = polaraxes(app.UIFigureBeamProfile);
        app.Polarax.Units = 'pixels';
        app.Polarax.Position = [600 20 420 400];
        app.Polarax.ThetaLim =[-90 90];
        app.Polarax.RLim = [dBmin, 0];
        app.Polarax.RTick= -72:6:0;
        app.Polarax.RMinorTick = 'on';
        app.Polarax.RMinorGrid = 'off';
        app.Polarax.ThetaAxisUnits="degrees";
        app.Polarax.ThetaZeroLocation="bottom";
        app.Polarax.FontUnits="pixels";
        app.Polarax.FontSize = 12;
        app.Polarax.NextPlot ="Add";
        app.Polarvalue =polarplot(app.Polarax, 0,1);
        
        %--- Update calculated fields and plot results
        UpdateWavelength(app);
        CalculateAndPlot(app,0);
        drawnow
        end

        % Value changed function: FrequencyMHzSpinner
        function FrequencyMHzSpinnerValueChanged(app, event)
        app.ArrayParameters.f=app.FrequencyMHzSpinner.Value*1e6;
        UpdateWavelength(app);
        UpdatePitch(app);
        CalculateAndPlot(app,event);
        end

        % Value changed function: SpeedofsoundmsEditField
        function SpeedofsoundmsEditFieldValueChanged(app, event)
        app.ArrayParameters.c=app.SpeedofsoundmsEditField.Value;
        UpdateWavelength(app);
        UpdatePitch(app);
        CalculateAndPlot(app,event);
        end

        % Value changed function: ScaledBSpinner
        function ScaledBSpinnerValueChanged(app, event)
        Pmin = app.ScaledBSpinner.Value;
        ylim(app.BeamprofileRectangular,[Pmin,0]);
        rlim(app.Polarax,[Pmin,0])
        end

        % Value changed function: ScaleDegSpinner
        function ScaleDegSpinnerValueChanged(app, event)
        AngleMax = app.ScaleDegSpinner.Value;
        xlim(app.BeamprofileRectangular,AngleMax*[-1,1]);
        
        end

        % Value changed function: ApodizationDropDown
        function ApodizationDropDownValueChanged(app, event)
        app.ArrayParameters.apodization= app.ApodizationDropDown.Value;
        CalculateAndPlot(app,event);
        end

        % Value changed function: ElementwidthSlider
        function ElementwidthSliderValueChanged(app, event)
        value = app.ElementwidthSlider.Value*1e-3;
        app.ArrayParameters.w=value;
        UpdatePitch(app);
        CalculateAndPlot(app,event);
        end

        % Value changed function: ElementwidthEditField
        function ElementwidthEditFieldValueChanged(app, event)
        value = app.ElementwidthEditField.Value*1e-3;
        app.ArrayParameters.w=value;
        UpdatePitch(app);
        CalculateAndPlot(app,event);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureBeamProfile = uifigure('Visible', 'off');
            app.UIFigureBeamProfile.Name = 'Far-Field Element Beam Profile';
            app.BeamprofileRectangular = uiaxes(app.UIFigureBeamProfile);
            xlabel(app.BeamprofileRectangular, 'Angle [Deg]')
            ylabel(app.BeamprofileRectangular, 'Amplitude [dB]')
            app.BeamprofileRectangular.XLim = [-90 90];
            app.BeamprofileRectangular.YLim = [-42 2];
            app.BeamprofileRectangular.XTick = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.BeamprofileRectangular.YTick = [-60 -54 -48 -42 -36 -30 -24 -18 -12 -6 0 6 12];
            app.BeamprofileRectangular.YTickLabel = {'-60'; '-54'; '-48'; '-42'; '-36'; '-30'; '-24'; '-18'; '-12'; '-6'; '0'; '6'; '12'};
            app.WavelengthumLabel = uilabel(app.UIFigureBeamProfile);
            app.WavelengthumLabel.Text = 'Wavelength [um] ';
            app.WavelengthumEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.WavelengthumEditField.Limits = [1 1000000];
            app.WavelengthumEditField.ValueDisplayFormat = '%4.0f';
            app.WavelengthumEditField.Editable = 'off';
            app.WavelengthumEditField.Value = 300;
            app.WidthWavelengthEditFieldLabel = uilabel(app.UIFigureBeamProfile);
            app.WidthWavelengthEditFieldLabel.Text = 'Width / Wavelength ';
            app.WidthWavelengthEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.WidthWavelengthEditField.Limits = [0 Inf];
            app.WidthWavelengthEditField.ValueDisplayFormat = '%4.1f';
            app.WidthWavelengthEditField.Editable = 'off';
            app.WidthWavelengthEditField.Value = 0.7;
            app.SpeedofsoundmsLabel = uilabel(app.UIFigureBeamProfile);
            app.SpeedofsoundmsLabel.Text = 'Speed of sound [m/s] ';
            app.SpeedofsoundmsEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.SpeedofsoundmsEditField.Limits = [10 5000];
            app.SpeedofsoundmsEditField.ValueDisplayFormat = '%4.0f';
            app.SpeedofsoundmsEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedofsoundmsEditFieldValueChanged, true);
            app.SpeedofsoundmsEditField.Value = 1540;
            app.BeamProfilefromaSingleElementRectangularTransducerLabel = uilabel(app.UIFigureBeamProfile);
            app.BeamProfilefromaSingleElementRectangularTransducerLabel.Text = 'Beam Profile from a Single Element Rectangular Transducer';
            app.Image = uiimage(app.UIFigureBeamProfile);
            app.Image.ImageSource = 'USN_logo_En_rgb.png';
            app.FrequencyMHzSpinnerLabel = uilabel(app.UIFigureBeamProfile);
            app.FrequencyMHzSpinnerLabel.Text = 'Frequency [MHz] ';
            app.FrequencyMHzSpinner = uispinner(app.UIFigureBeamProfile);
            app.FrequencyMHzSpinner.Step = 0.1;
            app.FrequencyMHzSpinner.Limits = [0.05 20];
            app.FrequencyMHzSpinner.ValueDisplayFormat = '%4.1f';
            app.FrequencyMHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyMHzSpinnerValueChanged, true);
            app.FrequencyMHzSpinner.Value = 3;
            app.FarfieldprofilecalculatedfromtheFraunhoferapproximationLabel = uilabel(app.UIFigureBeamProfile);
            app.FarfieldprofilecalculatedfromtheFraunhoferapproximationLabel.Text = 'Far-field profile calculated from the Fraunhofer-approximation';
            app.ScaledBSpinnerLabel = uilabel(app.UIFigureBeamProfile);
            app.ScaledBSpinnerLabel.Text = 'Scale [dB]';
            app.ScaledBSpinner = uispinner(app.UIFigureBeamProfile);
            app.ScaledBSpinner.LowerLimitInclusive = 'off';
            app.ScaledBSpinner.UpperLimitInclusive = 'off';
            app.ScaledBSpinner.Limits = [-200 0];
            app.ScaledBSpinner.ValueDisplayFormat = '%4.0f';
            app.ScaledBSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaledBSpinnerValueChanged, true);
            app.ScaledBSpinner.Value = -42;
            app.ScaleDegSpinnerLabel = uilabel(app.UIFigureBeamProfile);
            app.ScaleDegSpinnerLabel.Text = 'Scale [Deg]';
            app.ScaleDegSpinner = uispinner(app.UIFigureBeamProfile);
            app.ScaleDegSpinner.LowerLimitInclusive = 'off';
            app.ScaleDegSpinner.UpperLimitInclusive = 'off';
            app.ScaleDegSpinner.Limits = [1 90.1];
            app.ScaleDegSpinner.RoundFractionalValues = 'on';
            app.ScaleDegSpinner.ValueDisplayFormat = '%4.0f';
            app.ScaleDegSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleDegSpinnerValueChanged, true);
            app.ScaleDegSpinner.Value = 90;
            app.ApodizationLabel = uilabel(app.UIFigureBeamProfile);
            app.ApodizationLabel.Text = 'Apodization ';
            app.ApodizationDropDown = uidropdown(app.UIFigureBeamProfile);
            app.ApodizationDropDown.Items = {'None'};
            app.ApodizationDropDown.ValueChangedFcn = createCallbackFcn(app, @ApodizationDropDownValueChanged, true);
            app.ApodizationDropDown.Value = 'None';
            app.ElementwidthSlider = uislider(app.UIFigureBeamProfile);
            app.ElementwidthSlider.Limits = [0 20];
            app.ElementwidthSlider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30];
            app.ElementwidthSlider.ValueChangedFcn = createCallbackFcn(app, @ElementwidthSliderValueChanged, true);
            app.ElementwidthSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8];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
            app.ElementwidthSlider.Value = 20;
            app.ElementwidthmmEditFieldLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementwidthmmEditFieldLabel.Text = 'Element width [mm] ';
            app.ElementwidthEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.ElementwidthEditField.ValueDisplayFormat = '%5.2f';
            app.ElementwidthEditField.ValueChangedFcn = createCallbackFcn(app, @ElementwidthEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigureBeamProfile.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = element_beamprofile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigureBeamProfile)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureBeamProfile)
        end
    end
end