classdef Sinewave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        xtAcoswtLabel          matlab.ui.control.Label
        AmplitudeASliderLabel  matlab.ui.control.Label
        AmplitudeASlider       matlab.ui.control.Slider
        FrequencyfSliderLabel  matlab.ui.control.Label
        FrequencyfSlider       matlab.ui.control.Slider
        PhaseSliderLabel       matlab.ui.control.Label
        PhaseSlider            matlab.ui.control.Slider
        SinewavedemoLabel      matlab.ui.control.Label
        DelaysEditFieldLabel   matlab.ui.control.Label
        DelaysEditField        matlab.ui.control.NumericEditField
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cospar % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            y=app.cospar.A*cos(2*pi*app.cospar.f* app.cospar.t + app.cospar.phi);
            plot(app.UIAxes, app.cospar.t, y);
            app.cospar.td =-app.cospar.phi/(2*pi*app.cospar.f);
            app.DelaysEditField.Value = app.cospar.td;
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            N=3000;
            app.cospar.t=linspace(0,1,N);
            app.cospar.A   = app.AmplitudeASlider.Value;
            app.cospar.f   = app.FrequencyfSlider.Value;
            app.cospar.phi = app.PhaseSlider.Value;
            CalculateAndPlot(app);
        end

        % Callback function: AmplitudeASlider, AmplitudeASlider
        function AmplitudeASliderValueChanging(app, event)
            app.cospar.A=event.Value;
            CalculateAndPlot(app)
        end

        % Callback function: FrequencyfSlider, FrequencyfSlider
        function FrequencyfSliderValueChanged(app, event)
            app.cospar.f=event.Value;
            CalculateAndPlot(app)
        end

        % Callback function: PhaseSlider, PhaseSlider
        function PhaseSliderValueChanging(app, event)
            app.cospar.phi=event.Value;
            CalculateAndPlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xtAcoswtLabel = uilabel(app.UIFigure);
            app.xtAcoswtLabel.Text = 'x(t)= A cos(wt+ø)';
            app.AmplitudeASliderLabel = uilabel(app.UIFigure);
            app.AmplitudeASliderLabel.Text = 'Amplitude A';
            app.AmplitudeASlider = uislider(app.UIFigure);
            app.AmplitudeASlider.Limits = [0 2];
            app.AmplitudeASlider.MajorTicks = [0 0.5 1 1.5 2];
            app.AmplitudeASlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeASliderValueChanging, true);
            app.AmplitudeASlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeASliderValueChanging, true);
            app.AmplitudeASlider.Value = 1;
            app.FrequencyfSliderLabel = uilabel(app.UIFigure);
            app.FrequencyfSliderLabel.Text = 'Frequency f';
            app.FrequencyfSlider = uislider(app.UIFigure);
            app.FrequencyfSlider.Limits = [0 10];
            app.FrequencyfSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyfSliderValueChanged, true);
            app.FrequencyfSlider.ValueChangingFcn = createCallbackFcn(app, @FrequencyfSliderValueChanged, true);
            app.FrequencyfSlider.Value = 2;
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase ø';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [-6.28318530717959 6.28318530717959];
            app.PhaseSlider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.SinewavedemoLabel = uilabel(app.UIFigure);
            app.SinewavedemoLabel.Text = 'Sine wave demo';
            app.DelaysEditFieldLabel = uilabel(app.UIFigure);
            app.DelaysEditFieldLabel.Text = 'Delay [s]';
            app.DelaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelaysEditField.Limits = [-6.28318530717959 6.28318530717959];
            app.DelaysEditField.ValueDisplayFormat = '%5.3f';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'y(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-2 2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sinewave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end