classdef fir_response_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FilterCoefficientsLabel        matlab.ui.control.Label
        McClellanetalDSPFirstCh6Label  matlab.ui.control.Label
        FrequencyResponseofFIRFilterLabel  matlab.ui.control.Label
        bEditField                     matlab.ui.control.EditField
        bkLabel                        matlab.ui.control.Label
        phase                          matlab.ui.control.UIAxes
        magnitude                      matlab.ui.control.UIAxes
        coefficients                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        num % Description
    end
       
    methods (Access = private)
        
        function results = calculateAndPlot(app)
            b = app.num.b(:);      % FIR filter coefficients read from GUI as columne vector
            M = length(b)-1;
            n = 0:M;
            
            % Impulse response
            nNegative = 2;                % Impulse response plot before zero
            nExtend   = 6;                % Impulse response plot after M
            nh =-nNegative: M +nExtend;   % n-vector for plotting h
            h  = [ zeros( nNegative,1 ); b; zeros( nExtend,1 ) ];   % Impulse response as filter coefficients with appended zeros

            stem( app.coefficients, nh, h, 'filled' )
            xlim( app.coefficients, [ min(nh)-0.25  max(nh)+0.25 ] );
            ylim( app.coefficients, 1.2*max( abs(h) ) *[ -1 1 ] )
            ax = axis( app.coefficients );
            patch( app.coefficients, [ax(1) 0 0 ax(1)], [ ax(3) ax(3) ax(4) ax(4)],  [0 0 0], 'Edgecolor', 'none', 'FaceAlpha', 0.2 );  % Gray background for n<0
            grid( app.coefficients, "on")

            % Frequency response
            w = linspace ( -pi , pi , 3000 )';  % Norrmalised frequency vector
            Hk= zeros(length(w),M+1);           % Frequency response by doing the sum directly
            for k=n
                Hk(:,k+1)= b(k+1)*exp(-1i*w*k);                
            end
            H = sum(Hk,2);            
            FrequencyAxisText = 'Normalized frequency $\hat{\omega}$';
            
            plot( app.magnitude, w, abs(H) )        % Magnitude
            ylabel( app.magnitude, 'Magnitude |H|', Interpreter= 'latex' )
            piaxis( app.magnitude, 'x', FrequencyAxisText , 4 )

            plot( app.phase, w, angle(H) )          % Phase
            piaxis( app.phase, 'x', FrequencyAxisText , 4 )
            piaxis( app.phase, 'y', 'Phase [rad]', 4 )
            results=0;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bEditFieldValueChanged(app, 0)
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.num.b = str2num(value);
            %app.num.b=sscanf(value,'%g,');
            %app.FunctionEditField.Value='';
            calculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.coefficients = uiaxes(app.UIFigure);
            title(app.coefficients, 'Impulse response')
            xlabel(app.coefficients, 'n')
            ylabel(app.coefficients, 'h[n]')
            app.coefficients.YLim = [0 1];
            app.magnitude = uiaxes(app.UIFigure);
            title(app.magnitude, 'Frequency Response')
            xlabel(app.magnitude, 'Normalised frequency')
            ylabel(app.magnitude, 'Magnitude |H(w)|')
            app.magnitude.XLim = [-3.14159265358979 3.14159265358979];
            app.phase = uiaxes(app.UIFigure);
            xlabel(app.phase, 'Normalised frequency')
            ylabel(app.phase, 'Phase angle(H)/\pi')
            app.phase.XLim = [-3.14159265358979 3.14159265358979];
            app.phase.YLim = [-3.14159265358979 3.14159265358979];
            app.phase.ZLim = [-1 1];
            app.bkLabel = uilabel(app.UIFigure);
            app.bkLabel.Text = 'b ';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = '1,2,1';
            app.FrequencyResponseofFIRFilterLabel = uilabel(app.UIFigure);
            app.FrequencyResponseofFIRFilterLabel.Text = 'Frequency Response of FIR-Filter';
            app.McClellanetalDSPFirstCh6Label = uilabel(app.UIFigure);
            app.McClellanetalDSPFirstCh6Label.Text = 'McClellan et al., DSP First, Ch. 6';
            app.FilterCoefficientsLabel = uilabel(app.UIFigure);
            app.FilterCoefficientsLabel.Text = 'Filter Coefficients';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fir_response_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end