classdef alias_frequencies_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Image                  matlab.ui.control.Image
        fm_on                  matlab.ui.control.CheckBox
        fl_on                  matlab.ui.control.CheckBox
        f0_on                  matlab.ui.control.CheckBox
        McClellanetalDSPFirst2ndedCh4SamplingandAliasingLabel  matlab.ui.control.Label
        f0mfsEditField         matlab.ui.control.NumericEditField
        f0mfsEditFieldLabel    matlab.ui.control.Label
        f0lfsEditField         matlab.ui.control.NumericEditField
        f0lfsEditFieldLabel    matlab.ui.control.Label
        NegativealiasesLabel   matlab.ui.control.Label
        PositivealiasesLabel   matlab.ui.control.Label
        mSpinner               matlab.ui.control.Spinner
        mSpinnerLabel          matlab.ui.control.Label
        lSpinner               matlab.ui.control.Spinner
        lSpinnerLabel          matlab.ui.control.Label
        AliasFrequenciesLabel  matlab.ui.control.Label
        fsSpinner              matlab.ui.control.Spinner
        fsSpinnerLabel         matlab.ui.control.Label
        f0Spinner              matlab.ui.control.Spinner
        f0SpinnerLabel         matlab.ui.control.Label
        SignalGraph            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        CurvePar % Description
    end
    
    methods (Access = private)
        
        function results = CalculateAndPlot(app)
 
            fs= app.CurvePar.fs;
            
            f = app.CurvePar.f;
            fl= app.CurvePar.f + app.CurvePar.l*app.CurvePar.fs;
            fm=-app.CurvePar.f + app.CurvePar.m*app.CurvePar.fs;
            
            app.f0lfsEditField.Value= fl;
            app.f0mfsEditField.Value= fm;
            
            ts = ( 0: 1/fs :app.CurvePar.N );
            ti = ( 0: 1/(100*fs): app.CurvePar.N );
            
            ss =cos( 2*pi*f *ts );
            s0 =cos( 2*pi*f *ti );
            sl =cos( 2*pi*fl*ti );
            sm =cos( 2*pi*fm*ti );
            
            app.CurvePar.splot= stem( app.SignalGraph,ts,ss, "filled", Color=app.CurvePar.color(1,:) );
            hold (app.SignalGraph,"on")
            app.CurvePar.lplot= plot(app.SignalGraph,ti,sl, Color=app.CurvePar.color(2,:) );
            app.CurvePar.mplot= plot(app.SignalGraph,ti,sm, Color=app.CurvePar.color(3,:) );
            app.CurvePar.iplot= plot(app.SignalGraph,ti,s0, Color=app.CurvePar.color(1,:) );
            hold (app.SignalGraph,"off")
            
            ShowPlot(app,app.CurvePar.iplot,app.f0_on.Value);
            ShowPlot(app,app.CurvePar.lplot,app.fl_on.Value);
            ShowPlot(app,app.CurvePar.mplot,app.fm_on.Value);
            
            results=0;
        end
        
        function results = ShowPlot(app,ax,on)
            if(on)
                show='on';
            else  
                show='off';
            end
            set(ax,'Visible',show)
            results=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurvePar.N  = 3000;
            
            app.CurvePar.f  = app.f0Spinner.Value;
            app.CurvePar.l  = app.lSpinner.Value;
            app.CurvePar.m  = app.mSpinner.Value;
            app.CurvePar.fs = app.fsSpinner.Value;    
            app.CurvePar.color = colororder;
            
            CalculateAndPlot(app);
        end

        % Value changed function: f0Spinner
        function f0SpinnerValueChanged(app, event)
            app.CurvePar.f = app.f0Spinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: fsSpinner
        function fsSpinnerValueChanged(app, event)
            app.CurvePar.fs = app.fsSpinner.Value;
            CalculateAndPlot(app);
            
        end

        % Value changed function: lSpinner
        function lSpinnerValueChanged(app, event)
            app.CurvePar.l = app.lSpinner.Value;
            CalculateAndPlot(app);
            
        end

        % Value changed function: mSpinner
        function mSpinnerValueChanged(app, event)
            app.CurvePar.m = app.mSpinner.Value;
            CalculateAndPlot(app);
        end

        % Value changed function: f0_on
        function f0_onValueChanged(app, event)
            ShowPlot(app,app.CurvePar.iplot,app.f0_on.Value);
        end

        % Value changed function: fl_on
        function fl_onValueChanged(app, event)
            ShowPlot(app,app.CurvePar.lplot,app.fl_on.Value);
        end

        % Value changed function: fm_on
        function fm_onValueChanged(app, event)
            ShowPlot(app,app.CurvePar.mplot,app.fm_on.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SignalGraph = uiaxes(app.UIFigure);
            xlabel(app.SignalGraph, 'Time [s]')
            ylabel(app.SignalGraph, 'Amplitude [s]')
            app.SignalGraph.XLim = [0 10];
            app.SignalGraph.YLim = [-1.2 1.2];
            app.f0SpinnerLabel = uilabel(app.UIFigure);
            app.f0SpinnerLabel.Text = 'f0 ';
            app.f0Spinner = uispinner(app.UIFigure);
            app.f0Spinner.Step = 0.1;
            app.f0Spinner.Limits = [0 10];
            app.f0Spinner.ValueDisplayFormat = '%11.2g';
            app.f0Spinner.ValueChangedFcn = createCallbackFcn(app, @f0SpinnerValueChanged, true);
            app.f0Spinner.Value = 0.2;
            app.fsSpinnerLabel = uilabel(app.UIFigure);
            app.fsSpinnerLabel.Text = 'fs ';
            app.fsSpinner = uispinner(app.UIFigure);
            app.fsSpinner.Step = 0.1;
            app.fsSpinner.Limits = [0 100];
            app.fsSpinner.ValueDisplayFormat = '%11.2g';
            app.fsSpinner.ValueChangedFcn = createCallbackFcn(app, @fsSpinnerValueChanged, true);
            app.fsSpinner.Value = 1;
            app.AliasFrequenciesLabel = uilabel(app.UIFigure);
            app.AliasFrequenciesLabel.Text = 'Alias Frequencies';
            app.lSpinnerLabel = uilabel(app.UIFigure);
            app.lSpinnerLabel.Text = 'l ';
            app.lSpinner = uispinner(app.UIFigure);
            app.lSpinner.RoundFractionalValues = 'on';
            app.lSpinner.ValueDisplayFormat = '%d';
            app.lSpinner.ValueChangedFcn = createCallbackFcn(app, @lSpinnerValueChanged, true);
            app.mSpinnerLabel = uilabel(app.UIFigure);
            app.mSpinnerLabel.Text = 'm ';
            app.mSpinner = uispinner(app.UIFigure);
            app.mSpinner.RoundFractionalValues = 'on';
            app.mSpinner.ValueDisplayFormat = '%d';
            app.mSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.PositivealiasesLabel = uilabel(app.UIFigure);
            app.PositivealiasesLabel.Text = 'Positive aliases';
            app.NegativealiasesLabel = uilabel(app.UIFigure);
            app.NegativealiasesLabel.Text = 'Negative aliases';
            app.f0lfsEditFieldLabel = uilabel(app.UIFigure);
            app.f0lfsEditFieldLabel.Text = 'f0 + l fs ';
            app.f0lfsEditField = uieditfield(app.UIFigure, 'numeric');
            app.f0mfsEditFieldLabel = uilabel(app.UIFigure);
            app.f0mfsEditFieldLabel.Text = '-f0 + m fs ';
            app.f0mfsEditField = uieditfield(app.UIFigure, 'numeric');
            app.McClellanetalDSPFirst2ndedCh4SamplingandAliasingLabel = uilabel(app.UIFigure);
            app.McClellanetalDSPFirst2ndedCh4SamplingandAliasingLabel.Text = {'McClellan et al.,'; 'DSP First, 2nd ed.'; 'Ch. 4: Sampling and Aliasing'};
            app.f0_on = uicheckbox(app.UIFigure);
            app.f0_on.ValueChangedFcn = createCallbackFcn(app, @f0_onValueChanged, true);
            app.f0_on.Text = '';
            app.f0_on.Value = true;
            app.fl_on = uicheckbox(app.UIFigure);
            app.fl_on.ValueChangedFcn = createCallbackFcn(app, @fl_onValueChanged, true);
            app.fl_on.Text = '';
            app.fm_on = uicheckbox(app.UIFigure);
            app.fm_on.ValueChangedFcn = createCallbackFcn(app, @fm_onValueChanged, true);
            app.fm_on.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'USN_logo_En_rgb.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = alias_frequencies_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end