classdef convolution_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        McClellanetalDSPFirstCh5Label  matlab.ui.control.Label
        ConvolutionExampleLabel        matlab.ui.control.Label
        kSpinner                       matlab.ui.control.Spinner
        HiglightedsampleLabel          matlab.ui.control.Label
        hEditField                     matlab.ui.control.EditField
        hLabel                         matlab.ui.control.Label
        xEditField                     matlab.ui.control.EditField
        xLabel                         matlab.ui.control.Label
        ResultAxes                     matlab.ui.control.UIAxes
        ConvolutionAxes                matlab.ui.control.UIAxes
        ImpulseAxes                    matlab.ui.control.UIAxes
        SignalAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal % Description
    end
    
    methods (Access = private)
        
        function results = calculateAndPlot(app)
            n     = 0 : app.signal.nTime-1;       % Discrete time vector, signal
            nh    = 0 : length( app.signal.h )-1; % Discrete time vector, impulse response
            nlims = [ -4 app.signal.nTime+5 ];    % Plot limits 
            
            app.signal.x( app.signal.nTime ) = 0;

            y = conv( app.signal.x, app.signal.h );
            ny= 0 : length(y)-1;

            nHreverse= app.signal.m - ( 0: max(nh) );

            % Plot signals
            plotSingleGraph( app, app.ImpulseAxes,     nh,        app.signal.h, app.signal.color{2}, nlims  )
            plotSingleGraph( app, app.SignalAxes,      n,         app.signal.x, app.signal.color{1}, nlims  )
            plotSingleGraph( app, app.ConvolutionAxes, nHreverse, app.signal.h ,app.signal.color{2}, nlims  )            
            plotSingleGraph( app, app.ResultAxes,      ny,        y,            app.signal.color{4}, nlims  )

            % Define and draw markers
            nStartmarker = app.signal.m*[1 1];
            nEndmarker   = nStartmarker - max(nh);
            yLimits      = 10*[-1 1];
            markerColor = app.signal.color{2};
            markerLine  = ':';

            hold(app.ResultAxes,      "on" );   
            hold(app.SignalAxes,      "on" );               
            hold(app.ConvolutionAxes, "on" );
           
            plot( app.ResultAxes,      nStartmarker, yLimits, 'color', markerColor, LineStyle= markerLine );
            plot( app.SignalAxes,      nStartmarker, yLimits, 'color', markerColor, LineStyle= markerLine )
            plot( app.SignalAxes,      nEndmarker,   yLimits, 'color', markerColor, LineStyle= markerLine )
            plot( app.ConvolutionAxes, nStartmarker, yLimits, 'color', markerColor, LineStyle= markerLine )
            plot( app.ConvolutionAxes, nEndmarker,   yLimits, 'color', markerColor, LineStyle= markerLine )

            hold( app.ResultAxes,      "off" );   
            hold( app.SignalAxes,      "off" );   
            hold( app.ConvolutionAxes, "off" );

            % Scale axes
            xlim(app.ResultAxes, nlims);
            scaleYAixs(app, app.ResultAxes, [min(y) max(y)] );

        end

        function plotSingleGraph( app, ax, n, x, col, nlim )
            [ M, N ]= size(x);
            for k=1:M
                stem ( ax, n(k,:), x(k,:), "filled", "Color", col );
                hold( ax, 'on' )
            end
            hold( ax, 'off' );
            xlim( ax, nlim );
            grid( ax, "off" );
            patch( ax, [-10 0 0 -10], [-10 -10 10 10], 'k', 'Edgecolor', 'none', 'FaceAlpha', 0.2 );
            scaleYAixs( app, ax , [min(x) max(x) ]  );
        end

        
        function results = scaleYAixs(app, ax, ylims)
            ymin= min( [min(ylims) 0 ] ) -0.5;
            ymax= max( [max(ylims) 0 ] ) +0.5;
            
            ylim(ax, [ymin ymax] );
            results=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.signal.x = str2num( app.xEditField.Value ); % Signal
            app.signal.h = str2num( app.hEditField.Value ); % Impulse response
            app.signal.m = app.kSpinner.Value;              % Index to highlight
            app.signal.nTime = 20;

            c = colororder;
            app.signal.color= mat2cell( c, ones( length(c), 1 ), 3 );  % Colors as cell array
            calculateAndPlot(app);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.signal.x = str2num(value);
            calculateAndPlot(app);
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            value = app.hEditField.Value;
            app.signal.h = str2num( value );
            calculateAndPlot(app);
        end

        % Value changed function: kSpinner
        function mSpinnerValueChanged(app, event)
            app.signal.m= app.kSpinner.Value;
            app.signal.m= round(app.signal.m);
            calculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignalAxes = uiaxes(app.UIFigure);
            xlabel(app.SignalAxes, 'n')
            ylabel(app.SignalAxes, 'x[n]')
            zlabel(app.SignalAxes, 'Z')
            app.ImpulseAxes = uiaxes(app.UIFigure);
            xlabel(app.ImpulseAxes, 'n')
            ylabel(app.ImpulseAxes, 'h[n]')
            zlabel(app.ImpulseAxes, 'Z')
            app.ConvolutionAxes = uiaxes(app.UIFigure);
            xlabel(app.ConvolutionAxes, 'n')
            ylabel(app.ConvolutionAxes, 'h[k-n]')
            zlabel(app.ConvolutionAxes, 'Z')
            app.ResultAxes = uiaxes(app.UIFigure);
            xlabel(app.ResultAxes, 'n')
            ylabel(app.ResultAxes, 'x[n]*h[n]')
            zlabel(app.ResultAxes, 'Z')
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x ';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = '1, 1, 1, 1,1';
            app.hLabel = uilabel(app.UIFigure);
            app.hLabel.Text = 'h ';
            app.hEditField = uieditfield(app.UIFigure, 'text');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.hEditField.Value = '1,1,1';
            app.HiglightedsampleLabel = uilabel(app.UIFigure);
            app.HiglightedsampleLabel.Text = 'k';
            app.kSpinner = uispinner(app.UIFigure);
            app.kSpinner.Limits = [-3 30];
            app.kSpinner.RoundFractionalValues = 'on';
            app.kSpinner.ValueDisplayFormat = '%d';
            app.kSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.ConvolutionExampleLabel = uilabel(app.UIFigure);
            app.ConvolutionExampleLabel.Text = 'Convolution Example';
            app.McClellanetalDSPFirstCh5Label = uilabel(app.UIFigure);
            app.McClellanetalDSPFirstCh5Label.Text = 'McClellan et al., DSP First, Ch. 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convolution_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end