classdef fir_bandpass_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIRsimplebandpassfilterUIFigure  matlab.ui.Figure
        w0pi2k0LEditField               matlab.ui.control.NumericEditField
        w0pi2k0LEditFieldLabel          matlab.ui.control.Label
        k0Spinner                       matlab.ui.control.Spinner
        k0Label                         matlab.ui.control.Label
        LSpinner                        matlab.ui.control.Spinner
        LSpinnerLabel                   matlab.ui.control.Label
        FilterDefinitionLabel           matlab.ui.control.Label
        McClellanetalDSPFirstCh97Label  matlab.ui.control.Label
        SimpleBandpassFIRfilterLabel    matlab.ui.control.Label
        PhasePlot                       matlab.ui.control.UIAxes
        ImpulseresponsePlot             matlab.ui.control.UIAxes
        MagnitudePlot                   matlab.ui.control.UIAxes
        pzPlot                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filterdata % Description
    end
    
    methods (Access = private)
        
        function results = CalculateandPlot(app)
            % Find filter coefficients
            w = app.filterdata.w;
            N = app.filterdata.N;
            w0= app.filterdata.k0*pi/N;
            app.w0pi2k0LEditField.Value= w0 /pi;
            
            k = 0:N-1;
            km= 1/2*app.filterdata.N;
            b = cos( w0*(k-km) );       % Filter coefficients
            
            H= freqz(b,1,w);
            A= max(abs(H));
            H= H/A;                % Normalise to amplitude 1;
            b= b/A;
            k= find(abs(H)<max(H)*1e-6); % Replace H-values close to zero to avoid roundoff-error
            H(k)=zeros(size(k));
                        
            b0=1/N*ones(size(b));  % Reference low-pass filter of same order
            
            % Pole-zero plot
            cla(app.pzPlot)
            
            [hz,hp,ht]=zplane(b0,1, app.pzPlot); % Low-pass zeros as weak background
            delete(ht);                          % Keep only zeros in background
            delete(hp);
            set(hz, 'color', 0.80*[1 1 1 ] );
            [hz,hp,ht]=zplane(b,1,app.pzPlot);   % Bandpass pole-zero
            set(ht(1,1), 'color', 0.30*[1 1 1]); % Colour background lines
            axis(app.pzPlot, 1.2*[-1 1 -1 1])
            
            % Frequency response
            plot( app.MagnitudePlot, w,abs(H) )
            piaxis( app.MagnitudePlot,'x','Normalized frequency $\hat\omega$',3 )
            ylabel ( app.MagnitudePlot, 'Magnitude |H|', Interpreter='latex')
            ylim( app.MagnitudePlot, [0, 1.1] );
            
            plot( app.PhasePlot, w,angle(H) )
            piaxis( app.PhasePlot,'x','Normalized frequency $\hat\omega$',3 )
            piaxis( app.PhasePlot,'y','Phase',2 )
            
            % Impulse response 
            N = max(length(b)+2, 15);
            nz= 2;
            n = (-nz:N);    % Plot a few zero-points before and after the filter length
            x = zeros(size(n));
            x(nz+1)=1;
            h = filter(b,1,x);
            
            stem(app.ImpulseresponsePlot, n,h,'filled')

            ymax = 1.2*max( abs(h) );
            xlim(app.ImpulseresponsePlot, [min(n) max(n)])
            ylim(app.ImpulseresponsePlot, ymax*[-1 1] )

            hold( app.ImpulseresponsePlot, "on")
            patch(app.ImpulseresponsePlot, [ min(n) 0 0 min(n) ], [ -ymax -ymax ymax ymax  ], 'k', 'FaceAlpha', 0.1, 'EdgeColor', 'none' )
            hold( app.ImpulseresponsePlot, "off")
            
            results=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filterdata.w  = linspace( -pi, pi, 2001 );     % Normalized frequency vector
            app.filterdata.z  = exp( 1i*app.filterdata.w );    % Unit circle
            app.filterdata.k0 = app.k0Spinner.Value;
            app.filterdata.N = app.LSpinner.Value;             % Filter length
            
            app.k0Label.Text = sprintf('k%c ',char(8320) );
            app.w0pi2k0LEditFieldLabel.Text = sprintf('%c%c/%c =k%c/L ', char(969), char(8320), char(960), char(8320) );
            
            CalculateandPlot(app);
        end

        % Value changed function: k0Spinner
        function k0SpinnerValueChanged(app, event)
            app.filterdata.k0 = app.k0Spinner.Value;
            CalculateandPlot(app);
        end

        % Value changed function: LSpinner
        function LSpinnerValueChanged(app, event)
            app.filterdata.N = app.LSpinner.Value;
            CalculateandPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIRsimplebandpassfilterUIFigure = uifigure('Visible', 'off');
            app.FIRsimplebandpassfilterUIFigure.Name = 'FIR simple bandpass filter';
            app.pzPlot = uiaxes(app.FIRsimplebandpassfilterUIFigure);
            xlabel(app.pzPlot, 'Real Part')
            ylabel(app.pzPlot, 'Imaginary PardY')
            zlabel(app.pzPlot, 'Z')
            app.pzPlot.XLim = [-2 2];
            app.pzPlot.YLim = [-2 2];
            app.MagnitudePlot = uiaxes(app.FIRsimplebandpassfilterUIFigure);
            xlabel(app.MagnitudePlot, 'Normalized Frequency')
            ylabel(app.MagnitudePlot, 'Magnitude |H|')
            zlabel(app.MagnitudePlot, 'Z')
            app.MagnitudePlot.YLim = [-1 1];
            app.ImpulseresponsePlot = uiaxes(app.FIRsimplebandpassfilterUIFigure);
            xlabel(app.ImpulseresponsePlot, 'n')
            ylabel(app.ImpulseresponsePlot, 'h[n]')
            zlabel(app.ImpulseresponsePlot, 'Z')
            app.ImpulseresponsePlot.XLim = [-1 1];
            app.ImpulseresponsePlot.YLim = [-1 1];
            app.PhasePlot = uiaxes(app.FIRsimplebandpassfilterUIFigure);
            xlabel(app.PhasePlot, 'Normalized Frequency')
            ylabel(app.PhasePlot, 'Phase')
            zlabel(app.PhasePlot, 'Z')
            app.PhasePlot.XLim = [-1 1];
            app.PhasePlot.YLim = [-1 1];
            app.SimpleBandpassFIRfilterLabel = uilabel(app.FIRsimplebandpassfilterUIFigure);
            app.SimpleBandpassFIRfilterLabel.Text = 'Simple Bandpass FIR filter';
            app.McClellanetalDSPFirstCh97Label = uilabel(app.FIRsimplebandpassfilterUIFigure);
            app.McClellanetalDSPFirstCh97Label.Text = 'McClellan et al., DSP First, Ch. 9-7';
            app.FilterDefinitionLabel = uilabel(app.FIRsimplebandpassfilterUIFigure);
            app.FilterDefinitionLabel.Text = 'Filter Definition';
            app.LSpinnerLabel = uilabel(app.FIRsimplebandpassfilterUIFigure);
            app.LSpinnerLabel.Text = 'L';
            app.LSpinner = uispinner(app.FIRsimplebandpassfilterUIFigure);
            app.LSpinner.Limits = [1 100];
            app.LSpinner.RoundFractionalValues = 'on';
            app.LSpinner.ValueDisplayFormat = '%d';
            app.LSpinner.ValueChangedFcn = createCallbackFcn(app, @LSpinnerValueChanged, true);
            app.LSpinner.Value = 15;
            app.k0Label = uilabel(app.FIRsimplebandpassfilterUIFigure);
            app.k0Label.Text = 'k0 ';
            app.k0Spinner = uispinner(app.FIRsimplebandpassfilterUIFigure);
            app.k0Spinner.Step = 2;
            app.k0Spinner.Limits = [0 Inf];
            app.k0Spinner.ValueDisplayFormat = '%d';
            app.k0Spinner.ValueChangedFcn = createCallbackFcn(app, @k0SpinnerValueChanged, true);
            app.k0Spinner.Value = 4;
            app.w0pi2k0LEditFieldLabel = uilabel(app.FIRsimplebandpassfilterUIFigure);
            app.w0pi2k0LEditFieldLabel.Text = 'w0/pi =2k0/L';
            app.w0pi2k0LEditField = uieditfield(app.FIRsimplebandpassfilterUIFigure, 'numeric');
            % Show the figure after all components are created
            app.FIRsimplebandpassfilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fir_bandpass_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIRsimplebandpassfilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIRsimplebandpassfilterUIFigure)
        end
    end
end