classdef dirichlet_kernel_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Image           matlab.ui.control.Image
        referenceLabel  matlab.ui.control.Label
        headerLabel     matlab.ui.control.Label
        LSpinner        matlab.ui.control.Spinner
        LLabel          matlab.ui.control.Label
        dBscaled        matlab.ui.control.UIAxes
        Amplitude       matlab.ui.control.UIAxes
        FullRange       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
    end
    
    methods (Access = private)

        % Calculate results and call function to plot graphs
        function results = CalculateAndPlot(app)
            
            app.data.D  = sin( 1/2*app.data.w*app.data.L )./ ( app.data.L *sin( 1/2*app.data.w ) );
            app.data.DI = app.data.D( app.data.kI );
            
            plotD(app, app.FullRange, app.data.D,                [ -1 1] );
            plotD(app, app.Amplitude, abs(app.data.D),           [  0 1] );
            plotD(app, app.dBscaled,  20*log10(abs(app.data.D)), [-30 0] );

            results=0;
        end
        
        % Plot graph, according to input data, standardised format
        function results = plotD( app, Daxes, D, Drange )

            plot( Daxes, app.data.w/pi, D, '-', 'Color', app.data.color{1} )

            wrange = [ min( app.data.w ), max( app.data.w ) ] /pi;
            axis( Daxes, [ wrange, Drange] );

            xpatch1 = [ wrange(1) -1,        -1        wrange(1) ];
            xpatch2 = [  1        wrange(2) wrange(2)   1        ];           
            ypatch  = [ Drange(1)*[ 1 1 ]  Drange(2)*[ 1 1 ] ];
            patch( Daxes, xpatch1, ypatch, app.data.color{2}, 'Edgecolor', 'none', 'FaceAlpha', 0.2 );
            patch( Daxes, xpatch2, ypatch, app.data.color{2}, 'Edgecolor', 'none', 'FaceAlpha', 0.1 );            

            results=0;   
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data.w  = linspace( -2.2*pi, 4.2*pi, 1000 );    % Frequency vector extended beyond unique range
            app.data.kI = abs(app.data.w)<=pi;                  % Indices of frequency vector inside unique range
            app.data.wI = app.data.w(app.data.kI);
            app.data.L = app.LSpinner.Value;                    % Function order
            
            c = colororder;
            app.data.color= mat2cell( c, ones( length(c), 1 ), 3 );  % Colors as cell array
            CalculateAndPlot(app);
        end

        % Value changed function: LSpinner
        function LSpinnerValueChanged(app, event)
            app.data.L = app.LSpinner.Value;
            CalculateAndPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FullRange = uiaxes(app.UIFigure);
            title(app.FullRange, 'Linear scale - Extended beyond unique range')
            xlabel(app.FullRange, '\omega / \pi')
            ylabel(app.FullRange, '|D_{L}|')
            zlabel(app.FullRange, 'Z')
            app.FullRange.YLim = [-1 1];
            app.Amplitude = uiaxes(app.UIFigure);
            title(app.Amplitude, 'Amplitude')
            xlabel(app.Amplitude, '\omega / \pi')
            ylabel(app.Amplitude, '|D_{L}|')
            zlabel(app.Amplitude, 'Z')
            app.Amplitude.XLim = [-1 1];
            app.Amplitude.YLim = [0 1];
            app.dBscaled = uiaxes(app.UIFigure);
            title(app.dBscaled, 'Amplitude in dB')
            xlabel(app.dBscaled, '\omega / \pi')
            ylabel(app.dBscaled, '|D_{L}|  [dB]')
            zlabel(app.dBscaled, 'Z')
            app.dBscaled.XLim = [-1 1];
            app.dBscaled.YLim = [-30 0];
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L ';
            app.LSpinner = uispinner(app.UIFigure);
            app.LSpinner.Limits = [0 1000];
            app.LSpinner.RoundFractionalValues = 'on';
            app.LSpinner.ValueDisplayFormat = '%4d';
            app.LSpinner.ValueChangedFcn = createCallbackFcn(app, @LSpinnerValueChanged, true);
            app.LSpinner.Value = 7;
            app.headerLabel = uilabel(app.UIFigure);
            app.headerLabel.Text = 'Dirichlet-kernel';
            app.referenceLabel = uilabel(app.UIFigure);
            app.referenceLabel.Text = {'McClellan et al., DSP First, Ch. 6'; 'Amplitude normalized to 1'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'USN_logo_En_rgb.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dirichlet_kernel_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end