classdef array_beamprofile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureBeamProfile           matlab.ui.Figure
        dbSpinner                     matlab.ui.control.Spinner
        ScaledBSpinnerLabel           matlab.ui.control.Label
        frequencySpinner              matlab.ui.control.Spinner
        FrequencyMHzSpinnerLabel      matlab.ui.control.Label
        subtitleLabel                 matlab.ui.control.Label
        elementDelayEditField         matlab.ui.control.NumericEditField
        ElementDelaynsEditFieldLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        steeringAngleSpinner          matlab.ui.control.Spinner
        SteeringAngleDegLabel         matlab.ui.control.Label
        elementWidthEditField         matlab.ui.control.NumericEditField
        ElementwidthumLabel           matlab.ui.control.Label
        elementKerfEditField          matlab.ui.control.NumericEditField
        ElementkerfumLabel            matlab.ui.control.Label
        titleLabel                    matlab.ui.control.Label
        steeringAngleSlider           matlab.ui.control.Slider
        soundSpeedEditField           matlab.ui.control.NumericEditField
        SpeedofsoundmsLabel           matlab.ui.control.Label
        elementShapeDropDown          matlab.ui.control.DropDown
        ElementshapeDropDownLabel     matlab.ui.control.Label
        pitchWavelengthEditField      matlab.ui.control.NumericEditField
        PitchWavelengthLabel          matlab.ui.control.Label
        wavelengthEditField           matlab.ui.control.NumericEditField
        WavelengthumLabel             matlab.ui.control.Label
        pitchEditField                matlab.ui.control.NumericEditField
        ElementpitchumLabel           matlab.ui.control.Label
        nElementsEditField            matlab.ui.control.NumericEditField
        NoofelementsLabel             matlab.ui.control.Label
        halfWavelengthButton          matlab.ui.control.Button
        wavelengthButton              matlab.ui.control.Button
        BeamprofileRectangular        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        polarAxis  % Description
        polarValue
        array
        color
    end
    
    methods (Access = private)
        
        function results = plotResults(app, nPoints)
            if nargin<3
                nPoints=1000;
            end
            [pArray, pAperture, theta] = calculateBeamprofile(app, nPoints);

            plot(app.BeamprofileRectangular, ...
                  theta, db(app, pArray), "Color", app.color(1,:) );
            hold(app.BeamprofileRectangular, "ON")
           
            plot(app.BeamprofileRectangular, ...
                  theta, db(app, pAperture), "Color", 0.25*[1 1 1]);
            hold(app.BeamprofileRectangular, "OFF")
            drawnow
            
            set(app.polarValue, ...
                'ThetaData', deg2rad(theta), ...
                'RData', db(app, pArray), ...
                "Color", app.color(1,:) );

            results=0;
            
        end
        
        function [pArray, pAperture, theta] = calculateBeamprofile(app, nPoints)
            theta = linspace(-90, 90, nPoints);
            thetaS = app.array.thetaS;
            pitchWavelength = app.array.pitchWavelength ;
            nElements = app.array.nElements;
            widthLambda=app.array.width/app.array.wavelength;
            
            switch(lower(app.array.shape))
                case 'point'       
                    pAperture= ones(size(theta));
                case 'rectangular'
                    pAperture = sinc(widthLambda*(sin(deg2rad(theta))));
                otherwise, error('Unknown element shape')
            end
            x = pi*pitchWavelength*(sin(deg2rad(theta))-sin(deg2rad(thetaS)));
            pArray = pAperture.*sin(nElements*x)./(nElements*sin(x));
        end
        
        function results = updateWavelength(app)
            lambda = app.array.soundSpeed/app.array.frequency;
            app.array.wavelength = lambda;
            app.wavelengthEditField.Value = lambda*1e6;
            updatePitch(app);
            results=0;
        end
        
        function results = updatePitch(app)
            pitchWavelength = app.array.pitch/app.array.wavelength;
            width = app.array.pitch - app.array.kerf;

            app.array.pitchWavelength= pitchWavelength;
            app.array.width = width;
            
            app.elementWidthEditField.Value = width * 1e6;
            app.pitchWavelengthEditField.Value=pitchWavelength;
            findDelay(app);
            results=0;
        end
        
        function results = findDelay(app)
            thetaS = deg2rad(app.array.thetaS);
            app.array.delay = app.array.pitch * sin(thetaS) ...
                               /app.array.soundSpeed;
            app.elementDelayEditField.Value = app.array.delay * 1e9;
            results=0;
        end
        
        function xdB = db(app, x)
            xdB = 20*log10(abs(x));            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.color = colororder;

            %--- Initialize array parameters with values from GUI
            app.array.frequency = app.frequencySpinner.Value*1e6;
            app.array.soundSpeed = app.soundSpeedEditField.Value;
            app.array.pitch = app.pitchEditField.Value*1e-6;
            app.array.kerf = app.elementKerfEditField.Value*1e-6;
            app.array.nElements = app.nElementsEditField.Value;
            app.array.shape = app.elementShapeDropDown.Value;
            app.array.thetaS = app.steeringAngleSlider.Value;

            dBmin = app.dbSpinner.Value;
            app.BeamprofileRectangular.YLim= [dBmin, 0];
            
            %--- Create polar axes and position it in pixels
            app.polarAxis = polaraxes(app.UIFigureBeamProfile);
            app.polarAxis.Units = 'pixels';
            app.polarAxis.Position = [600 30 420 280];
            app.polarAxis.ThetaLim =[-90 90];
            app.polarAxis.RLim = [dBmin, 0];
            app.polarAxis.RTick= -72:6:0;
            app.polarAxis.RMinorTick = 'on';
            app.polarAxis.RMinorGrid = 'on';
            app.polarAxis.ThetaAxisUnits="degrees";
            app.polarAxis.ThetaZeroLocation="bottom";
            app.polarAxis.FontUnits="pixels";
            app.polarAxis.FontSize = 12;
            app.polarAxis.NextPlot ="Add";
            app.polarValue =polarplot(app.polarAxis, 0,1);
            
            %--- Update calculated fields and plot results
            updateWavelength(app);
            plotResults(app,0);
            drawnow
        end

        % Value changed function: soundSpeedEditField
        function soundSpeedEditFieldValueChanged(app, event)
            app.array.soundspeed = app.soundSpeedEditField.Value;
            updateWavelength(app);
            updatePitch(app);
            plotResults(app,event);
        end

        % Value changed function: frequencySpinner
        function frequencySpinnerValueChanged(app, event)
            app.array.frequency = app.frequencySpinner.Value*1e6;
            updateWavelength(app);
            updatePitch(app);
            plotResults(app,event);
        end

        % Value changed function: pitchEditField
        function pitchEditFieldValueChanged(app, event)
            app.array.pitch = app.pitchEditField.Value*1e-6;
            updatePitch(app);
            plotResults(app,event);
        end

        % Value changed function: elementKerfEditField
        function elementKerfEditFieldValueChanged(app, event)
            app.array.kerf = app.elementKerfEditField.Value*1e-6;
            updatePitch(app);
            plotResults(app,event);
        end

        % Value changed function: nElementsEditField
        function nElementsEditFieldValueChanged(app, event)
            app.array.nElements = app.nElementsEditField.Value;
            plotResults(app,event);
        end

        % Value changed function: elementShapeDropDown
        function elementShapeDropDownValueChanged(app, event)
            app.array.shape = app.elementShapeDropDown.Value;
            plotResults(app,event);
        end

        % Value changed function: steeringAngleSpinner
        function steeringAngleSpinnerValueChanged(app, event)
            thetaS = app.steeringAngleSpinner.Value;
            app.steeringAngleSlider.Value=thetaS;
            app.array.thetaS = thetaS;
            findDelay(app);
            plotResults(app, event);
        end

        % Callback function: steeringAngleSlider, steeringAngleSlider
        function SteeringAngleSliderValueChanged(app, event)
            thetaS = event.Value;
            app.steeringAngleSpinner.Value=thetaS;
            app.array.thetaS = thetaS;
            findDelay(app);
            plotResults(app, event);
            drawnow
        end

        % Button pushed function: halfWavelengthButton
        function halfWavelengthButtonPushed(app, event)
            app.array.pitch = 1/2*app.array.wavelength;
            app.pitchEditField.Value=app.array.pitch*1e6;
            updatePitch(app);
            plotResults(app,event);
        end

        % Button pushed function: wavelengthButton
        function wavelengthButtonPushed(app, event)
            app.array.pitch = app.array.wavelength;
            app.pitchEditField.Value=app.array.pitch*1e6;
            updatePitch(app);
            plotResults(app,event);
        end

        % Value changed function: dbSpinner
        function dbSpinnerValueChanged(app, event)
            Pmin = app.dbSpinner.Value;
            ylim(app.BeamprofileRectangular,[Pmin,0]);
            rlim(app.polarAxis,[Pmin,0])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureBeamProfile = uifigure('Visible', 'off');
            app.UIFigureBeamProfile.Name = 'Far-Field Array Beam Profile';
            app.BeamprofileRectangular = uiaxes(app.UIFigureBeamProfile);
            xlabel(app.BeamprofileRectangular, 'Angle [Deg]')
            ylabel(app.BeamprofileRectangular, 'Amplitude [dB]')
            app.BeamprofileRectangular.XLim = [-90 90];
            app.BeamprofileRectangular.YLim = [-42 2];
            app.BeamprofileRectangular.XTick = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.BeamprofileRectangular.YTick = [-60 -54 -48 -42 -36 -30 -24 -18 -12 -6 0 6];
            app.BeamprofileRectangular.YTickLabel = {'-60'; '-54'; '-48'; '-42'; '-36'; '-30'; '-24'; '-18'; '-12'; '-6'; '0'; '6'};
            app.wavelengthButton = uibutton(app.UIFigureBeamProfile, 'push');
            app.wavelengthButton.ButtonPushedFcn = createCallbackFcn(app, @wavelengthButtonPushed, true);
            app.wavelengthButton.Text = 'l';
            app.halfWavelengthButton = uibutton(app.UIFigureBeamProfile, 'push');
            app.halfWavelengthButton.ButtonPushedFcn = createCallbackFcn(app, @halfWavelengthButtonPushed, true);
            app.halfWavelengthButton.Text = 'l/2';
            app.NoofelementsLabel = uilabel(app.UIFigureBeamProfile);
            app.NoofelementsLabel.Text = 'No. of elements ';
            app.nElementsEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.nElementsEditField.Limits = [1 4000];
            app.nElementsEditField.ValueDisplayFormat = '%d';
            app.nElementsEditField.ValueChangedFcn = createCallbackFcn(app, @nElementsEditFieldValueChanged, true);
            app.nElementsEditField.Value = 64;
            app.ElementpitchumLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementpitchumLabel.Text = 'Element pitch [um] ';
            app.pitchEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.pitchEditField.Limits = [1 5000];
            app.pitchEditField.ValueDisplayFormat = '%4.0f';
            app.pitchEditField.ValueChangedFcn = createCallbackFcn(app, @pitchEditFieldValueChanged, true);
            app.pitchEditField.Value = 300;
            app.WavelengthumLabel = uilabel(app.UIFigureBeamProfile);
            app.WavelengthumLabel.Text = 'Wavelength [um] ';
            app.wavelengthEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.wavelengthEditField.Limits = [10 5000];
            app.wavelengthEditField.ValueDisplayFormat = '%4.0f';
            app.wavelengthEditField.Editable = 'off';
            app.wavelengthEditField.Value = 300;
            app.PitchWavelengthLabel = uilabel(app.UIFigureBeamProfile);
            app.PitchWavelengthLabel.Text = 'Pitch / Wavelength ';
            app.pitchWavelengthEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.pitchWavelengthEditField.Limits = [0.01 10];
            app.pitchWavelengthEditField.ValueDisplayFormat = '%4.2f';
            app.pitchWavelengthEditField.Editable = 'off';
            app.pitchWavelengthEditField.Value = 0.7;
            app.ElementshapeDropDownLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementshapeDropDownLabel.Text = 'Element shape';
            app.elementShapeDropDown = uidropdown(app.UIFigureBeamProfile);
            app.elementShapeDropDown.Items = {'Point', 'Rectangular'};
            app.elementShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @elementShapeDropDownValueChanged, true);
            app.elementShapeDropDown.Value = 'Point';
            app.SpeedofsoundmsLabel = uilabel(app.UIFigureBeamProfile);
            app.SpeedofsoundmsLabel.Text = 'Speed of sound [m/s] ';
            app.soundSpeedEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.soundSpeedEditField.Limits = [10 5000];
            app.soundSpeedEditField.ValueDisplayFormat = '%4.0f';
            app.soundSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @soundSpeedEditFieldValueChanged, true);
            app.soundSpeedEditField.Value = 1540;
            app.steeringAngleSlider = uislider(app.UIFigureBeamProfile);
            app.steeringAngleSlider.Limits = [-90 90];
            app.steeringAngleSlider.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.steeringAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SteeringAngleSliderValueChanged, true);
            app.steeringAngleSlider.ValueChangingFcn = createCallbackFcn(app, @SteeringAngleSliderValueChanged, true);
            app.titleLabel = uilabel(app.UIFigureBeamProfile);
            app.titleLabel.Text = 'Beam Profile from a Linear Transducer Array';
            app.ElementkerfumLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementkerfumLabel.Text = 'Element kerf [um] ';
            app.elementKerfEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.elementKerfEditField.Limits = [0 5000];
            app.elementKerfEditField.ValueDisplayFormat = '%4.0f';
            app.elementKerfEditField.ValueChangedFcn = createCallbackFcn(app, @elementKerfEditFieldValueChanged, true);
            app.elementKerfEditField.Value = 20;
            app.ElementwidthumLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementwidthumLabel.Text = 'Element width [um] ';
            app.elementWidthEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.elementWidthEditField.Limits = [1 5000];
            app.elementWidthEditField.ValueDisplayFormat = '%4.0f';
            app.elementWidthEditField.Editable = 'off';
            app.elementWidthEditField.Value = 280;
            app.SteeringAngleDegLabel = uilabel(app.UIFigureBeamProfile);
            app.SteeringAngleDegLabel.Text = 'Steering Angle [Deg] ';
            app.steeringAngleSpinner = uispinner(app.UIFigureBeamProfile);
            app.steeringAngleSpinner.Limits = [-90 90];
            app.steeringAngleSpinner.ValueDisplayFormat = '%4.1f';
            app.steeringAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @steeringAngleSpinnerValueChanged, true);
            app.Image = uiimage(app.UIFigureBeamProfile);
            app.Image.ImageSource = 'USN_logo_En_rgb.png';
            app.ElementDelaynsEditFieldLabel = uilabel(app.UIFigureBeamProfile);
            app.ElementDelaynsEditFieldLabel.Text = 'Element Delay [ns] ';
            app.elementDelayEditField = uieditfield(app.UIFigureBeamProfile, 'numeric');
            app.elementDelayEditField.Limits = [-100000 100000];
            app.elementDelayEditField.ValueDisplayFormat = '%6.1f';
            app.elementDelayEditField.Editable = 'off';
            app.elementDelayEditField.Value = 1e-05;
            app.subtitleLabel = uilabel(app.UIFigureBeamProfile);
            app.subtitleLabel.Text = 'Far-field profile calculated from the Fraunhofer-approximation';
            app.FrequencyMHzSpinnerLabel = uilabel(app.UIFigureBeamProfile);
            app.FrequencyMHzSpinnerLabel.Text = 'Frequency [MHz] ';
            app.frequencySpinner = uispinner(app.UIFigureBeamProfile);
            app.frequencySpinner.Step = 0.1;
            app.frequencySpinner.Limits = [0.01 100];
            app.frequencySpinner.ValueDisplayFormat = '%5.2f';
            app.frequencySpinner.ValueChangedFcn = createCallbackFcn(app, @frequencySpinnerValueChanged, true);
            app.frequencySpinner.Value = 3;
            app.ScaledBSpinnerLabel = uilabel(app.UIFigureBeamProfile);
            app.ScaledBSpinnerLabel.Text = 'Scale [dB]';
            app.dbSpinner = uispinner(app.UIFigureBeamProfile);
            app.dbSpinner.LowerLimitInclusive = 'off';
            app.dbSpinner.UpperLimitInclusive = 'off';
            app.dbSpinner.Limits = [-200 0];
            app.dbSpinner.ValueDisplayFormat = '%4.0f';
            app.dbSpinner.ValueChangedFcn = createCallbackFcn(app, @dbSpinnerValueChanged, true);
            app.dbSpinner.Value = -42;
            % Show the figure after all components are created
            app.UIFigureBeamProfile.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = array_beamprofile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigureBeamProfile)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureBeamProfile)
        end
    end
end